classdef radiology_ablation_data_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool5                    matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        PushTool3                    matlab.ui.container.toolbar.PushTool
        PushTool4                    matlab.ui.container.toolbar.PushTool
        SliceSlider_5                matlab.ui.control.Slider
        SliceSlider_5Label           matlab.ui.control.Label
        SliceSlider_2                matlab.ui.control.Slider
        SliceSlider_2Label           matlab.ui.control.Label
        SliceSlider_3                matlab.ui.control.Slider
        SliceSlider_3Label           matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        EditField                    matlab.ui.control.EditField
        ROCcurveanalysisPanel        matlab.ui.container.Panel
        DisplayROCcurvesButton       matlab.ui.control.Button
        AUC3EditField                matlab.ui.control.NumericEditField
        AUC3EditFieldLabel           matlab.ui.control.Label
        AUC2EditField                matlab.ui.control.NumericEditField
        AUC2EditFieldLabel           matlab.ui.control.Label
        ROIradiusEditField           matlab.ui.control.NumericEditField
        ROIradiusEditFieldLabel      matlab.ui.control.Label
        UIAxes_12                    matlab.ui.control.UIAxes
        SegmentationPanel            matlab.ui.container.Panel
        FinishsegmentationButton     matlab.ui.control.Button
        CreatemaskButton             matlab.ui.control.Button
        EditpolygonButton            matlab.ui.control.Button
        Slider_5                     matlab.ui.control.Slider
        Slider_5Label                matlab.ui.control.Label
        Slider_4                     matlab.ui.control.Slider
        Slider_4Label                matlab.ui.control.Label
        SelectablatedregionButton    matlab.ui.control.Button
        Slider_3                     matlab.ui.control.Slider
        Slider_3Label                matlab.ui.control.Label
        AblationlastsliceEditField   matlab.ui.control.NumericEditField
        AblationlastsliceEditFieldLabel  matlab.ui.control.Label
        AblationfirstsliceEditField  matlab.ui.control.NumericEditField
        AblationfirstsliceEditFieldLabel  matlab.ui.control.Label
        DisplayMRIButton             matlab.ui.control.Button
        UIAxes_11                    matlab.ui.control.UIAxes
        rhoEditField                 matlab.ui.control.NumericEditField
        rhoEditFieldLabel            matlab.ui.control.Label
        FinishregistrationButton     matlab.ui.control.Button
        UpdateregistrationButton     matlab.ui.control.Button
        SelectechovolumeapexButton   matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        SliceSlider_4                matlab.ui.control.Slider
        SliceSlider_4Label           matlab.ui.control.Label
        RollEditField                matlab.ui.control.NumericEditField
        RollEditFieldLabel           matlab.ui.control.Label
        PitchEditField               matlab.ui.control.NumericEditField
        PitchEditFieldLabel          matlab.ui.control.Label
        YawEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel            matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.Slider
        SliceSliderLabel             matlab.ui.control.Label
        AblationdateDropDown         matlab.ui.control.DropDown
        AblationdateDropDownLabel    matlab.ui.control.Label
        UIAxes_10                    matlab.ui.control.UIAxes
        UIAxes_9                     matlab.ui.control.UIAxes
        UIAxes_8                     matlab.ui.control.UIAxes
        UIAxes_7                     matlab.ui.control.UIAxes
        UIAxes_6                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        case_path % Description
        photo_names
        Bmode
        MRvol
        CTvol
    end

    properties (Constant = true)
        base_path = '/Users/elmiraghahramani/Documents/Recovered files from EHD 11082021/Deep Scan - APFS/Back up from lab PC/Elmira/Radiology ablation cases/'
        folder_extension = ' radiology MW ablation and followup/'
        procedure_photos_extension = 'Procedure photos/'
        date = {'03182021_T1_LR_pre',...
            '03182021_T1_LR_dur',...
            '03182021_T2_LR_pre',...
            '03182021_T2_LR_dur',...
            '08242021_T1_HR',...
            '08242021_T1_LR_pre',...
            '08242021_T1_LR_dur',...
            '08242021_T2_HR',...
            '08242021_T2_LR_pre',...
            '08242021_T2_LR_dur',...
            '12032021_T1_HR',...
            '12032021_T1_LR_pre',...
            '12032021_T1_LR_dur'} % Date of ablation and trial number
        HR_num_frames = [] % Number of high resolution IQ volume pairs pre-ablation
        LR_pre_num_frames = [] % Number of low resolution IQ volume pairs pre-ablation
    end

    methods (Access = public)

        function constructor(app)
            app.US_class_obj = Ultrasound_analysis_class;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: AblationdateDropDown
        function AblationdateDropDownOpening(app, event)
            app.AblationdateDropDown.Items = app.date;
            value = app.AblationdateDropDown.Value;
            app.case_path = [app.base_path,value(1:8),app.folder_extension];
        end

        % Value changed function: AblationdateDropDown
        function AblationdateDropDownValueChanged(app, event)
            value = app.AblationdateDropDown.Value;
            app.case_path = [app.base_path,value(1:8),app.folder_extension];
        end

        % Clicked callback: PushTool5
        function PushTool5Clicked(app, event)
            directory = [app.case_path,app.procedure_photos_extension];
            app.photo_names = uigetfile([directory,'*.jpg'],...
                'MultiSelect','on','Select 2 photos from procedure');
            photo1 = imread([directory,app.photo_names{1}]);
            photo2 = imread([directory,app.photo_names{2}]);
            imshow(photo1,'Parent',app.UIAxes)
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            %             axis image
            imshow(photo2,'Parent',app.UIAxes_2)
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            us_cart = load([app.case_path,'3-18-2021 radiology MW ablation/','usDataInfo_includesmotioncorretion_notflipped_FINAL_2021-3-18_tumor1_rhoScalFact1.mat']);
            app.Bmode = (10*log10(abs(us_cart.rawData_cart(:,:,:,2,10)./.1).^2)+65)/65;
            app.Bmode = flip(app.Bmode,2);
            app.Bmode=flip(app.Bmode,3);
            imagesc(app.UIAxes_3,app.Bmode(:,:,30))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSlider.Limits = [1 size(app.Bmode,3)];
            app.SliceSlider.Value = 30;
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_3,app.Bmode(:,:,round(changingValue)))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSliderLabel.Text = num2str(round(changingValue));
        end

        % Clicked callback: PushTool4
        function PushTool4Clicked(app, event)
            dicomdir = '/Users/elmiraghahramani/Documents/Recovered files from EHD 11082021/Deep Scan - APFS/Back up from lab PC/Elmira/Radiology ablation cases/03182021 radiology MW ablation and followup/3-18-2021 radiology MW ablation/20210319 + 20210205/DICOM/P0000001/ST000001/SE000008/';
            MRvolAll = single(squeeze(dicomreadVolume(dicomdir)));
            Nim = 5;
            MRvolAll = reshape(MRvolAll,size(MRvolAll,1),size(MRvolAll,2), ...
                Nim,size(MRvolAll,3)/Nim);
            app.MRvol = squeeze(MRvolAll(:,:,1,:));
            dirinfo = dir(dicomdir);
            MRinfo = dicominfo([dicomdir '/' dirinfo(end).name]);
            MRinfo.MRvol = app.MRvol;
            MRCTinfoObj = MRinfo;
            MRCTinfoObj.VolumeSlices=540;
            MRCTinfoObj.SeriesNumber=5;
            imagesc(app.UIAxes_5,app.MRvol(:,:,73));
            axis(app.UIAxes_5,'image');
            colormap(app.UIAxes_5,'gray');
            app.SliceSlider_3.Limits = [1 size(app.MRvol,3)];
            app.SliceSlider_3.Value = 73;
        end

        % Value changing function: SliceSlider_3
        function SliceSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_5,app.MRvol(:,:,round(changingValue)));
            axis(app.UIAxes_5,'image');
            colormap(app.UIAxes_5,'gray');
            app.SliceSlider_3Label.Text = num2str(round(changingValue));
        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            dicomdir = '/Users/elmiraghahramani/Documents/Recovered files from EHD 11082021/Deep Scan - APFS/Back up from lab PC/Elmira/Radiology ablation cases/03182021 radiology MW ablation and followup/20210318 (suite CT, US) + 20210420 (1 mo MR)/DICOM/P0000001/ST000001/SE000008/';
            app.CTvol = single(squeeze(dicomreadVolume(dicomdir)));
            imagesc(app.UIAxes_4,app.CTvol(:,:,20));
            axis(app.UIAxes_4,'image');
            colormap(app.UIAxes_4,'gray');
            app.SliceSlider_2.Limits = [1 size(app.CTvol,3)];
            app.SliceSlider_2.Value = 20;
        end

        % Value changing function: SliceSlider_2
        function SliceSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_4,app.CTvol(:,:,round(changingValue)));
            axis(app.UIAxes_4,'image');
            colormap(app.UIAxes_4,'gray');
            app.SliceSlider_2Label.Text = num2str(round(changingValue));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool5.Icon = 'photo.png';
            app.PushTool5.Separator = 'on';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'US.png';
            app.PushTool2.Separator = 'on';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'CT.png';
            app.PushTool3.Separator = 'on';
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = 'mri.png';
            app.PushTool4.Separator = 'on';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Procedure photo 1')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Procedure photo 2')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Ultrasound')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'CT')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'MR')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Registered US')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Registered delta')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Registered deltacorr')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'MR')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Registered LR')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.AblationdateDropDownLabel = uilabel(app.UIFigure);
            app.AblationdateDropDownLabel.Text = 'Ablation date';
            app.AblationdateDropDown = uidropdown(app.UIFigure);
            app.AblationdateDropDown.Items = {};
            app.AblationdateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AblationdateDropDownOpening, true);
            app.AblationdateDropDown.ValueChangedFcn = createCallbackFcn(app, @AblationdateDropDownValueChanged, true);
            app.AblationdateDropDown.Value = {};
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliceSlider_4Label = uilabel(app.UIFigure);
            app.SliceSlider_4Label.Text = 'Slice';
            app.SliceSlider_4 = uislider(app.UIFigure);
            app.SliceSlider_4.MajorTicks = [];
            app.SliceSlider_4.MinorTicks = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'axes_photo.jpg';
            app.SelectechovolumeapexButton = uibutton(app.UIFigure, 'push');
            app.SelectechovolumeapexButton.Text = 'Select echo volume apex';
            app.UpdateregistrationButton = uibutton(app.UIFigure, 'push');
            app.UpdateregistrationButton.Text = 'Update registration';
            app.FinishregistrationButton = uibutton(app.UIFigure, 'push');
            app.FinishregistrationButton.Text = 'Finish registration';
            app.rhoEditFieldLabel = uilabel(app.UIFigure);
            app.rhoEditFieldLabel.Text = 'rho';
            app.rhoEditField = uieditfield(app.UIFigure, 'numeric');
            app.SegmentationPanel = uipanel(app.UIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UIAxes_11 = uiaxes(app.SegmentationPanel);
            title(app.UIAxes_11, 'MR')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.DisplayMRIButton = uibutton(app.SegmentationPanel, 'push');
            app.DisplayMRIButton.Text = 'Display MRI';
            app.AblationfirstsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationfirstsliceEditFieldLabel.Text = 'Ablation first slice';
            app.AblationfirstsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationlastsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationlastsliceEditFieldLabel.Text = 'Ablation last slice';
            app.AblationlastsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.Slider_3Label = uilabel(app.SegmentationPanel);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.SegmentationPanel);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MinorTicks = [];
            app.SelectablatedregionButton = uibutton(app.SegmentationPanel, 'push');
            app.SelectablatedregionButton.Text = 'Select ablated region';
            app.Slider_4Label = uilabel(app.SegmentationPanel);
            app.Slider_4Label.Text = 'Slider';
            app.Slider_4 = uislider(app.SegmentationPanel);
            app.Slider_4.MajorTicks = [];
            app.Slider_4.MinorTicks = [];
            app.Slider_5Label = uilabel(app.SegmentationPanel);
            app.Slider_5Label.Text = 'Slider';
            app.Slider_5 = uislider(app.SegmentationPanel);
            app.Slider_5.MajorTicks = [];
            app.Slider_5.MinorTicks = [];
            app.EditpolygonButton = uibutton(app.SegmentationPanel, 'push');
            app.EditpolygonButton.Text = 'Edit polygon';
            app.CreatemaskButton = uibutton(app.SegmentationPanel, 'push');
            app.CreatemaskButton.Text = 'Create mask';
            app.FinishsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.FinishsegmentationButton.Text = 'Finish segmentation';
            app.ROCcurveanalysisPanel = uipanel(app.UIFigure);
            app.ROCcurveanalysisPanel.Title = 'ROC curve analysis';
            app.UIAxes_12 = uiaxes(app.ROCcurveanalysisPanel);
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ROIradiusEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.ROIradiusEditFieldLabel.Text = 'ROI radius';
            app.ROIradiusEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUC2EditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUC2EditFieldLabel.Text = 'AUC 2';
            app.AUC2EditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUC3EditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUC3EditFieldLabel.Text = 'AUC 3';
            app.AUC3EditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.DisplayROCcurvesButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.DisplayROCcurvesButton.Text = 'Display ROC curves';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Lamp = uilamp(app.UIFigure);
            app.SliceSlider_3Label = uilabel(app.UIFigure);
            app.SliceSlider_3Label.Text = 'Slice';
            app.SliceSlider_3 = uislider(app.UIFigure);
            app.SliceSlider_3.MajorTicks = [];
            app.SliceSlider_3.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_3ValueChanging, true);
            app.SliceSlider_3.MinorTicks = [];
            app.SliceSlider_2Label = uilabel(app.UIFigure);
            app.SliceSlider_2Label.Text = 'Slice';
            app.SliceSlider_2 = uislider(app.UIFigure);
            app.SliceSlider_2.MajorTicks = [];
            app.SliceSlider_2.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_2ValueChanging, true);
            app.SliceSlider_2.MinorTicks = [];
            app.SliceSlider_5Label = uilabel(app.UIFigure);
            app.SliceSlider_5Label.Text = 'Slice';
            app.SliceSlider_5 = uislider(app.UIFigure);
            app.SliceSlider_5.MajorTicks = [];
            app.SliceSlider_5.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radiology_ablation_data_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end