classdef radiology_ablation_data_processing_v10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        RegistrationTab                 matlab.ui.container.Tab
        ElevationsliceLabel             matlab.ui.control.Label
        IQdatanumberLabel               matlab.ui.control.Label
        Slider_2                        matlab.ui.control.Slider
        Slider_2Label                   matlab.ui.control.Label
        ZoomSlider_2                    matlab.ui.control.Slider
        ZoomSlider_2Label               matlab.ui.control.Label
        ZoomSlider                      matlab.ui.control.Slider
        ZoomSliderLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        RegistrationPanel               matlab.ui.container.Panel
        LoadregisteredUSimagesButton    matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        UpdatetableButton               matlab.ui.control.Button
        LoadsavedregistrationparametersButton  matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        LRUSIQfilenumberEditField       matlab.ui.control.NumericEditField
        LRUSIQfilenumberEditFieldLabel  matlab.ui.control.Label
        DrawrectangularROIButton_2      matlab.ui.control.Button
        NeedletrackslicenumberEditField  matlab.ui.control.NumericEditField
        NeedletrackslicenumberEditFieldLabel  matlab.ui.control.Label
        ApplytoLRUSButton               matlab.ui.control.Button
        RotationanglesdegLabel          matlab.ui.control.Label
        ApextoneedleinZmmEditField      matlab.ui.control.NumericEditField
        ApextoneedleinZmmEditFieldLabel  matlab.ui.control.Label
        AlphaSlider                     matlab.ui.control.Slider
        AlphaSliderLabel                matlab.ui.control.Label
        OverlayultrasoundButton         matlab.ui.control.Button
        SliceSlider_7                   matlab.ui.control.Slider
        SliceSlider_7Label              matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Lamp                            matlab.ui.control.Lamp
        UpdateregistrationButton        matlab.ui.control.Button
        EchoapexonMRLabel               matlab.ui.control.Label
        RollEditField                   matlab.ui.control.NumericEditField
        PitchEditFieldLabel_2           matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        OrderofrotationDropDown         matlab.ui.control.DropDown
        OrderofrotationDropDownLabel    matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        YawEditField                    matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        UIAxes_15                       matlab.ui.control.UIAxes
        UIAxes_14                       matlab.ui.control.UIAxes
        UIAxes_13                       matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
        DateDropDown                    matlab.ui.control.DropDown
        DateDropDownLabel               matlab.ui.control.Label
        SliceSlider                     matlab.ui.control.Slider
        SliceSliderLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        SegmentationandROCTab           matlab.ui.container.Tab
        LoadregistereddecorrelationIBSimagesButton  matlab.ui.control.Button
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        ShowpostopimageonlyButton       matlab.ui.control.Button
        ZsliceEditField                 matlab.ui.control.NumericEditField
        ZsliceEditFieldLabel            matlab.ui.control.Label
        XsliceEditField                 matlab.ui.control.NumericEditField
        XsliceEditFieldLabel            matlab.ui.control.Label
        CoronalLabel                    matlab.ui.control.Label
        SagittalLabel                   matlab.ui.control.Label
        TransverseLabel                 matlab.ui.control.Label
        IsosurfacethresholdEditField    matlab.ui.control.NumericEditField
        IsosurfacethresholdEditFieldLabel  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        SavefigureincludingallimagingparametersButton  matlab.ui.control.Button
        AlphaSlider_3                   matlab.ui.control.Slider
        AlphaSlider_3Label              matlab.ui.control.Label
        DisplaydecorrelationIBSDropDown  matlab.ui.control.DropDown
        DisplaydecorrelationIBSDropDownLabel  matlab.ui.control.Label
        SliceSlider_11                  matlab.ui.control.Slider
        SliceSlider_11Label             matlab.ui.control.Label
        SliceSlider_10                  matlab.ui.control.Slider
        SliceSlider_10Label             matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        SliceSlider_8                   matlab.ui.control.Slider
        SliceSlider_8Label              matlab.ui.control.Label
        ROCcurveanalysisPanel           matlab.ui.container.Panel
        SaveROCfiguresButton            matlab.ui.control.Button
        ListBox                         matlab.ui.control.ListBox
        GlobalnormAUCEditField_2        matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.EditField
        IBSAUCEditField                 matlab.ui.control.NumericEditField
        IBSAUCEditFieldLabel            matlab.ui.control.Label
        WithmotcorrLabel_3              matlab.ui.control.Label
        WithoutLabel_3                  matlab.ui.control.Label
        WithmotcorrLabel_2              matlab.ui.control.Label
        WithoutLabel_2                  matlab.ui.control.Label
        WithmotcorrLabel                matlab.ui.control.Label
        WithoutLabel                    matlab.ui.control.Label
        GlobalnormAUCEditField          matlab.ui.control.NumericEditField
        GlobalnormAUCEditFieldLabel     matlab.ui.control.Label
        LocalnormAUCEditField_2         matlab.ui.control.NumericEditField
        CombinednormAUCEditField_2      matlab.ui.control.NumericEditField
        TrialsDropDown                  matlab.ui.control.DropDown
        TrialsDropDownLabel             matlab.ui.control.Label
        DisplayROCcurvesButton          matlab.ui.control.Button
        LocalnormAUCEditField           matlab.ui.control.NumericEditField
        LocalnormAUCEditFieldLabel      matlab.ui.control.Label
        CombinednormAUCEditField        matlab.ui.control.NumericEditField
        CombinednormAUCEditFieldLabel   matlab.ui.control.Label
        UIAxes_17                       matlab.ui.control.UIAxes
        SegmentationPanel               matlab.ui.container.Panel
        SliceSlider_12                  matlab.ui.control.Slider
        SliceSlider_12Label             matlab.ui.control.Label
        DrawrectangularROIButton        matlab.ui.control.Button
        SliceSpinner                    matlab.ui.control.Spinner
        SliceSpinnerLabel               matlab.ui.control.Label
        FinishsegmentationButton        matlab.ui.control.Button
        EditpolygonButton               matlab.ui.control.Button
        AblationlastsliceEditField      matlab.ui.control.NumericEditField
        AblationlastsliceEditFieldLabel  matlab.ui.control.Label
        AblationfirstsliceEditField     matlab.ui.control.NumericEditField
        AblationfirstsliceEditFieldLabel  matlab.ui.control.Label
        DisplayMRIButton                matlab.ui.control.Button
        UIAxes_18                       matlab.ui.control.UIAxes
        UIAxes_16                       matlab.ui.control.UIAxes
        ApplyregistrationtoechodecorrelationandIBSButton  matlab.ui.control.Button
        UIAxes_12                       matlab.ui.control.UIAxes
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes_10                       matlab.ui.control.UIAxes
        UIAxes_9                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        drop_down_val
        US_decorr_xyz
        case_path % Description
        US_path
        photo_names
        trial_name

        local_cum_decorr_lastframe
        local_cum_decorr_motcorr_lastframe

        global_cum_decorr_lastframe
        global_cum_decorr_motcorr_lastframe

        combined_cum_decorr_lastframe
        combined_cum_decorr_motcorr_lastframe

        cum_IBS_lastframe

        xyz_range_HR
        xyz_range_LR
        Bmode_HR
        Bmode_vol_LR
        trial_number
        suite_CT_vol
        post_abl_1day_obj
        post_abl_1day_vol
        post_abl_1day_vol_image
        apex_loc
        apex_to_needle_dist
        rotation_order_choice
        rotation_angles
        registered_US_flag = false
        registered_US_LR_flag = false
        registered_US
        registered_US_LR
        overlay_flag = false;
        overlay_slice = 30;
        green
        overlay_threshold = 0;
        slider7value = 10;
        slider8value = 1;
        slider10value = 10;
        slider11value = 10;
        trial_name_LR

        registered_local_decorr_unscaled = [];
        registered_local_decorr_scaled = [];
        registered_local_motcorr_decorr_unscaled = [];
        registered_local_motcorr_decorr_scaled = [];

        registered_global_decorr_unscaled = [];
        registered_global_decorr_scaled = [];
        registered_global_motcorr_decorr_unscaled = [];
        registered_global_motcorr_decorr_scaled = [];

        registered_combined_decorr_unscaled = [];
        registered_combined_decorr_scaled = [];
        registered_combined_motcorr_decorr_unscaled = [];
        registered_combined_motcorr_decorr_scaled = [];

        registered_IBS = [];

        US_overlay
        lesion_slice = [0,0];
        mask
        mask3D = [];
        mask_number
        SliceSlider_9value
        alpha_decorr_value = 0.5;
        display_decorr
        alpha3value = 0.5
        max_decorr = .1;
        dyn_range = 3;
        max_Bmode = 0.08;
        dB_range = 65;
        dicom_dxdydz
        dicom_x
        dicom_y
        dicom_z
        dicom_xmesh
        dicom_ymesh
        dicom_zmesh
        ROC_trials
        US_info_table
        cum_decorr_lastframe
        cum_decorr_motcorr_lastframe
        post_abl_1day_segment_inROI
        segment_ROI_Xrange
        segment_ROI_Yrange
        post_abl_1day_segment_ROI_flag = false;
        post_abl_1day_tab1_inROI
        tab1_ROI_azimuth
        tab1_ROI_range
        post_abl_1day_tab1_ROI_flag = false;
        photo1
        photo2

        xline1_handle
        yline1_handle
        xline2_handle
        yline2_handle
        xline3_handle
        yline3_handle

        slider8value_mm = 1;
        slider10value_mm = 1;
        slider11value_mm = 1;

        show_TPR_vs_FPR_flag = true;
        just_show_postop_image_flag = false;

        tpr
        fpr
        ppv
        AUC_TPR_FPR
        AUC_PPV_TPR
    end

    properties (Access = public, Constant = true)
        transverse_color = [0.85,0.33,0.10];
        sagittal_color = [0.49,0.18,0.56];
        coronal_color = [1.00,0.07,0.65];
        MR_image_grayscale = [0 256];
        Bmode_image_scale = [0 1];

        imaging_parameters = {
            'local decorr', 'local mot corr decorr',...
            'global decorr', 'global mot corr decorr',...
            'combined decorr', 'combined mot corr decorr',...
            'IBS'};

        input_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/input/';
        info_table_extension = 'ablation_information_for_app.xlsx';
        procedure_photos_extension = 'photos_of_procedures/';
        US_decorr_extension = 'ultrasound + decorr/';
        low_res_file_extension = '_IQcart_decorr_IBS_xyz_flipped.mat';
        high_res_file_extension = '_IQcart_xyz_flipped.mat';
        postop_1day_extension = '1day_postop/';
        postop_1day_subfolder_extension = '_postop_1day_images';
        suiteCT_extension = 'suiteCT/';
        suiteCT_file_extension = '_suiteCT';

        output_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/';
        ablation_mask3D_extension = 'ablation_masks/';
        ablation_mask3D_file_extension = '_ablation_mask3D.mat';
        registered_US_decorr_extension = 'registered_US_decorr/';
        registered_US_decorr_file_extension = {'_registered_HR_Bmode.mat';...
            '_registered_LR_Bmode.mat';...
            '_registered_LR_local_decorr.mat';...
            '_registered_LR_local_motcorr_decorr.mat';...
            '_registered_LR_global_decorr.mat';...
            '_registered_LR_global_motcorr_decorr.mat';...
            '_registered_LR_combined_decorr.mat';...
            '_registered_LR_combined_motcorr_decorr.mat';...
            '_registered_LR_IBS.mat'};
        registration_parameters_extension = 'registration_parameters/';
        registration_parameters_file_extension = '_registration_parameters.mat';
        decorr_IBS_figure_extension = 'figures/';
        decorr_IBS_figure_file_extension = '_decorr_normalizations_andIBS.fig';
        decorr_IBS_3Dfigure_file_extension = '_3D_decorr_normalizations_andIBS.fig';
        ROC_extension = 'ROC_curves_and_image_vectors/';
        ROC_figure_TPR_FPR_extension = '_ROC_TPR_FPR_figure_IBS_decorr_w_wo_motcorr.fig';
        ROC_figure_PPV_TPR_extension = '_ROC_PPV_TPR_figure_IBS_decorr_w_wo_motcorr.fig';
        ROC_curves_colors = [[0 0.4470 0.7410];...
            [0 0.4470 0.7410];...
            [0.9290 0.6940 0.1250];...
            [0.9290 0.6940 0.1250];...
            [0.4660 0.6740 0.1880];...
            [0.4660 0.6740 0.1880];...
            [0.6350 0.0780 0.1840]];

        input_US_decorr =  {'trial1_03182021_LR',...
            'trial2_03182021_LR',...
            'trial3_08242021_HR',...
            'trial4_08242021_HR',...
            'trial5_12032021_HR',...
            'trial6_04222022_HR',...
            'trial7_11252022_HR',...
            'trial8_12132022_HR',...
            'trial9_01052023_abl1_',...
            'trial9_01052023_abl2_',...
            'trial10_01192023_HR'};

        case_dates = {'03182021',...
            '03182021',...
            '08242021',...
            '08242021',...
            '12032021',...
            '04222022',...
            '11252022',...
            '12132022',...
            '01052023'};

        rotation_order    = {'x -> y -> z',...
            'x -> z -> y',...
            'y -> x -> z',...
            'y -> z -> x',...
            'z -> x -> y',...
            'z -> y -> x'};

        default_HR_IQfile_number  = [1, 1, 1, 1, 3, 1, 3, 1, 1, 1];
        postop_1day_needle_sl = [1, 1, 1, 1, 30, 21, 1, 1, 1, 1];
        has_HR = [0 0 1 1 1 1 1 1 1 1];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DateDropDown
        function DateDropDownOpening(app, event)
            app.DateDropDown.Items = app.input_US_decorr;
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            app.drop_down_val = app.DateDropDown.Value;
            temp = regexp(app.drop_down_val,'_','split');
            app.trial_number = str2double(temp{1}(6:end));
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');

            app.US_info_table = readtable([app.input_path,app.info_table_extension]);

            table_params = app.US_info_table.Properties.VariableNames;

            app.UITable.Data = {table_params{2};table_params{3};...
                table_params{4};table_params{5}};

            table_vals_HR = ...
                app.US_info_table{find(strcmp(app.US_info_table{:,1}, {app.drop_down_val})),2:6};

            temp = app.drop_down_val;
            temp(end-1) = 'L';

            table_vals_LR = ...
                app.US_info_table{find(strcmp(app.US_info_table{:,1}, {temp})),2:6};

            app.UITable.Data(:,2) = {table_vals_HR(1);...
                table_vals_HR(2); table_vals_HR(3); table_vals_HR(4)};

            app.UITable.Data(:,3) = {table_vals_LR(1);...
                table_vals_LR(2); table_vals_LR(3); table_vals_LR(4)};

            app.dicom_dxdydz = ...
                [app.US_info_table.dicom_dx(find(strcmp(app.US_info_table{:,1}, {app.drop_down_val}))),...
                app.US_info_table.dicom_dy((find(strcmp(app.US_info_table{:,1}, {app.drop_down_val})))),...
                app.US_info_table.dicom_dz((find(strcmp(app.US_info_table{:,1}, {app.drop_down_val}))))];

            app.UITable2.Data = {'Echo apex on MR';...
                'Needle track slice number';...
                'Apex to needle in Z(mm)';...
                'Order of rotation';...
                'Yaw, pitch, roll'};
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            directory1 = [app.input_path, app.procedure_photos_extension, ...
                app.drop_down_val(1:15), '_', num2str(1), '.jpeg'];
            directory2 = [app.input_path, app.procedure_photos_extension, ...
                app.drop_down_val(1:15), '_', num2str(2), '.jpeg'];

            app.photo1 = imread(directory1);
            app.photo2 = imread(directory2);

            imagesc(app.UIAxes, app.photo1)
            axis(app.UIAxes,'equal','tight');
            colormap(app.UIAxes,'gray');
            zoom(app.UIAxes,'on');
            
            imagesc(app.UIAxes_2, app.photo2)
            axis(app.UIAxes_2,'equal','tight');
            colormap(app.UIAxes_2,'gray');
            zoom(app.UIAxes_2,'on');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            answer = 'Yes';
            if ~isempty(app.xyz_range_HR)
                answer = questdlg('The US images are loaded. Do you want to reload them?', ...
                    'Warning', 'Yes','No','No');
            end
            if isempty(app.xyz_range_HR) || strcmp(answer, 'Yes')

                temp = app.drop_down_val;
                temp(17) = 'L';
                directory = strcat(app.input_path, app.US_decorr_extension, temp, app.low_res_file_extension);
                US_imaging_xyz_LR = load(directory);

                rawData_cart = squeeze(US_imaging_xyz_LR.rawData_cart(:,:,:,2,:));
                app.xyz_range_LR{1} = US_imaging_xyz_LR.x_range;
                app.xyz_range_LR{2} = US_imaging_xyz_LR.y_range;
                app.xyz_range_LR{3} = US_imaging_xyz_LR.z_range;

                app.Bmode_vol_LR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dB_range)/app.dB_range;

                app.local_cum_decorr_lastframe = US_imaging_xyz_LR.local_cum_decorr(:,:,:,end);
                app.local_cum_decorr_motcorr_lastframe = US_imaging_xyz_LR.local_cum_decorr_motcorr(:,:,:,end);

                app.global_cum_decorr_lastframe = US_imaging_xyz_LR.global_cum_decorr(:,:,:,end);
                app.global_cum_decorr_motcorr_lastframe = US_imaging_xyz_LR.global_cum_decorr_motcorr(:,:,:,end);

                app.combined_cum_decorr_lastframe = US_imaging_xyz_LR.combined_cum_decorr(:,:,:,end);
                app.combined_cum_decorr_motcorr_lastframe = US_imaging_xyz_LR.combined_cum_decorr_motcorr(:,:,:,end);

                app.cum_IBS_lastframe = US_imaging_xyz_LR.cum_IBS(:,:,:,end);

                elevation_sl = ceil(size(app.Bmode_vol_LR, 3) / 2);
                imagesc(app.UIAxes2, app.Bmode_vol_LR(:, :, elevation_sl, 1), app.Bmode_image_scale)
                axis(app.UIAxes2,'equal','tight');
                colormap(app.UIAxes2,'gray');
                app.Slider.Limits = [1 size(app.Bmode_vol_LR, 4)];
                app.Slider.Value = 1;
                app.Label.Text = num2str(app.Slider.Value);
                app.Slider_2.Limits = [1 size(app.Bmode_vol_LR, 3)];

                if app.has_HR(app.trial_number)
                    directory = strcat(app.input_path, app.US_decorr_extension, app.drop_down_val, app.high_res_file_extension, '.mat');
                    app.EditField.Value = 'Loading US images ...';
                    pause(.1);
                    US_imaging_xyz_HR = load(directory);
                    app.EditField.Value = '';

                    rawData_cart = squeeze(US_imaging_xyz_HR.rawData_cart(:,:,:,2,app.default_HR_IQfile_number(app.trial_number)));
                    app.xyz_range_HR{1} = US_imaging_xyz_HR.x_range;
                    app.xyz_range_HR{2} = US_imaging_xyz_HR.y_range;
                    app.xyz_range_HR{3} = US_imaging_xyz_HR.z_range;

                    app.Bmode_HR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dB_range)/app.dB_range;

                    elevation_sl = ceil(size(app.Bmode_HR, 3) / 2);
                    imagesc(app.UIAxes_3, app.Bmode_HR(:, :, elevation_sl), app.Bmode_image_scale)
                    axis(app.UIAxes_3,'equal','tight');
                    colormap(app.UIAxes_3,'gray');
                    app.SliceSlider.Limits = [1 size(app.Bmode_HR,3)];
                    app.SliceSlider.Value = 30;
                else
                    app.Bmode_HR = (10*log10(abs(rawData_cart(:,:,:,1)./app.max_Bmode).^2)+app.dB_range)/app.dB_range;
                    app.xyz_range_HR = app.xyz_range_LR;
                end
            end
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_3,app.Bmode_HR(:,:,round(changingValue)), app.Bmode_image_scale)
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSliderLabel.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            directory = strcat(app.input_path, app.suiteCT_extension, app.drop_down_val(1:end-3), app.suiteCT_file_extension, '.jpg');
            suitCT_image = imread(directory);

            imagesc(app.UIAxes_4, suitCT_image)
            axis(app.UIAxes_4,'equal','tight');
            colormap(app.UIAxes_4,'gray');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.SliceSlider_7.Value = app.postop_1day_needle_sl(app.trial_number);
            directory = strcat(app.input_path, app.postop_1day_extension, app.drop_down_val(1:end-3), app.postop_1day_subfolder_extension);
            [post_1day_image_vol, app.post_abl_1day_vol] = read_1day_post_ablation_image_vol(directory);

            app.dicom_x = (1:size(app.post_abl_1day_vol,2))*app.dicom_dxdydz(1);
            app.dicom_y = (1:size(app.post_abl_1day_vol,1))*app.dicom_dxdydz(2);
            app.dicom_z = (1:size(app.post_abl_1day_vol,3))*app.dicom_dxdydz(3);
            [app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh] = ...
                meshgrid(app.dicom_x, app.dicom_y, app.dicom_z);

            app.post_abl_1day_obj.image_vol = post_1day_image_vol;
            app.post_abl_1day_obj.vol = app.post_abl_1day_vol;
            app.post_abl_1day_obj.dxdydz = app.dicom_dxdydz;

            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,app.postop_1day_needle_sl(app.trial_number)),app.MR_image_grayscale)
            axis(app.UIAxes_13,'equal','tight');
            colormap(app.UIAxes_13,'gray');
            app.SliceSlider_7.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_7.Value = app.postop_1day_needle_sl(app.trial_number);

            % Tab 2 plots
            % Transverse plane
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, ...
                app.post_abl_1day_vol(:,:,app.postop_1day_needle_sl(app.trial_number)),app.MR_image_grayscale);
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'gray');
            hold(app.UIAxes_12,'on');
            app.xline1_handle = xline(app.UIAxes_12,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline1_handle = yline(app.UIAxes_12,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            % Sagittal plane
            x_sl = ceil(size(app.post_abl_1day_vol, 2) / 2);
            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_y, ...
                squeeze(app.post_abl_1day_vol(:,x_sl,:)),app.MR_image_grayscale);
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'gray');
            hold(app.UIAxes_9,'on');
            app.xline2_handle = xline(app.UIAxes_9,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            app.yline2_handle = yline(app.UIAxes_9,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');

            % Coronal plane
            y_sl = ceil(size(app.post_abl_1day_vol, 1) / 2);
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, ...
                rot90(squeeze(app.post_abl_1day_vol(y_sl,:,:))),app.MR_image_grayscale);
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'gray');
            hold(app.UIAxes_10,'on');
            app.xline3_handle = xline(app.UIAxes_10,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline3_handle = yline(app.UIAxes_10,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');

            app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_10.Limits = [1 size(app.post_abl_1day_vol,2)];
            app.SliceSlider_11.Limits = [1 size(app.post_abl_1day_vol,1)];
            app.AlphaSlider_3.Limits = [0 1];
        end

        % Value changing function: SliceSlider_7
        function SliceSlider_7ValueChanging(app, event)
            app.slider7value = event.Value;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if app.registered_US_flag
                imagesc(app.UIAxes_14, app.registered_US(range, azimuth, round(app.slider7value)), app.Bmode_image_scale)
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15, app.registered_US_LR(range, azimuth, round(app.overlay_slice)), app.Bmode_image_scale)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13, vol, app.MR_image_grayscale)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13, app.US_overlay, "AlphaData", app.overlay_threshold)
                hold(app.UIAxes_13,'off');

            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)),app.MR_image_grayscale);
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [app.apex_loc(1), app.apex_loc(2)] = getpts(app.UIAxes_13);
            app.apex_loc = round(app.apex_loc);

            app.UITable2.Data(1,2) = {num2str([app.apex_loc(1), app.apex_loc(2)])};
            %             app.apex_loc(1) = 23;
            %             app.apex_loc(2) = 236;
            %             app.apex_loc(3) = 21;
        end

        % Drop down opening function: OrderofrotationDropDown
        function OrderofrotationDropDownOpening(app, event)
            app.OrderofrotationDropDown.Items = app.rotation_order;
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value;
        end

        % Value changed function: OrderofrotationDropDown
        function OrderofrotationDropDownValueChanged(app, event)
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value;

            app.UITable2.Data(4,2) = {app.rotation_order_choice};
        end

        % Value changed function: ApextoneedleinZmmEditField
        function ApextoneedleinZmmEditFieldValueChanged(app, event)
            app.apex_to_needle_dist = app.ApextoneedleinZmmEditField.Value;

            app.UITable2.Data(3,2) = {num2str(app.apex_to_needle_dist)};
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.rotation_angles(1) = app.YawEditField.Value;

            app.UITable2.Data(5,2) = {num2str(app.rotation_angles)};
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.rotation_angles(2) = app.PitchEditField.Value;

            app.UITable2.Data(5,2) = {num2str(app.rotation_angles)};
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.rotation_angles(3) = app.RollEditField.Value;

            app.UITable2.Data(5,2) = {num2str(app.rotation_angles)};
        end

        % Button pushed function: UpdateregistrationButton
        function UpdateregistrationButtonPushed(app, event)
            app.Lamp.Color = 'red';
            pause(0.1);
            app.EditField.Value = 'Registration in progress...';
            output_directory = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.drop_down_val(1:end-3),...
                app.registered_US_decorr_file_extension{1});

            [app.registered_US, load_registration_parameters_flag] = register(app.post_abl_1day_obj, ...
                app.Bmode_HR, app.xyz_range_HR, app.apex_loc,...
                app.apex_to_needle_dist, app.rotation_order_choice,...
                app.rotation_angles, output_directory);

            app.Lamp.Color = 'green';
            pause(0.1);
            app.EditField.Value = 'Registration is now complete!';

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            imagesc(app.UIAxes_14,app.registered_US(range, azimuth, round(app.overlay_slice)), app.Bmode_image_scale)
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');

            app.registered_US_flag = true;

            % If not loading previous registration and applying current
            % registration, save the registration parameters.
            if ~load_registration_parameters_flag
                output_directory_regist_param = strcat(app.output_path, ...
                    app.registration_parameters_extension, ...
                    app.drop_down_val(1:end-3),app.registration_parameters_file_extension);

                echo_apex_on_MRI = app.apex_loc;
                apex_to_needle_in_z = app.apex_to_needle_dist;
                order_of_rotation = app.rotation_order_choice;
                rot_angles = app.rotation_angles;
                save(output_directory_regist_param, 'echo_apex_on_MRI',...
                    'apex_to_needle_in_z', 'order_of_rotation', ...
                    'rot_angles')
            end

            if load_registration_parameters_flag
                directory = strcat(app.output_path, ...
                    app.registration_parameters_extension, ...
                    app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
                registration_parameters = load(directory);

                echo_apex_on_MRI = num2str(registration_parameters.echo_apex_on_MRI(1:2));
                needle_track_slice_number = num2str(registration_parameters.echo_apex_on_MRI(3));
                apex_to_needle_in_z = num2str(registration_parameters.apex_to_needle_in_z);
                order_of_rotation = registration_parameters.order_of_rotation;
                rot_angles = num2str(registration_parameters.rot_angles);

                app.apex_loc = [echo_apex_on_MRI,needle_track_slice_number];
                app.apex_to_needle_dist = apex_to_needle_in_z;
                app.rotation_order_choice = order_of_rotation;
                app.rotation_angles = rot_angles;

                app.UITable2.Data(:,2) = {echo_apex_on_MRI;...
                    needle_track_slice_number;...
                    apex_to_needle_in_z;...
                    order_of_rotation;...
                    rot_angles};
            end
        end

        % Value changed function: NeedletrackslicenumberEditField
        function NeedletrackslicenumberEditFieldValueChanged(app, event)
            value = app.NeedletrackslicenumberEditField.Value;
            app.apex_loc(3) = round(value);

            app.UITable2.Data(2,2) = {num2str(app.apex_loc(3))};
        end

        % Button pushed function: OverlayultrasoundButton
        function OverlayultrasoundButtonPushed(app, event)
            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13, vol, app.MR_image_grayscale)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13, app.US_overlay, "AlphaData", app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice)),app.MR_image_grayscale);
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end

            app.AlphaSlider.Limits = [0 1];

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                app.OverlayultrasoundButton.Text = 'Remove overlay';
                app.overlay_flag = true;
            else
                app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
                app.overlay_flag = false;
            end
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            if app.overlay_flag
                app.overlay_threshold = event.Value;

                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end

                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13, vol, app.MR_image_grayscale)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = abs(app.registered_US(range, azimuth, round(app.overlay_slice)));
                %                 app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            end
        end

        % Button pushed function: ApplytoLRUSButton
        function ApplytoLRUSButtonPushed(app, event)
            if app.LRUSIQfilenumberEditField.Value == 0
                warndlg('You have not specified the IQ file number for the low resolution US image to apply registration to.','Warning');
            elseif ~app.has_HR(app.trial_number)
                warndlg('This trial does not have HR images, so the registration done here is applied to the LR.', 'warning');
            else
                Bmode_LR = app.Bmode_vol_LR(:,:,:,app.LRUSIQfilenumberEditField.Value);

                app.Lamp.Color = 'red';
                pause(0.5);
                app.EditField.Value = 'Registration in progress...';
                output_directory = strcat(app.output_path, ...
                    app.registered_US_decorr_extension, ...
                    app.drop_down_val(1:end-3),...
                    app.registered_US_decorr_file_extension{2});

                [app.registered_US_LR, load_registration_parameters_flag] = register(app.post_abl_1day_obj, ...
                    Bmode_LR, app.xyz_range_LR, app.apex_loc,...
                    app.apex_to_needle_dist,app.rotation_order_choice,...
                    app.rotation_angles, output_directory);

                app.Lamp.Color = 'green';
                app.EditField.Value = 'Registration is now complete!';

                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end

                vol = app.registered_US_LR(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_15, vol, app.Bmode_image_scale)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');

                app.registered_US_LR_flag = true;

                if load_registration_parameters_flag
                    directory = strcat(app.output_path, ...
                        app.registration_parameters_extension, ...
                        app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
                    registration_parameters = load(directory);

                    echo_apex_on_MRI = registration_parameters.echo_apex_on_MRI(1:2);
                    needle_track_slice_number = registration_parameters.echo_apex_on_MRI(3);
                    apex_to_needle_in_z = registration_parameters.apex_to_needle_in_z;
                    order_of_rotation = registration_parameters.order_of_rotation;
                    rot_angles = registration_parameters.rot_angles;

                    app.apex_loc = [echo_apex_on_MRI,needle_track_slice_number];
                    app.apex_to_needle_dist = apex_to_needle_in_z;
                    app.rotation_order_choice = order_of_rotation;
                    app.rotation_angles = rot_angles;

                    app.UITable2.Data(:,2) = {num2str(echo_apex_on_MRI);...
                        num2str(needle_track_slice_number);...
                        num2str(apex_to_needle_in_z);...
                        order_of_rotation;...
                        num2str(rot_angles)};
                end
            end
        end

        % Button pushed function: 
        % ApplyregistrationtoechodecorrelationandIBSButton
        function ApplyregistrationtoechodecorrelationandIBSButtonPushed(app, event)
            app.Lamp_2.Color = 'red';
            pause(0.5);

            for i = 1:length(app.imaging_parameters)

                switch i
                    case 1
                        vol_to_register = app.local_cum_decorr_lastframe;
                    case 2
                        vol_to_register = app.local_cum_decorr_motcorr_lastframe;
                    case 3
                        vol_to_register = app.global_cum_decorr_lastframe;
                    case 4
                        vol_to_register = app.global_cum_decorr_motcorr_lastframe;
                    case 5
                        vol_to_register = app.combined_cum_decorr_lastframe;
                    case 6
                        vol_to_register = app.combined_cum_decorr_motcorr_lastframe;
                    case 7
                        vol_to_register = app.cum_IBS_lastframe;
                end

                app.EditField_2.Value = ['Applying registration to ', ...
                    app.imaging_parameters{i}, '(registratoin ', ...
                    num2str(i), ' out of ', num2str(length(app.imaging_parameters)), ')'];

                output_directory = strcat(app.output_path, ...
                    app.registered_US_decorr_extension, ...
                    app.drop_down_val(1:end-3),...
                    app.registered_US_decorr_file_extension{i+2});

                [vol, ~] = register(app.post_abl_1day_obj, ...
                    vol_to_register, app.xyz_range_LR, app.apex_loc,...
                    app.apex_to_needle_dist,app.rotation_order_choice,...
                    app.rotation_angles, output_directory);

                if i ~= 7
                    vol_scaled = scale_decorrelation_map(vol, app.max_decorr, app.dyn_range);
                end

                switch i
                    case 1
                        app.registered_local_decorr_unscaled = vol;
                        app.registered_local_decorr_scaled = vol_scaled;
                    case 2
                        app.registered_local_motcorr_decorr_unscaled = vol;
                        app.registered_local_motcorr_decorr_scaled = vol_scaled;
                    case 3
                        app.registered_global_decorr_unscaled = vol;
                        app.registered_global_decorr_scaled = vol_scaled;
                    case 4
                        app.registered_global_motcorr_decorr_unscaled = vol;
                        app.registered_global_motcorr_decorr_scaled = vol_scaled;
                    case 5
                        app.registered_combined_decorr_unscaled = vol;
                        app.registered_combined_decorr_scaled = vol_scaled;
                    case 6
                        app.registered_combined_motcorr_decorr_unscaled = vol;
                        app.registered_combined_motcorr_decorr_scaled = vol_scaled;
                    case 7
                        app.registered_IBS = vol;
                end

            end

            app.EditField_2.Value = 'All registrations are done! Choose an imaging parameter to show on the plots below.';

            app.Lamp_2.Color = 'green';

            %             if load_registration_parameters_flag
            %                 directory = strcat(app.output_path, ...
            %                     app.registration_parameters_extension, ...
            %                     app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
            %                 registration_parameters = load(directory);
            %
            %                 echo_apex_on_MRI = registration_parameters.echo_apex_on_MRI(1:2);
            %                 needle_track_slice_number = registration_parameters.echo_apex_on_MRI(3);
            %                 apex_to_needle_in_z = registration_parameters.apex_to_needle_in_z;
            %                 order_of_rotation = registration_parameters.order_of_rotation;
            %                 rot_angles = registration_parameters.rot_angles;
            %
            %                 app.apex_loc = [echo_apex_on_MRI,needle_track_slice_number];
            %                 app.apex_to_needle_dist = apex_to_needle_in_z;
            %                 app.rotation_order_choice = order_of_rotation;
            %                 app.rotation_angles = rot_angles;
            %
            %                 app.UITable2.Data(:,2) = {num2str(echo_apex_on_MRI);...
            %                     num2str(needle_track_slice_number);...
            %                     num2str(apex_to_needle_in_z);...
            %                 order_of_rotation;...
            %                 num2str(rot_angles)};
            %             end
        end

        % Value changing function: SliceSlider_8
        function SliceSlider_8ValueChanging(app, event)
            app.slider8value = round(event.Value);
            app.slider8value_mm = app.slider8value * app.dicom_dxdydz(3);
            app.SliceSlider_8Label.Text = num2str(app.slider8value);

            if app.just_show_postop_image_flag
                % Transverse plane
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, ...
                    app.post_abl_1day_vol(:,:,app.slider8value),app.MR_image_grayscale);
                axis(app.UIAxes_12,'image');
                colormap(app.UIAxes_12,'gray');
            else
                climB = [];
                climF = [0 1];
                switch app.display_decorr
                    case app.imaging_parameters{1}
                        image_vol = app.registered_local_decorr_scaled;
                    case app.imaging_parameters{2}
                        image_vol = app.registered_local_motcorr_decorr_scaled;
                    case app.imaging_parameters{3}
                        image_vol = app.registered_global_decorr_scaled;
                    case app.imaging_parameters{4}
                        image_vol = app.registered_global_motcorr_decorr_scaled;
                    case app.imaging_parameters{5}
                        image_vol = app.registered_combined_decorr_scaled;
                    case app.imaging_parameters{6}
                        image_vol = app.registered_combined_motcorr_decorr_scaled;
                    case app.imaging_parameters{7}
                        image_vol = app.registered_IBS;
                        climF = [0 20];
                end

                alphaMap = app.alpha3value * image_vol/max(image_vol(:));

                % Transverse plane
                if ~isempty(app.mask3D)
                    mask_edge = edge(app.mask3D(:,:,app.slider8value));
                else
                    mask_edge = [];
                end
                mapDecorr = squeeze(image_vol(:,:,app.slider8value));
                mapMR = squeeze(app.post_abl_1day_vol(:,:,app.slider8value));
                mapAlpha = squeeze(alphaMap(:,:,app.slider8value));
                imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                    app.dicom_x, app.dicom_y, mask_edge, app.UIAxes_12);
            end

            hold(app.UIAxes_12,'on');
            app.xline1_handle = xline(app.UIAxes_12,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline1_handle = yline(app.UIAxes_12,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            app.xline2_handle.Visible = 'off';
            app.yline3_handle.Visible = 'off';

            hold(app.UIAxes_9,'on');
            app.xline2_handle = xline(app.UIAxes_9,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');

            hold(app.UIAxes_10,'on');
            app.yline3_handle = yline(app.UIAxes_10,app.SliceSlider_8.Limits(2)*app.dicom_dxdydz(3)-app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');
            close(gcf);
        end

        % Button pushed function: DisplayMRIButton
        function DisplayMRIButtonPushed(app, event)
            if isempty(app.post_abl_1day_vol)
                directory = strcat(app.input_path, app.postop_1day_extension, app.drop_down_val(1:end-3), app.postop_1day_subfolder_extension);
                [~, app.post_abl_1day_vol] = read_1day_post_ablation_image_vol(directory);
                app.dicom_x = (1:size(app.post_abl_1day_vol,2))*app.dicom_dxdydz(1);
                app.dicom_y = (1:size(app.post_abl_1day_vol,1))*app.dicom_dxdydz(2);
                app.dicom_z = (1:size(app.post_abl_1day_vol,3))*app.dicom_dxdydz(3);
                [app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh] = ...
                    meshgrid(app.dicom_x, app.dicom_y, app.dicom_z);
            end
            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16, app.post_abl_1day_vol(:,:,app.slider8value), app.MR_image_grayscale)
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');

            app.SliceSpinner.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.post_abl_1day_segment_ROI_flag = false;
        end

        % Value changed function: AblationfirstsliceEditField
        function AblationfirstsliceEditFieldValueChanged(app, event)
            app.lesion_slice(1) = app.AblationfirstsliceEditField.Value;

            app.SliceSpinner.Limits = [app.lesion_slice(1) app.SliceSpinner.Limits(2)];
            app.SliceSpinner.Value = app.lesion_slice(1);

            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16, app.post_abl_1day_vol(:,:,round(app.lesion_slice(1))), app.MR_image_grayscale)
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');
        end

        % Value changed function: AblationlastsliceEditField
        function AblationlastsliceEditFieldValueChanged(app, event)
            app.lesion_slice(2) = app.AblationlastsliceEditField.Value;

            app.SliceSpinner.Limits = [app.SliceSpinner.Limits(1) app.lesion_slice(2)];
        end

        % Button pushed function: EditpolygonButton
        function EditpolygonButtonPushed(app, event)
            roiPoly = drawpolygon(app.UIAxes_16);
            app.mask = createMask(roiPoly);

            imagesc(app.UIAxes_18, app.mask);
            axis(app.UIAxes_18,'image');
            colormap(app.UIAxes_18,'gray');

            app.mask_number = app.SliceSpinner.Value - app.lesion_slice(1) + 1;
            app.mask3D(:,:,app.mask_number) = app.mask;
            pause(.1);

            imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)+1), app.MR_image_grayscale);
            axis(app.UIAxes_16,'image');
            colormap(app.UIAxes_16,'gray');
            if app.SliceSpinner.Value < app.SliceSpinner.Limits (2)
                app.SliceSpinner.Value = app.SliceSpinner.Value + 1;
            end
        end

        % Button pushed function: FinishsegmentationButton
        function FinishsegmentationButtonPushed(app, event)
            ablation_mask3D = zeros(size(app.post_abl_1day_vol));
            ablation_masks2D = zeros(size(ablation_mask3D,1), ...
                size(ablation_mask3D,2), size(app.mask3D,3));

            segment_ROI_Zrange = ...
                app.AblationfirstsliceEditField.Value:app.AblationlastsliceEditField.Value;
            if app.post_abl_1day_segment_ROI_flag
                ablation_mask3D(app.segment_ROI_Yrange, app.segment_ROI_Xrange, ...
                    segment_ROI_Zrange) = app.mask3D;
                ablation_masks2D(app.segment_ROI_Yrange, app.segment_ROI_Xrange,:) ...
                    = app.mask3D;
            else
                ablation_mask3D(:, :, segment_ROI_Zrange) = app.mask3D;
                ablation_masks2D = app.mask3D;
            end

            close(gcf);
            quest = 'The 3D mask already exists. Do you want to overwrite?';
            dlgtitle = 'Overwriting warning';
            btn1 = 'No';
            btn2 = 'Yes';
            defbtn = 'No';
            output_directory = strcat(app.output_path, ...
                app.ablation_mask3D_extension, app.drop_down_val(1:end-3), ...
                app.ablation_mask3D_file_extension);

            if exist(output_directory, 'file')
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                if strcmp(answer, 'Yes')
                    save(output_directory,'ablation_mask3D','ablation_masks2D');
                end
            else
                save(output_directory,'ablation_mask3D','ablation_masks2D');
            end

            % Display the 3D ablated mask
            app.mask3D = ablation_mask3D;
            mask3D_gauss = imgaussfilt3(app.mask3D,2);

            cla(app.UIAxes_18);
            p = patch(app.UIAxes_18,isosurface(app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, mask3D_gauss,.5));
            p.FaceColor = 'red';
            p.EdgeColor = 'none';
            app.UIAxes_18.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_18, 3);
            axis(app.UIAxes_18,'tight');
            camlight(app.UIAxes_18);
            lighting gouraud
            app.UIAxes_18.XLim = [0 max(app.dicom_xmesh(:))];
            app.UIAxes_18.YLim = [0 max(app.dicom_ymesh(:))];
            app.UIAxes_18.ZLim = [0 max(app.dicom_zmesh(:))];
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            close(gcf); % to close the extra figure the code generate by mistake

            app.post_abl_1day_segment_ROI_flag = false;
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            if app.post_abl_1day_segment_ROI_flag

                imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)), app.MR_image_grayscale);
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');

            else

                imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.SliceSpinner.Value)), app.MR_image_grayscale);
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');

            end
        end

        % Button pushed function: DisplayROCcurvesButton
        function DisplayROCcurvesButtonPushed(app, event)
            input_directory_regist_decorrs = strcat(app.output_path, ...
                app.registered_US_decorr_extension);
            input_directory_ablation_masks = strcat(app.output_path, ...
                app.ablation_mask3D_extension);
            output_directory = strcat(app.output_path, ...
                app.ROC_extension, app.ROC_trials);

            if strcmp(app.ROC_trials, 'All trials')
                [app.AUC_TPR_FPR, app.AUC_PPV_TPR, app.ppv, app.tpr, app.fpr, ~, trial_count] = ...
                    ROC_analysis(app.ROC_trials, input_directory_regist_decorrs, ...
                    input_directory_ablation_masks, output_directory);
            else
                if isempty(app.registered_IBS)
                    temp = {};
                    for i = 1:length(app.imaging_parameters)
                        directory = strcat(input_directory_regist_decorrs,...
                            app.drop_down_val(1:end-3),...
                            app.registered_US_decorr_file_extension{i+2});

                        temp{i} = load(directory);
                        temp{i} = temp{i}.registered_vol;
                    end

                    curr_trial_decorr_IBS_obj.registered_local_decorr_unscaled = temp{1};
                    curr_trial_decorr_IBS_obj.registered_local_motcorr_decorr_unscaled = temp{2};

                    curr_trial_decorr_IBS_obj.registered_global_decorr_unscaled = temp{3};
                    curr_trial_decorr_IBS_obj.registered_global_motcorr_decorr_unscaled = temp{4};

                    curr_trial_decorr_IBS_obj.registered_combined_decorr_unscaled = temp{5};
                    curr_trial_decorr_IBS_obj.registered_combined_motcorr_decorr_unscaled = temp{6};

                    curr_trial_decorr_IBS_obj.registered_IBS = temp{1};

                else
                    curr_trial_decorr_IBS_obj.registered_local_decorr_unscaled = ...
                        app.registered_local_decorr_unscaled;
                    curr_trial_decorr_IBS_obj.registered_local_motcorr_decorr_unscaled = ...
                        app.registered_local_motcorr_decorr_unscaled;

                    curr_trial_decorr_IBS_obj.registered_global_decorr_unscaled = ...
                        app.registered_global_decorr_unscaled;
                    curr_trial_decorr_IBS_obj.registered_global_motcorr_decorr_unscaled = ...
                        app.registered_global_motcorr_decorr_unscaled;

                    curr_trial_decorr_IBS_obj.registered_combined_decorr_unscaled = ...
                        app.registered_combined_decorr_unscaled;
                    curr_trial_decorr_IBS_obj.registered_combined_motcorr_decorr_unscaled = ...
                        app.registered_combined_motcorr_decorr_unscaled;

                    curr_trial_decorr_IBS_obj.registered_IBS = ...
                        app.registered_IBS;

                end
                if isempty(app.mask3D)
                    directory = strcat(input_directory_ablation_masks,...
                        app.drop_down_val(1:end-3),...
                        app.ablation_mask3D_file_extension);
                    temp2 = load(directory);
                    temp2 = temp2.ablation_mask3D;
                    curr_trial_mask = temp2;
                else
                    curr_trial_mask = app.mask3D;
                end

                [app.AUC_TPR_FPR, app.AUC_PPV_TPR, app.ppv, app.tpr, app.fpr, ~, trial_count] = ...
                    ROC_analysis(app.ROC_trials, input_directory_regist_decorrs, ...
                    input_directory_ablation_masks, output_directory, ...
                    curr_trial_decorr_IBS_obj, curr_trial_mask);
            end

            app.EditField_3.Value = ['Displaying ROC curves for ', num2str(trial_count), ' trials.'];

            if strcmp(app.ListBox.Value,'TPR vs. FPR')
                cla(app.UIAxes_17);
                for i = 1:size(app.ppv,2)
                    if rem(i,2) == 0
                        line_style = '--';
                    else
                        line_style = '-';
                    end
                    plot(app.UIAxes_17,app.fpr(:,i),app.tpr(:,i),'Color',...
                        app.ROC_curves_colors(i,:),'LineWidth',2,...
                        'LineStyle',line_style,...
                        'DisplayName', app.imaging_parameters{i});
                    hold(app.UIAxes_17, 'on');
                end
                p = plot(app.UIAxes_17,[0 1],[0 1],':k','LineWidth',1);
                p = plot(app.UIAxes_17,[0 1],[0 1],':k','LineWidth',1);
                hold(app.UIAxes_17, 'off');
                xlabel(app.UIAxes_17, 'FPR (1-specificity)');
                ylabel(app.UIAxes_17, 'TPR (sensitivity)');
                app.UIAxes_17.XTick = [0 0.2 0.4 0.6 0.8 1];
                app.UIAxes_17.YTick = [0 0.2 0.4 0.6 0.8 1];
                axis(app.UIAxes_17,'equal','tight');
                app.UIAxes_17.XLim = [-.001 1.001];
                app.UIAxes_17.YLim = [-.001 1.001];
                legend(app.UIAxes_17,'Location','SouthEast','FontSize',10)
                grid(app.UIAxes_17,'on');

                AUC = app.AUC_TPR_FPR;
            else
                cla(app.UIAxes_17);
                for i = 1:size(app.ppv,2)
                    if rem(i,2) == 0
                        line_style = '--';
                    else
                        line_style = '-';
                    end
                    plot(app.UIAxes_17,app.tpr(:,i),app.ppv(:,i),'Color',...
                        app.ROC_curves_colors(i,:),'LineWidth',2,...
                        'LineStyle',line_style,...
                        'DisplayName', app.imaging_parameters{i});
                    hold(app.UIAxes_17, 'on');
                end
                hold(app.UIAxes_17, 'off');
                xlabel(app.UIAxes_17, 'TPR (sensitivity)');
                ylabel(app.UIAxes_17, 'PPV (precision)');
                app.UIAxes_17.XTick = [0 0.2 0.4 0.6 0.8 1];
                app.UIAxes_17.YTick = [0 0.2 0.4 0.6 0.8 1];
                axis(app.UIAxes_17,'equal','tight');
                app.UIAxes_17.XLim = [-.001 1.001];
                app.UIAxes_17.YLim = [-.001 1.001];
                legend(app.UIAxes_17,'Location','best','FontSize',10)
                grid(app.UIAxes_17,'on');

                AUC = app.AUC_PPV_TPR;
            end

            app.LocalnormAUCEditField.Value = round(AUC(1),3);
            app.LocalnormAUCEditField_2.Value = round(AUC(2),3);

            app.GlobalnormAUCEditField.Value = round(AUC(3),3);
            app.GlobalnormAUCEditField_2.Value = round(AUC(4),3);

            app.CombinednormAUCEditField.Value = round(AUC(5),3);
            app.CombinednormAUCEditField_2.Value = round(AUC(6),3);

            app.IBSAUCEditField.Value = round(AUC(7),3);
        end

        % Value changing function: SliceSlider_10
        function SliceSlider_10ValueChanging(app, event)
            app.slider10value = round(event.Value);
            app.slider10value_mm = app.slider10value * app.dicom_dxdydz(1);
            app.SliceSlider_10Label.Text = num2str(app.slider10value);

            if app.just_show_postop_image_flag
                % Sagittal plane
                imagesc(app.UIAxes_9, app.dicom_z, app.dicom_y, ...
                    squeeze(app.post_abl_1day_vol(:,app.slider10value,:)),app.MR_image_grayscale);
                axis(app.UIAxes_9,'image');
                colormap(app.UIAxes_9,'gray');
            else
                climB = [];
                climF = [0 1];
                switch app.display_decorr
                    case app.imaging_parameters{1}
                        image_vol = app.registered_local_decorr_scaled;
                    case app.imaging_parameters{2}
                        image_vol = app.registered_local_motcorr_decorr_scaled;
                    case app.imaging_parameters{3}
                        image_vol = app.registered_global_decorr_scaled;
                    case app.imaging_parameters{4}
                        image_vol = app.registered_global_motcorr_decorr_scaled;
                    case app.imaging_parameters{5}
                        image_vol = app.registered_combined_decorr_scaled;
                    case app.imaging_parameters{6}
                        image_vol = app.registered_combined_motcorr_decorr_scaled;
                    case app.imaging_parameters{7}
                        image_vol = app.registered_IBS;
                        climF = [0 20];
                end

                alphaMap = app.alpha3value * image_vol/max(image_vol(:));

                % Y vs. Z
                if ~isempty(app.mask3D)
                    mask_edge = edge(squeeze(app.mask3D(:,app.slider10value,:)));
                else
                    mask_edge = [];
                end
                mapDecorr = squeeze(image_vol(:,app.slider10value,:));
                mapMR = squeeze(app.post_abl_1day_vol(:,app.slider10value,:));
                mapAlpha = squeeze(alphaMap(:,app.slider10value,:));
                imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                    app.dicom_z,app.dicom_y, mask_edge, app.UIAxes_9);
            end

            hold(app.UIAxes_9,'on');
            app.xline2_handle = xline(app.UIAxes_9,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            app.yline2_handle = yline(app.UIAxes_9,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');

            app.xline1_handle.Visible = 'off';
            app.xline3_handle.Visible = 'off';

            hold(app.UIAxes_12,'on');
            app.xline1_handle = xline(app.UIAxes_12,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            hold(app.UIAxes_10,'on');
            app.xline3_handle = xline(app.UIAxes_10,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');
            close(gcf);
        end

        % Value changing function: SliceSlider_11
        function SliceSlider_11ValueChanging(app, event)
            app.slider11value = round(event.Value);
            app.slider11value_mm = app.slider11value * app.dicom_dxdydz(2);
            app.SliceSlider_11Label.Text = num2str(app.slider11value);

            if app.just_show_postop_image_flag
                % Coronal plane
                imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, ...
                    rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:))),app.MR_image_grayscale);
                axis(app.UIAxes_10,'image');
                colormap(app.UIAxes_10,'gray');
            else
                climB = [];
                climF = [0 1];
                switch app.display_decorr
                    case app.imaging_parameters{1}
                        image_vol = app.registered_local_decorr_scaled;
                    case app.imaging_parameters{2}
                        image_vol = app.registered_local_motcorr_decorr_scaled;
                    case app.imaging_parameters{3}
                        image_vol = app.registered_global_decorr_scaled;
                    case app.imaging_parameters{4}
                        image_vol = app.registered_global_motcorr_decorr_scaled;
                    case app.imaging_parameters{5}
                        image_vol = app.registered_combined_decorr_scaled;
                    case app.imaging_parameters{6}
                        image_vol = app.registered_combined_motcorr_decorr_scaled;
                    case app.imaging_parameters{7}
                        image_vol = app.registered_IBS;
                        climF = [0 20];
                end

                alphaMap = app.alpha3value * image_vol/max(image_vol(:));

                % Z vs. X
                if ~isempty(app.mask3D)
                    mask_edge = edge(rot90(squeeze(app.mask3D(app.slider11value,:,:))));
                else
                    mask_edge = [];
                end
                mapDecorr = rot90(squeeze(image_vol(app.slider11value,:,:)));
                mapMR = rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:)));
                mapAlpha = rot90(squeeze(alphaMap(app.slider11value,:,:)));
                imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                    app.dicom_x,app.dicom_z, mask_edge, app.UIAxes_10);
            end

            hold(app.UIAxes_10,'on');
            app.xline3_handle = xline(app.UIAxes_10,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline3_handle = yline(app.UIAxes_10,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');

            app.yline1_handle.Visible = 'off';
            app.yline2_handle.Visible = 'off';

            hold(app.UIAxes_12,'on');
            app.yline1_handle = yline(app.UIAxes_12,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            hold(app.UIAxes_9,'on');
            app.yline2_handle = yline(app.UIAxes_9,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');
            close(gcf);
        end

        % Value changed function: DisplaydecorrelationIBSDropDown
        function DisplaydecorrelationIBSDropDownValueChanged(app, event)
            app.display_decorr = app.DisplaydecorrelationIBSDropDown.Value;
            app.just_show_postop_image_flag = false;
            climB = [];
            climF = [0 1];
            threshold = app.IsosurfacethresholdEditField.Value;

            switch app.display_decorr
                case app.imaging_parameters{1}
                    image_vol = app.registered_local_decorr_scaled;
                case app.imaging_parameters{2}
                    image_vol = app.registered_local_motcorr_decorr_scaled;
                case app.imaging_parameters{3}
                    image_vol = app.registered_global_decorr_scaled;
                case app.imaging_parameters{4}
                    image_vol = app.registered_global_motcorr_decorr_scaled;
                case app.imaging_parameters{5}
                    image_vol = app.registered_combined_decorr_scaled;
                case app.imaging_parameters{6}
                    image_vol = app.registered_combined_motcorr_decorr_scaled;
                case app.imaging_parameters{7}
                    image_vol = app.registered_IBS;
                    climF = [0 20];
            end

            if ~strcmp(app.display_decorr,app.imaging_parameters{7})
                threshold = (log10(10^threshold/app.max_decorr)+app.dyn_range)/app.dyn_range;
            end

            alphaMap = app.alpha3value * image_vol/max(image_vol(:));

            % Y vs. X
            if ~isempty(app.mask3D)
                mask_edge = edge(app.mask3D(:,:,app.slider8value));
            else
                mask_edge = [];
            end
            mapDecorr = squeeze(image_vol(:,:,app.slider8value));
            mapMR = squeeze(app.post_abl_1day_vol(:,:,app.slider8value));
            mapAlpha = squeeze(alphaMap(:,:,app.slider8value));
            imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                app.dicom_x, app.dicom_y, mask_edge, app.UIAxes_12);
            hold(app.UIAxes_12,'on');
            app.xline1_handle = xline(app.UIAxes_12,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline1_handle = yline(app.UIAxes_12,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            % Y vs. Z
            if ~isempty(app.mask3D)
                mask_edge = edge(squeeze(app.mask3D(:,app.slider10value,:)));
            else
                mask_edge = [];
            end
            mapDecorr = squeeze(image_vol(:,app.slider10value,:));
            mapMR = squeeze(app.post_abl_1day_vol(:,app.slider10value,:));
            mapAlpha = squeeze(alphaMap(:,app.slider10value,:));
            imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                app.dicom_z, app.dicom_y, mask_edge, app.UIAxes_9);
            hold(app.UIAxes_9,'on');
            app.xline2_handle = xline(app.UIAxes_9,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            app.yline2_handle = yline(app.UIAxes_9,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');

            % Z vs. X
            if ~isempty(app.mask3D)
                mask_edge = edge(rot90(squeeze(app.mask3D(app.slider11value,:,:))));
            else
                mask_edge = [];
            end
            mapDecorr = rot90(squeeze(image_vol(app.slider11value,:,:)));
            mapMR = rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:)));
            mapAlpha = rot90(squeeze(alphaMap(app.slider11value,:,:)));
            imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                app.dicom_x, app.dicom_z, mask_edge, app.UIAxes_10);
            hold(app.UIAxes_10,'on');
            app.xline3_handle = xline(app.UIAxes_10,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline3_handle = yline(app.UIAxes_10,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');

            % 3D image in the 4th pannel
            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, ...
                app.dicom_zmesh, app.post_abl_1day_vol,...
                app.slider10value*app.dicom_dxdydz(1),0,...
                app.slider8value*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5);
            colormap(app.UIAxes_11,'gray');
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');

            image_vol_gauss = imgaussfilt3(image_vol,2);
            p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, ...
                app.dicom_ymesh, app.dicom_zmesh, ...
                image_vol_gauss, threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            close(gcf);
        end

        % Value changing function: AlphaSlider_3
        function AlphaSlider_3ValueChanging(app, event)
            app.alpha3value = event.Value;

            climB = [];
            climF = [0 1];
            switch app.display_decorr
                case app.imaging_parameters{1}
                    image_vol = app.registered_local_decorr_scaled;
                case app.imaging_parameters{2}
                    image_vol = app.registered_local_motcorr_decorr_scaled;
                case app.imaging_parameters{3}
                    image_vol = app.registered_global_decorr_scaled;
                case app.imaging_parameters{4}
                    image_vol = app.registered_global_motcorr_decorr_scaled;
                case app.imaging_parameters{5}
                    image_vol = app.registered_combined_decorr_scaled;
                case app.imaging_parameters{6}
                    image_vol = app.registered_combined_motcorr_decorr_scaled;
                case app.imaging_parameters{7}
                    image_vol = app.registered_IBS;
                    climF = [0 20];
            end

            alphaMap = app.alpha3value * image_vol/max(image_vol(:));

            % Y vs. X
            if ~isempty(app.mask3D)
                mask_edge = edge(app.mask3D(:,:,app.slider8value));
            else
                mask_edge = [];
            end
            mapDecorr = squeeze(image_vol(:,:,app.slider8value));
            mapMR = squeeze(app.post_abl_1day_vol(:,:,app.slider8value));
            mapAlpha = squeeze(alphaMap(:,:,app.slider8value));
            imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                app.dicom_x, app.dicom_y, mask_edge, app.UIAxes_12);
            hold(app.UIAxes_12,'on');
            app.xline1_handle = xline(app.UIAxes_12,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline1_handle = yline(app.UIAxes_12,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            % Y vs. Z
            if ~isempty(app.mask3D)
                mask_edge = edge(squeeze(app.mask3D(:,app.slider10value,:)));
            else
                mask_edge = [];
            end
            mapDecorr = squeeze(image_vol(:,app.slider10value,:));
            mapMR = squeeze(app.post_abl_1day_vol(:,app.slider10value,:));
            mapAlpha = squeeze(alphaMap(:,app.slider10value,:));
            imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                app.dicom_z, app.dicom_y, mask_edge, app.UIAxes_9);
            hold(app.UIAxes_9,'on');
            app.xline2_handle = xline(app.UIAxes_9,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            app.yline2_handle = yline(app.UIAxes_9,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');

            % Z vs. X
            if ~isempty(app.mask3D)
                mask_edge = edge(rot90(squeeze(app.mask3D(app.slider11value,:,:))));
            else
                mask_edge = [];
            end
            mapDecorr = rot90(squeeze(image_vol(app.slider11value,:,:)));
            mapMR = rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:)));
            mapAlpha = rot90(squeeze(alphaMap(app.slider11value,:,:)));
            imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                app.dicom_x, app.dicom_z, mask_edge, app.UIAxes_10);
            hold(app.UIAxes_10,'on');
            app.xline3_handle = xline(app.UIAxes_10,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline3_handle = yline(app.UIAxes_10,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');
            %             ---
            %             % Y vs. X
            %             if ~isempty(app.mask3D)
            %                 mask_edge = edge(app.mask3D(:,:,app.slider8value));
            %             else
            %                 mask_edge = [];
            %             end
            %             mapDecorr = squeeze(image_vol(:,:,app.slider8value));
            %             mapMR = squeeze(app.post_abl_1day_vol(:,:,app.slider8value));
            %             mapAlpha = squeeze(alphaMap(:,:,app.slider8value));
            %             imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
            %                 app.dicom_x, app.dicom_y, mask_edge, app.UIAxes_12);
            %
            %             % Y vs. Z
            %             if ~isempty(app.mask3D)
            %                 mask_edge = edge(squeeze(app.mask3D(:,app.slider10value,:)));
            %             else
            %                 mask_edge = [];
            %             end
            %             mapDecorr = squeeze(image_vol(:,app.slider10value,:));
            %             mapMR = squeeze(app.post_abl_1day_vol(:,app.slider10value,:));
            %             mapAlpha = squeeze(alphaMap(:,app.slider10value,:));
            %             imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
            %                 app.dicom_z,app.dicom_y, mask_edge, app.UIAxes_9);
            %
            %             % Z vs. X
            %             if ~isempty(app.mask3D)
            %                 mask_edge = edge(rot90(squeeze(app.mask3D(app.slider11value,:,:))));
            %             else
            %                 mask_edge = [];
            %             end
            %             mapDecorr = rot90(squeeze(image_vol(app.slider11value,:,:)));
            %             mapMR = rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:)));
            %             mapAlpha = rot90(squeeze(alphaMap(app.slider11value,:,:)));
            %             imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
            %                 app.dicom_x,app.dicom_z, mask_edge, app.UIAxes_10);
        end

        % Drop down opening function: TrialsDropDown
        function TrialsDropDownOpening(app, event)
            app.TrialsDropDown.Items = {'Current trial','All trials'};
            if strcmp(app.TrialsDropDown.Value,'Current trial')
                temp = split(app.drop_down_val,'_');
                app.ROC_trials = temp{1};
            else
                app.ROC_trials = app.TrialsDropDown.Value;
            end
        end

        % Value changed function: TrialsDropDown
        function TrialsDropDownValueChanged(app, event)
            if strcmp(app.TrialsDropDown.Value,'Current trial')
                temp = split(app.drop_down_val,'_');
                app.ROC_trials = temp{1};
            else
                app.ROC_trials = app.TrialsDropDown.Value;
            end
        end

        % Callback function
        function SavefigureButtonPushed(app, event)
            output_directory = strcat(app.output_path, ...
                app.ROC_extension, app.drop_down_val(1:15), '_rhoScalFact2.fig');
            saveas(app.UIAxes_17, output_directory);
        end

        % Button pushed function: DrawrectangularROIButton
        function DrawrectangularROIButtonPushed(app, event)
            if ~app.post_abl_1day_segment_ROI_flag
                app.post_abl_1day_segment_ROI_flag = true;
                roiRect = drawrectangle(app.UIAxes_16);
                post_abl_1day_segment_ROI_pos = round(roiRect.Position);
                app.segment_ROI_Xrange = post_abl_1day_segment_ROI_pos(1) : post_abl_1day_segment_ROI_pos(1) + post_abl_1day_segment_ROI_pos(3);
                app.segment_ROI_Yrange = post_abl_1day_segment_ROI_pos(2) : post_abl_1day_segment_ROI_pos(2) + post_abl_1day_segment_ROI_pos(4);
                app.post_abl_1day_segment_inROI = ...
                    app.post_abl_1day_vol(app.segment_ROI_Yrange, app.segment_ROI_Xrange, :);

                imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)),app.MR_image_grayscale);
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');
            end
        end

        % Button pushed function: DrawrectangularROIButton_2
        function DrawrectangularROIButton_2Pushed(app, event)
            if ~app.post_abl_1day_tab1_ROI_flag
                roiRect = drawrectangle(app.UIAxes_13);
                post_abl_1day_tab1_ROI_pos = round(roiRect.Position);
                app.tab1_ROI_azimuth = post_abl_1day_tab1_ROI_pos(1) : post_abl_1day_tab1_ROI_pos(1) + post_abl_1day_tab1_ROI_pos(3);
                app.tab1_ROI_range = post_abl_1day_tab1_ROI_pos(2) : post_abl_1day_tab1_ROI_pos(2) + post_abl_1day_tab1_ROI_pos(4);
                app.post_abl_1day_tab1_inROI = ...
                    app.post_abl_1day_vol(app.tab1_ROI_range, app.tab1_ROI_azimuth, :);
            end

            if strcmp(app.DrawrectangularROIButton_2.Text,'Draw rectangular ROI')
                app.DrawrectangularROIButton_2.Text = 'Original size images';
                app.post_abl_1day_tab1_ROI_flag = true;
            else
                app.DrawrectangularROIButton_2.Text = 'Draw rectangular ROI';
                app.post_abl_1day_tab1_ROI_flag = false;
            end

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if app.registered_US_flag
                imagesc(app.UIAxes_14, app.registered_US(range, azimuth, round(app.slider7value)), app.Bmode_image_scale)
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15, app.registered_US_LR(range, azimuth, round(app.overlay_slice)), app.Bmode_image_scale)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_13, vol, app.MR_image_grayscale)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:, :, 2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:, :, 2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:, :, 2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13, app.US_overlay, "AlphaData", app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)),app.MR_image_grayscale);
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            elevation_sl = ceil(size(app.Bmode_vol_LR, 3) / 2);
            imagesc(app.UIAxes2, app.Bmode_vol_LR(:, :, elevation_sl, changingValue), app.Bmode_image_scale);
            axis(app.UIAxes2,'equal','tight');
            colormap(app.UIAxes2,'gray');
            app.Label.Text = num2str(changingValue);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            changingValue = event.Value;

            imshow(app.photo1,'Parent',app.UIAxes)
            axis(app.UIAxes,'fill');
            app.UIAxes.XLim = [size(app.photo1, 2) - changingValue * size(app.photo1, 2),...
                changingValue * size(app.photo1, 2)];
            app.UIAxes.YLim = [size(app.photo1, 1) - changingValue * size(app.photo1, 1),...
                changingValue * size(app.photo1, 1)];
            pan(app.UIAxes,'off');
            zoom(app.UIAxes,'off');
            app.ZoomSliderLabel.Text= num2str(changingValue);
        end

        % Value changing function: ZoomSlider_2
        function ZoomSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            imshow(app.photo2,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'fill');
            app.UIAxes_2.XLim = [size(app.photo2, 2) - changingValue * size(app.photo2, 2),...
                changingValue * size(app.photo2, 2)];
            app.UIAxes_2.YLim = [size(app.photo2, 1) - changingValue * size(app.photo2, 1),...
                changingValue * size(app.photo2, 1)];
            pan(app.UIAxes_2,'off');
            zoom(app.UIAxes_2,'off');
            app.ZoomSlider_2Label.Text= num2str(changingValue);
        end

        % Drop down opening function: 
        % DisplaydecorrelationIBSDropDown
        function DisplaydecorrelationIBSDropDownOpening(app, event)
            app.DisplaydecorrelationIBSDropDown.Items = app.imaging_parameters;
        end

        % Button pushed function: 
        % SavefigureincludingallimagingparametersButton
        function SavefigureincludingallimagingparametersButtonPushed(app, event)
            num_crosssections = 3;

            figure('Position',[868    70   892   947]);
            for i = 1:length(app.imaging_parameters)

                climB = [];
                climF = [0 1];
                switch i
                    case 1
                        image_vol = app.registered_local_decorr_scaled;
                    case 2
                        image_vol = app.registered_local_motcorr_decorr_scaled;
                    case 3
                        image_vol = app.registered_global_decorr_scaled;
                    case 4
                        image_vol = app.registered_global_motcorr_decorr_scaled;
                    case 5
                        image_vol = app.registered_combined_decorr_scaled;
                    case 6
                        image_vol = app.registered_combined_motcorr_decorr_scaled;
                    case 7
                        image_vol = app.registered_IBS;
                        climF = [0 20];
                end

                alphaMap = app.alpha3value * image_vol/max(image_vol(:));

                % Y vs. X
                curr_axis = subplot(7, 3, (i-1)*num_crosssections+1);
                if ~isempty(app.mask3D)
                    mask_edge = edge(app.mask3D(:,:,app.slider8value));
                else
                    mask_edge = [];
                end
                mapDecorr = squeeze(image_vol(:,:,app.slider8value));
                mapMR = squeeze(app.post_abl_1day_vol(:,:,app.slider8value));
                mapAlpha = squeeze(alphaMap(:,:,app.slider8value));
                imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                    app.dicom_x, app.dicom_y, mask_edge, curr_axis);
                xticks(gca,[]); yticks([]);

                % Y vs. Z
                curr_axis = subplot(7, 3, (i-1)*num_crosssections+2);
                if ~isempty(app.mask3D)
                    mask_edge = edge(squeeze(app.mask3D(:,app.slider10value,:)));
                else
                    mask_edge = [];
                end
                mapDecorr = squeeze(image_vol(:,app.slider10value,:));
                mapMR = squeeze(app.post_abl_1day_vol(:,app.slider10value,:));
                mapAlpha = squeeze(alphaMap(:,app.slider10value,:));
                imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                    app.dicom_z, app.dicom_y, mask_edge, curr_axis);
                xticks(gca,[]); yticks([]);

                % Z vs. X
                curr_axis = subplot(7, 3, (i-1)*num_crosssections+3);
                if ~isempty(app.mask3D)
                    mask_edge = edge(rot90(squeeze(app.mask3D(app.slider11value,:,:))));
                else
                    mask_edge = [];
                end
                mapDecorr = rot90(squeeze(image_vol(app.slider11value,:,:)));
                mapMR = rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:)));
                mapAlpha = rot90(squeeze(alphaMap(app.slider11value,:,:)));
                imoverlay(mapMR,mapDecorr,climF,climB,'hot',mapAlpha, ...
                    app.dicom_x, app.dicom_z, mask_edge, curr_axis);
                xticks(gca,[]); yticks([]);
            end
            directory = strcat(app.output_path, app.decorr_IBS_figure_extension, ...
                app.drop_down_val(1:end-3), app.decorr_IBS_figure_file_extension);
            saveas(gcf, directory)

            % 3D images
            figure('Position',[326         484        1417         533]);
            for i = 1:length(app.imaging_parameters)

                threshold = app.IsosurfacethresholdEditField.Value;

                switch i
                    case 1
                        image_vol = app.registered_local_decorr_scaled;
                    case 2
                        image_vol = app.registered_local_motcorr_decorr_scaled;
                    case 3
                        image_vol = app.registered_global_decorr_scaled;
                    case 4
                        image_vol = app.registered_global_motcorr_decorr_scaled;
                    case 5
                        image_vol = app.registered_combined_decorr_scaled;
                    case 6
                        image_vol = app.registered_combined_motcorr_decorr_scaled;
                    case 7
                        image_vol = app.registered_IBS;
                end

                if ~strcmp(app.display_decorr,app.imaging_parameters{7})
                    threshold = (log10(10^threshold/app.max_decorr)+app.dyn_range)/app.dyn_range;
                end

                subplot(1, length(app.imaging_parameters), i)
                p1 = slice(gca, app.dicom_xmesh, app.dicom_ymesh, ...
                    app.dicom_zmesh, app.post_abl_1day_vol,...
                    app.slider10value*app.dicom_dxdydz(1),0,...
                    app.slider8value*app.dicom_dxdydz(3));
                set(p1, 'EdgeColor','none', 'FaceColor','interp');
                alpha(p1,.5);
                colormap gray;
                axis image;
                set(gca,'ZTick',[], 'YTick', [], 'XTick', []);
                set(gca,'YDir','reverse');
                hold on;

                image_vol_gauss = imgaussfilt3(image_vol,2);
                p2 = patch(gca,isosurface(app.dicom_xmesh, ...
                    app.dicom_ymesh, app.dicom_zmesh, ...
                    image_vol_gauss,threshold));%squeeze(hybrid(predictor,:,:,:,2)),threshold));
                p2.FaceAlpha = .8;
                p2.FaceColor = 'red';
                p2.EdgeColor = 'none';
                %             app.UIAxes_11.PlotBoxAspectRatio = [1 1 1];
                view(gca, 3);
                axis(gca,'tight');
                camlight(gca);
                lighting gouraud
                %                         app.UIAxes_11.XLim = [0 size(cum_decorr_gauss,1)];
                %                         app.UIAxes_11.YLim = [0 size(cum_decorr_gauss,2)];
                %                         app.UIAxes_11.ZLim = [0 size(cum_decorr_gauss,3)];
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
                hold off;
            end
            directory = strcat(app.output_path,app.decorr_IBS_figure_extension, ...
                app.drop_down_val(1:end-3), app.decorr_IBS_3Dfigure_file_extension);
            saveas(gcf, directory)
        end

        % Button pushed function: 
        % LoadsavedregistrationparametersButton
        function LoadsavedregistrationparametersButtonPushed(app, event)
            directory = strcat(app.output_path, ...
                app.registration_parameters_extension, ...
                app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
            registration_parameters = load(directory);

            echo_apex_on_MRI = registration_parameters.echo_apex_on_MRI(1:2);
            needle_track_slice_number = registration_parameters.echo_apex_on_MRI(3);
            apex_to_needle_in_z = registration_parameters.apex_to_needle_in_z;
            order_of_rotation = registration_parameters.order_of_rotation;
            rot_angles = registration_parameters.rot_angles;

            app.apex_loc = [echo_apex_on_MRI,needle_track_slice_number];
            app.apex_to_needle_dist = apex_to_needle_in_z;
            app.rotation_order_choice = order_of_rotation;
            app.rotation_angles = rot_angles;

            app.UITable2.Data(:,2) = {num2str(echo_apex_on_MRI);...
                num2str(needle_track_slice_number);...
                num2str(apex_to_needle_in_z);...
                order_of_rotation;...
                num2str(rot_angles)};
        end

        % Button pushed function: UpdatetableButton
        function UpdatetableButtonPushed(app, event)
            app.UITable2.Data(:,2) = {num2str(app.apex_loc(1:2));...
                num2str(app.apex_loc(3));...
                num2str(app.apex_to_needle_dist);...
                app.rotation_order_choice;...
                num2str(app.rotation_angles)};
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = round(event.Value);
            elevation_sl = changingValue;
            imagesc(app.UIAxes2, app.Bmode_vol_LR(:, :, elevation_sl, round(app.Slider.Value)), app.Bmode_image_scale);
            axis(app.UIAxes2,'equal','tight');
            colormap(app.UIAxes2,'gray');
            app.Slider_2Label.Text = num2str(changingValue);
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = round(app.XEditField.Value);
            app.apex_loc(1) = value;
            app.UITable2.Data(1,2) = {num2str(app.apex_loc(1:2))};
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = round(app.YEditField.Value);
            app.apex_loc(2) = value;
            app.UITable2.Data(1,2) = {num2str(app.apex_loc(1:2))};
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.slider7value = app.slider7value-1;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if app.registered_US_flag
                imagesc(app.UIAxes_14, app.registered_US(range, azimuth, round(app.slider7value)), app.Bmode_image_scale)
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15, app.registered_US_LR(range, azimuth, round(app.overlay_slice)), app.Bmode_image_scale)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13, vol, app.MR_image_grayscale)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13, app.US_overlay, "AlphaData", app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)),app.MR_image_grayscale);
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.slider7value = app.slider7value+1;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if app.registered_US_flag
                imagesc(app.UIAxes_14, app.registered_US(range, azimuth, round(app.slider7value)), app.Bmode_image_scale)
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15, app.registered_US_LR(range, azimuth, round(app.overlay_slice)), app.Bmode_image_scale)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13, vol, app.MR_image_grayscale)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13, app.US_overlay, "AlphaData", app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)),app.MR_image_grayscale);
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Value changed function: IsosurfacethresholdEditField
        function IsosurfacethresholdEditFieldValueChanged(app, event)
            switch app.display_decorr
                case app.imaging_parameters{1}
                    image_vol = app.registered_local_decorr_scaled;
                case app.imaging_parameters{2}
                    image_vol = app.registered_local_motcorr_decorr_scaled;
                case app.imaging_parameters{3}
                    image_vol = app.registered_global_decorr_scaled;
                case app.imaging_parameters{4}
                    image_vol = app.registered_global_motcorr_decorr_scaled;
                case app.imaging_parameters{5}
                    image_vol = app.registered_combined_decorr_scaled;
                case app.imaging_parameters{6}
                    image_vol = app.registered_combined_motcorr_decorr_scaled;
                case app.imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            threshold = app.IsosurfacethresholdEditField.Value;
            if ~strcmp(app.display_decorr,app.imaging_parameters{7})
                threshold = (log10(10^threshold/app.max_decorr)+app.dyn_range)/app.dyn_range;
            end

            % 3D image in the 4th pannel
            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, ...
                app.dicom_zmesh, app.post_abl_1day_vol,...
                app.slider10value*app.dicom_dxdydz(1),0,...
                app.slider8value*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5);
            colormap(app.UIAxes_11,'gray');
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');

            image_vol_gauss = imgaussfilt3(image_vol,2);
            p2 = patch(app.UIAxes_11, isosurface(app.dicom_xmesh, ...
                app.dicom_ymesh, app.dicom_zmesh, ...
                image_vol_gauss, threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            close(gcf);
        end

        % Value changed function: XsliceEditField
        function XsliceEditFieldValueChanged(app, event)
            switch app.display_decorr
                case app.imaging_parameters{1}
                    image_vol = app.registered_local_decorr_scaled;
                case app.imaging_parameters{2}
                    image_vol = app.registered_local_motcorr_decorr_scaled;
                case app.imaging_parameters{3}
                    image_vol = app.registered_global_decorr_scaled;
                case app.imaging_parameters{4}
                    image_vol = app.registered_global_motcorr_decorr_scaled;
                case app.imaging_parameters{5}
                    image_vol = app.registered_combined_decorr_scaled;
                case app.imaging_parameters{6}
                    image_vol = app.registered_combined_motcorr_decorr_scaled;
                case app.imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            threshold = app.IsosurfacethresholdEditField.Value;
            if ~strcmp(app.display_decorr,app.imaging_parameters{7})
                threshold = (log10(10^threshold/app.max_decorr)+app.dyn_range)/app.dyn_range;
            end

            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, ...
                app.dicom_zmesh, app.post_abl_1day_vol,...
                app.XsliceEditField.Value*app.dicom_dxdydz(1),0,...
                app.ZsliceEditField.Value*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5);
            colormap(app.UIAxes_11,'gray');
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');

            image_vol_gauss = imgaussfilt3(image_vol,2);
            p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, ...
                app.dicom_ymesh, app.dicom_zmesh, ...
                image_vol_gauss, threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            close(gcf);
        end

        % Value changed function: ZsliceEditField
        function ZsliceEditFieldValueChanged(app, event)
            switch app.display_decorr
                case app.imaging_parameters{1}
                    image_vol = app.registered_local_decorr_scaled;
                case app.imaging_parameters{2}
                    image_vol = app.registered_local_motcorr_decorr_scaled;
                case app.imaging_parameters{3}
                    image_vol = app.registered_global_decorr_scaled;
                case app.imaging_parameters{4}
                    image_vol = app.registered_global_motcorr_decorr_scaled;
                case app.imaging_parameters{5}
                    image_vol = app.registered_combined_decorr_scaled;
                case app.imaging_parameters{6}
                    image_vol = app.registered_combined_motcorr_decorr_scaled;
                case app.imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            threshold = app.IsosurfacethresholdEditField.Value;
            if ~strcmp(app.display_decorr,app.imaging_parameters{7})
                threshold = (log10(10^threshold/app.max_decorr)+app.dyn_range)/app.dyn_range;
            end

            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, ...
                app.dicom_zmesh, app.post_abl_1day_vol,...
                app.XsliceEditField.Value*app.dicom_dxdydz(1),0,...
                app.ZsliceEditField.Value*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5);
            colormap(app.UIAxes_11,'gray');
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');

            image_vol_gauss = imgaussfilt3(image_vol,2);
            p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, ...
                app.dicom_ymesh, app.dicom_zmesh, ...
                image_vol_gauss, threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            close(gcf);
        end

        % Value changing function: SliceSlider_12
        function SliceSlider_12ValueChanging(app, event)
            changingValue = round(event.Value);
            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,changingValue),app.MR_image_grayscale)
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');
        end

        % Button pushed function: ShowpostopimageonlyButton
        function ShowpostopimageonlyButtonPushed(app, event)
            app.just_show_postop_image_flag = true;
            cla(app.UIAxes_11); % clear 3D figure

            % Transverse plane
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, app.post_abl_1day_vol(:,:,app.slider8value),app.MR_image_grayscale);
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'gray');
            hold(app.UIAxes_12,'on');
            app.xline1_handle = xline(app.UIAxes_12,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline1_handle = yline(app.UIAxes_12,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_12,'off');

            % Sagittal plane
            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_y, ...
                squeeze(app.post_abl_1day_vol(:,app.slider10value,:)),app.MR_image_grayscale);
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'gray');
            hold(app.UIAxes_9,'on');
            app.xline2_handle = xline(app.UIAxes_9,app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            app.yline2_handle = yline(app.UIAxes_9,app.slider11value_mm, ...
                'Color',app.coronal_color,'LineWidth',2);
            hold(app.UIAxes_9,'off');

            % Coronal plane
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, ...
                rot90(squeeze(app.post_abl_1day_vol(app.slider11value,:,:))),app.MR_image_grayscale);
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'gray');
            hold(app.UIAxes_10,'on');
            app.xline3_handle = xline(app.UIAxes_10,app.slider10value_mm, ...
                'Color',app.sagittal_color,'LineWidth',2);
            app.yline3_handle = yline(app.UIAxes_10,app.SliceSlider_8.Limits(2)*app.dicom_dxdydz(3)-app.slider8value_mm, ...
                'Color',app.transverse_color,'LineWidth',2);
            hold(app.UIAxes_10,'off');
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if strcmp(app.ListBox.Value,'TPR vs. FPR')
                cla(app.UIAxes_17);
                for i = 1:size(app.ppv,2)
                    if rem(i,2) == 0
                        line_style = '--';
                    else
                        line_style = '-';
                    end
                    plot(app.UIAxes_17,app.fpr(:,i),app.tpr(:,i),'Color',...
                        app.ROC_curves_colors(i,:),'LineWidth',2,...
                        'LineStyle',line_style,...
                        'DisplayName', app.imaging_parameters{i});
                    hold(app.UIAxes_17, 'on');
                end
                p = plot(app.UIAxes_17,[0 1],[0 1],':k','LineWidth',1);
                set(get(get(p,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                hold(app.UIAxes_17, 'off');
                xlabel(app.UIAxes_17, 'FPR (1-specificity)');
                ylabel(app.UIAxes_17, 'TPR (sensitivity)');
                app.UIAxes_17.XTick = [0 0.2 0.4 0.6 0.8 1];
                app.UIAxes_17.YTick = [0 0.2 0.4 0.6 0.8 1];
                axis(app.UIAxes_17,'equal','tight');
                app.UIAxes_17.XLim = [-.001 1.001];
                app.UIAxes_17.YLim = [-.001 1.001];
                legend(app.UIAxes_17,'Location','SouthEast','FontSize',10)
                grid(app.UIAxes_17,'on');

                AUC = app.AUC_TPR_FPR;
            else
                cla(app.UIAxes_17);
                for i = 1:size(app.ppv,2)
                    if rem(i,2) == 0
                        line_style = '--';
                    else
                        line_style = '-';
                    end
                    plot(app.UIAxes_17,app.tpr(:,i),app.ppv(:,i),'Color',...
                        app.ROC_curves_colors(i,:),'LineWidth',2,...
                        'LineStyle',line_style,...
                        'DisplayName', app.imaging_parameters{i});
                    hold(app.UIAxes_17, 'on');
                end
                hold(app.UIAxes_17, 'off');
                xlabel(app.UIAxes_17, 'TPR (sensitivity)');
                ylabel(app.UIAxes_17, 'PPV (precision)');
                app.UIAxes_17.XTick = [0 0.2 0.4 0.6 0.8 1];
                app.UIAxes_17.YTick = [0 0.2 0.4 0.6 0.8 1];
                axis(app.UIAxes_17,'equal','tight');
                app.UIAxes_17.XLim = [-.001 1.001];
                app.UIAxes_17.YLim = [-.001 1.001];
                legend(app.UIAxes_17,'Location','best','FontSize',10)
                grid(app.UIAxes_17,'on');

                AUC = app.AUC_PPV_TPR;
            end

            app.LocalnormAUCEditField.Value = round(AUC(1),3);
            app.LocalnormAUCEditField_2.Value = round(AUC(2),3);

            app.GlobalnormAUCEditField.Value = round(AUC(3),3);
            app.GlobalnormAUCEditField_2.Value = round(AUC(4),3);

            app.CombinednormAUCEditField.Value = round(AUC(5),3);
            app.CombinednormAUCEditField_2.Value = round(AUC(6),3);

            app.IBSAUCEditField.Value = round(AUC(7),3);
        end

        % Button pushed function: LoadregisteredUSimagesButton
        function LoadregisteredUSimagesButtonPushed(app, event)
            app.Lamp.Color = 'red';
            pause(0.1);
            app.EditField.Value = 'Loading the US images ...';

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end
            
            input_directory_HR = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.drop_down_val(1:end-3),...
                app.registered_US_decorr_file_extension{1});
            if ~exist(input_directory_HR,'file')
                warndlg(['Registered HR US image does not exist in ',input_directory_HR]);
            else
                temp = load(input_directory_HR);
                app.registered_US = temp.registered_vol;

                vol = app.registered_US(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_14, vol, app.Bmode_image_scale)
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');

                app.registered_US_flag = true;
            end

            input_directory_LR = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.drop_down_val(1:end-3),...
                app.registered_US_decorr_file_extension{2});
            if ~exist(input_directory_LR,'file')
                warndlg(['Registered LR US image does not exist in ',input_directory_LR]);
            else
                temp = load(input_directory_LR);
                app.registered_US_LR = temp.registered_vol;

                vol = app.registered_US_LR(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_15, vol, app.Bmode_image_scale)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');

                app.registered_US_LR_flag = true;
            end

            app.Lamp.Color = 'green';
            pause(0.1);
            app.EditField.Value = 'US images are loaded!';
        end

        % Button pushed function: SaveROCfiguresButton
        function SaveROCfiguresButtonPushed(app, event)
            output_ROC_figure = strcat(...
                app.output_path, app.ROC_extension, app.ROC_trials);

            cla(app.UIAxes_17);
            for i = 1:size(app.ppv,2)
                if rem(i,2) == 0
                    line_style = '--';
                else
                    line_style = '-';
                end
                plot(app.UIAxes_17,app.fpr(:,i),app.tpr(:,i),'Color',...
                    app.ROC_curves_colors(i,:),'LineWidth',2,...
                    'LineStyle',line_style,...
                    'DisplayName', app.imaging_parameters{i});
                hold(app.UIAxes_17, 'on');
            end
            p = plot(app.UIAxes_17,[0 1],[0 1],':k','LineWidth',1);
            set(get(get(p,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
            hold(app.UIAxes_17, 'off');
            xlabel(app.UIAxes_17, 'FPR (1-specificity)');
            ylabel(app.UIAxes_17, 'TPR (sensitivity)');
            app.UIAxes_17.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_17.YTick = [0 0.2 0.4 0.6 0.8 1];
            axis(app.UIAxes_17,'equal','tight');
            app.UIAxes_17.XLim = [-.001 1.001];
            app.UIAxes_17.YLim = [-.001 1.001];
            legend(app.UIAxes_17,'Location','SouthEast','FontSize',10)
            grid(app.UIAxes_17,'on');
            saveas(app.UIAxes_17, [output_ROC_figure, app.ROC_figure_TPR_FPR_extension]);
            app.EditField_3.Value = 'FPR vs. TPR figure is saved.';
            pause(.1);
            
            cla(app.UIAxes_17);
            for i = 1:size(app.ppv,2)
                if rem(i,2) == 0
                    line_style = '--';
                else
                    line_style = '-';
                end
                plot(app.UIAxes_17,app.tpr(:,i),app.ppv(:,i),'Color',...
                    app.ROC_curves_colors(i,:),'LineWidth',2,...
                    'LineStyle',line_style,...
                    'DisplayName', app.imaging_parameters{i});
                hold(app.UIAxes_17, 'on');
            end
            hold(app.UIAxes_17, 'off');
            xlabel(app.UIAxes_17, 'TPR (sensitivity)');
            ylabel(app.UIAxes_17, 'PPV (precision)');
            app.UIAxes_17.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_17.YTick = [0 0.2 0.4 0.6 0.8 1];
            axis(app.UIAxes_17,'equal','tight');
            app.UIAxes_17.XLim = [-.001 1.001];
            app.UIAxes_17.YLim = [-.001 1.001];
            legend(app.UIAxes_17,'Location','best','FontSize',10)
            grid(app.UIAxes_17,'on');
            saveas(app.UIAxes_17, [output_ROC_figure, app.ROC_figure_PPV_TPR_extension]);
            app.EditField_3.Value = 'Both figures are saved.';
            pause(.1);
            
            app.LocalnormAUCEditField.Value = round(app.AUC_PPV_TPR(1),3);
            app.LocalnormAUCEditField_2.Value = round(app.AUC_PPV_TPR(2),3);

            app.GlobalnormAUCEditField.Value = round(app.AUC_PPV_TPR(3),3);
            app.GlobalnormAUCEditField_2.Value = round(app.AUC_PPV_TPR(4),3);

            app.CombinednormAUCEditField.Value = round(app.AUC_PPV_TPR(5),3);
            app.CombinednormAUCEditField_2.Value = round(app.AUC_PPV_TPR(6),3);

            app.IBSAUCEditField.Value = round(app.AUC_PPV_TPR(7),3);
        end

        % Button pushed function: 
        % LoadregistereddecorrelationIBSimagesButton
        function LoadregistereddecorrelationIBSimagesButtonPushed(app, event)
            for i = 1:length(app.imaging_parameters)
                directory = strcat(app.output_path, ...
                    app.registered_US_decorr_extension, ...
                    app.drop_down_val(1:end-3),...
                    app.registered_US_decorr_file_extension{i+2});

                temp = load(directory);
                vol = temp.registered_vol;

                if i ~= 7
                    vol_scaled = scale_decorrelation_map(vol, app.max_decorr, app.dyn_range);
                end

                switch i
                    case 1
                        app.registered_local_decorr_unscaled = vol;
                        app.registered_local_decorr_scaled = vol_scaled;
                    case 2
                        app.registered_local_motcorr_decorr_unscaled = vol;
                        app.registered_local_motcorr_decorr_scaled = vol_scaled;
                    case 3
                        app.registered_global_decorr_unscaled = vol;
                        app.registered_global_decorr_scaled = vol_scaled;
                    case 4
                        app.registered_global_motcorr_decorr_unscaled = vol;
                        app.registered_global_motcorr_decorr_scaled = vol_scaled;
                    case 5
                        app.registered_combined_decorr_unscaled = vol;
                        app.registered_combined_decorr_scaled = vol_scaled;
                    case 6
                        app.registered_combined_motcorr_decorr_unscaled = vol;
                        app.registered_combined_motcorr_decorr_scaled = vol_scaled;
                    case 7
                        app.registered_IBS = vol;
                end

            end
            
            directory_mask = strcat(app.output_path, ...
                app.ablation_mask3D_extension, app.drop_down_val(1:end-3), ...
                app.ablation_mask3D_file_extension);
            if exist(directory_mask,'file')
                temp = load(directory_mask);
                app.mask3D = temp.ablation_mask3D;
            end

            app.EditField_2.Value = 'All registered images are loaded! Choose an imaging parameter to show on the plots below.';
            app.Lamp_2.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.UIAxes = uiaxes(app.RegistrationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RegistrationTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RegistrationTab);
            title(app.UIAxes_3, 'High resolution US image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RegistrationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes2 = uiaxes(app.RegistrationTab);
            title(app.UIAxes2, 'Low resolution US image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Image = uiimage(app.RegistrationTab);
            app.Image.ImageSource = 'axes_photo.jpeg';
            app.UITable = uitable(app.RegistrationTab);
            app.UITable.ColumnName = {'Parameter'; 'High res.'; 'Low res.'};
            app.UITable.RowName = {};
            app.SliceSliderLabel = uilabel(app.RegistrationTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.RegistrationTab);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.DateDropDownLabel = uilabel(app.RegistrationTab);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.RegistrationTab);
            app.DateDropDown.Items = {};
            app.DateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DateDropDownOpening, true);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.Button = uibutton(app.RegistrationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'CT.png';
            app.Button.Text = '';
            app.RegistrationPanel = uipanel(app.RegistrationTab);
            app.RegistrationPanel.Title = 'Registration';
            app.UIAxes_13 = uiaxes(app.RegistrationPanel);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.RegistrationPanel);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.RegistrationPanel);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.YawEditFieldLabel = uilabel(app.RegistrationPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.YawEditField.Value = Inf;
            app.Button_2 = uibutton(app.RegistrationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'apex.png';
            app.Button_2.Text = '';
            app.OrderofrotationDropDownLabel = uilabel(app.RegistrationPanel);
            app.OrderofrotationDropDownLabel.Text = 'Order of rotation';
            app.OrderofrotationDropDown = uidropdown(app.RegistrationPanel);
            app.OrderofrotationDropDown.Items = {};
            app.OrderofrotationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OrderofrotationDropDownOpening, true);
            app.OrderofrotationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderofrotationDropDownValueChanged, true);
            app.OrderofrotationDropDown.Value = {};
            app.PitchEditFieldLabel = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditField.Value = Inf;
            app.PitchEditFieldLabel_2 = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel_2.Text = 'Roll';
            app.RollEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.RollEditField.Value = Inf;
            app.EchoapexonMRLabel = uilabel(app.RegistrationPanel);
            app.EchoapexonMRLabel.Text = 'Echo apex on MR';
            app.UpdateregistrationButton = uibutton(app.RegistrationPanel, 'push');
            app.UpdateregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateregistrationButtonPushed, true);
            app.UpdateregistrationButton.Text = 'Update registration';
            app.Lamp = uilamp(app.RegistrationPanel);
            app.EditField = uieditfield(app.RegistrationPanel, 'text');
            app.SliceSlider_7Label = uilabel(app.RegistrationPanel);
            app.SliceSlider_7Label.Text = 'Slice';
            app.SliceSlider_7 = uislider(app.RegistrationPanel);
            app.SliceSlider_7.MajorTicks = [];
            app.SliceSlider_7.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_7ValueChanging, true);
            app.SliceSlider_7.MinorTicks = [];
            app.OverlayultrasoundButton = uibutton(app.RegistrationPanel, 'push');
            app.OverlayultrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayultrasoundButtonPushed, true);
            app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
            app.AlphaSliderLabel = uilabel(app.RegistrationPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.RegistrationPanel);
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.MinorTicks = [];
            app.ApextoneedleinZmmEditFieldLabel = uilabel(app.RegistrationPanel);
            app.ApextoneedleinZmmEditFieldLabel.Text = 'Apex to needle in Z (mm)';
            app.ApextoneedleinZmmEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.ApextoneedleinZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ApextoneedleinZmmEditFieldValueChanged, true);
            app.RotationanglesdegLabel = uilabel(app.RegistrationPanel);
            app.RotationanglesdegLabel.Text = 'Rotation angles (deg):';
            app.ApplytoLRUSButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytoLRUSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoLRUSButtonPushed, true);
            app.ApplytoLRUSButton.Text = 'Apply to LR US';
            app.NeedletrackslicenumberEditFieldLabel = uilabel(app.RegistrationPanel);
            app.NeedletrackslicenumberEditFieldLabel.Text = 'Needle track slice number';
            app.NeedletrackslicenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.NeedletrackslicenumberEditField.ValueChangedFcn = createCallbackFcn(app, @NeedletrackslicenumberEditFieldValueChanged, true);
            app.DrawrectangularROIButton_2 = uibutton(app.RegistrationPanel, 'push');
            app.DrawrectangularROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangularROIButton_2Pushed, true);
            app.DrawrectangularROIButton_2.Text = 'Draw rectangular ROI';
            app.LRUSIQfilenumberEditFieldLabel = uilabel(app.RegistrationPanel);
            app.LRUSIQfilenumberEditFieldLabel.Text = 'LR US IQ file number:';
            app.LRUSIQfilenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.UITable2 = uitable(app.RegistrationPanel);
            app.UITable2.ColumnName = {'Parameter'; 'Value'};
            app.UITable2.RowName = {};
            app.LoadsavedregistrationparametersButton = uibutton(app.RegistrationPanel, 'push');
            app.LoadsavedregistrationparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsavedregistrationparametersButtonPushed, true);
            app.LoadsavedregistrationparametersButton.Text = 'Load saved registration parameters';
            app.UpdatetableButton = uibutton(app.RegistrationPanel, 'push');
            app.UpdatetableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatetableButtonPushed, true);
            app.UpdatetableButton.Text = 'Update table';
            app.XEditFieldLabel = uilabel(app.RegistrationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.RegistrationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.Button_6 = uibutton(app.RegistrationPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-';
            app.Button_7 = uibutton(app.RegistrationPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '+';
            app.LoadregisteredUSimagesButton = uibutton(app.RegistrationPanel, 'push');
            app.LoadregisteredUSimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadregisteredUSimagesButtonPushed, true);
            app.LoadregisteredUSimagesButton.Text = 'Load registered US images';
            app.Button_3 = uibutton(app.RegistrationTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'US.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.RegistrationTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'photo.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.RegistrationTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'mri.png';
            app.Button_5.Text = '';
            app.Label = uilabel(app.RegistrationTab);
            app.Label.Text = '1';
            app.Slider = uislider(app.RegistrationTab);
            app.Slider.Limits = [0 50];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.ZoomSliderLabel = uilabel(app.RegistrationTab);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.RegistrationTab);
            app.ZoomSlider.Limits = [0.7 0.99];
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Value = 0.7;
            app.ZoomSlider_2Label = uilabel(app.RegistrationTab);
            app.ZoomSlider_2Label.Text = 'Zoom';
            app.ZoomSlider_2 = uislider(app.RegistrationTab);
            app.ZoomSlider_2.Limits = [0.7 0.99];
            app.ZoomSlider_2.MajorTicks = [];
            app.ZoomSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZoomSlider_2ValueChanging, true);
            app.ZoomSlider_2.MinorTicks = [];
            app.ZoomSlider_2.Value = 0.7;
            app.Slider_2Label = uilabel(app.RegistrationTab);
            app.Slider_2Label.Text = '1';
            app.Slider_2 = uislider(app.RegistrationTab);
            app.Slider_2.Limits = [0 50];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MajorTickLabels = {''};
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.IQdatanumberLabel = uilabel(app.RegistrationTab);
            app.IQdatanumberLabel.Text = 'IQ data number:';
            app.ElevationsliceLabel = uilabel(app.RegistrationTab);
            app.ElevationsliceLabel.Text = 'Elevation slice:';
            app.SegmentationandROCTab = uitab(app.TabGroup);
            app.SegmentationandROCTab.Title = 'Segmentation and ROC';
            app.UIAxes_9 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_9, 'Inf.  "Z"  Sup.')
            ylabel(app.UIAxes_9, 'Post.  "Y"  Ant.')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_10, 'R.  "X"  L.')
            ylabel(app.UIAxes_10, 'Inf.  "Z"  Sup.')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [20 50 60];
            app.UIAxes_11 = uiaxes(app.SegmentationandROCTab);
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_12, 'R.  "X"  L.')
            ylabel(app.UIAxes_12, 'Post.  "Y"  Ant.')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ApplyregistrationtoechodecorrelationandIBSButton = uibutton(app.SegmentationandROCTab, 'push');
            app.ApplyregistrationtoechodecorrelationandIBSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyregistrationtoechodecorrelationandIBSButtonPushed, true);
            app.ApplyregistrationtoechodecorrelationandIBSButton.Text = 'Apply registration to echo decorrelation and IBS';
            app.SegmentationPanel = uipanel(app.SegmentationandROCTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UIAxes_16 = uiaxes(app.SegmentationPanel);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_18 = uiaxes(app.SegmentationPanel);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.DisplayMRIButton = uibutton(app.SegmentationPanel, 'push');
            app.DisplayMRIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMRIButtonPushed, true);
            app.DisplayMRIButton.Text = 'Display MRI';
            app.AblationfirstsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationfirstsliceEditFieldLabel.Text = 'Ablation first slice';
            app.AblationfirstsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationfirstsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationfirstsliceEditFieldValueChanged, true);
            app.AblationlastsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationlastsliceEditFieldLabel.Text = 'Ablation last slice';
            app.AblationlastsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationlastsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationlastsliceEditFieldValueChanged, true);
            app.EditpolygonButton = uibutton(app.SegmentationPanel, 'push');
            app.EditpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @EditpolygonButtonPushed, true);
            app.EditpolygonButton.Text = 'Edit polygon';
            app.FinishsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.FinishsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @FinishsegmentationButtonPushed, true);
            app.FinishsegmentationButton.Text = 'Finish segmentation';
            app.SliceSpinnerLabel = uilabel(app.SegmentationPanel);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.SegmentationPanel);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.DrawrectangularROIButton = uibutton(app.SegmentationPanel, 'push');
            app.DrawrectangularROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangularROIButtonPushed, true);
            app.DrawrectangularROIButton.Text = 'Draw rectangular ROI';
            app.SliceSlider_12Label = uilabel(app.SegmentationPanel);
            app.SliceSlider_12Label.Text = 'Slice';
            app.SliceSlider_12 = uislider(app.SegmentationPanel);
            app.SliceSlider_12.MajorTicks = [];
            app.SliceSlider_12.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_12ValueChanging, true);
            app.SliceSlider_12.MinorTicks = [];
            app.ROCcurveanalysisPanel = uipanel(app.SegmentationandROCTab);
            app.ROCcurveanalysisPanel.Title = 'ROC curve analysis';
            app.UIAxes_17 = uiaxes(app.ROCcurveanalysisPanel);
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.CombinednormAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.CombinednormAUCEditFieldLabel.Text = 'Combined norm. AUC';
            app.CombinednormAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.CombinednormAUCEditField.ValueChangedFcn = createCallbackFcn(app, @AUC2EditFieldValueChanged, true);
            app.LocalnormAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.LocalnormAUCEditFieldLabel.Text = 'Local norm. AUC';
            app.LocalnormAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.DisplayROCcurvesButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.DisplayROCcurvesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROCcurvesButtonPushed, true);
            app.DisplayROCcurvesButton.Text = 'Display ROC curves';
            app.TrialsDropDownLabel = uilabel(app.ROCcurveanalysisPanel);
            app.TrialsDropDownLabel.Text = 'Trials';
            app.TrialsDropDown = uidropdown(app.ROCcurveanalysisPanel);
            app.TrialsDropDown.Items = {'Current trial', 'All trials'};
            app.TrialsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrialsDropDownOpening, true);
            app.TrialsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialsDropDownValueChanged, true);
            app.TrialsDropDown.Value = 'Current trial';
            app.CombinednormAUCEditField_2 = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.LocalnormAUCEditField_2 = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.GlobalnormAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.GlobalnormAUCEditFieldLabel.Text = 'Global norm. AUC';
            app.GlobalnormAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.WithoutLabel = uilabel(app.ROCcurveanalysisPanel);
            app.WithoutLabel.Text = 'Without';
            app.WithmotcorrLabel = uilabel(app.ROCcurveanalysisPanel);
            app.WithmotcorrLabel.Text = 'With motcorr';
            app.WithoutLabel_2 = uilabel(app.ROCcurveanalysisPanel);
            app.WithoutLabel_2.Text = 'Without';
            app.WithmotcorrLabel_2 = uilabel(app.ROCcurveanalysisPanel);
            app.WithmotcorrLabel_2.Text = 'With motcorr';
            app.WithoutLabel_3 = uilabel(app.ROCcurveanalysisPanel);
            app.WithoutLabel_3.Text = 'Without';
            app.WithmotcorrLabel_3 = uilabel(app.ROCcurveanalysisPanel);
            app.WithmotcorrLabel_3.Text = 'With motcorr';
            app.IBSAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.IBSAUCEditFieldLabel.Text = 'IBS AUC';
            app.IBSAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.EditField_3 = uieditfield(app.ROCcurveanalysisPanel, 'text');
            app.GlobalnormAUCEditField_2 = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.ListBox = uilistbox(app.ROCcurveanalysisPanel);
            app.ListBox.Items = {'TPR vs. FPR', 'PPV vs. TPR'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'TPR vs. FPR';
            app.SaveROCfiguresButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.SaveROCfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROCfiguresButtonPushed, true);
            app.SaveROCfiguresButton.Text = 'Save ROC figures';
            app.SliceSlider_8Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_8Label.Text = 'Slice';
            app.SliceSlider_8 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_8.MajorTicks = [];
            app.SliceSlider_8.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_8ValueChanging, true);
            app.SliceSlider_8.MinorTicks = [];
            app.Lamp_2 = uilamp(app.SegmentationandROCTab);
            app.SliceSlider_10Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_10Label.Text = 'Slice';
            app.SliceSlider_10 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_10.MajorTicks = [];
            app.SliceSlider_10.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_10ValueChanging, true);
            app.SliceSlider_10.MinorTicks = [];
            app.SliceSlider_11Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_11Label.Text = 'Slice';
            app.SliceSlider_11 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_11.MajorTicks = [];
            app.SliceSlider_11.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_11ValueChanging, true);
            app.SliceSlider_11.MinorTicks = [];
            app.DisplaydecorrelationIBSDropDownLabel = uilabel(app.SegmentationandROCTab);
            app.DisplaydecorrelationIBSDropDownLabel.Text = 'Display decorrelation/IBS';
            app.DisplaydecorrelationIBSDropDown = uidropdown(app.SegmentationandROCTab);
            app.DisplaydecorrelationIBSDropDown.Items = {};
            app.DisplaydecorrelationIBSDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DisplaydecorrelationIBSDropDownOpening, true);
            app.DisplaydecorrelationIBSDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydecorrelationIBSDropDownValueChanged, true);
            app.DisplaydecorrelationIBSDropDown.Value = {};
            app.AlphaSlider_3Label = uilabel(app.SegmentationandROCTab);
            app.AlphaSlider_3Label.Text = 'Alpha';
            app.AlphaSlider_3 = uislider(app.SegmentationandROCTab);
            app.AlphaSlider_3.Limits = [0 1];
            app.AlphaSlider_3.MajorTicks = [];
            app.AlphaSlider_3.ValueChangingFcn = createCallbackFcn(app, @AlphaSlider_3ValueChanging, true);
            app.AlphaSlider_3.MinorTicks = [];
            app.SavefigureincludingallimagingparametersButton = uibutton(app.SegmentationandROCTab, 'push');
            app.SavefigureincludingallimagingparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureincludingallimagingparametersButtonPushed, true);
            app.SavefigureincludingallimagingparametersButton.Text = 'Save figure including all imaging parameters';
            app.EditField_2 = uieditfield(app.SegmentationandROCTab, 'text');
            app.IsosurfacethresholdEditFieldLabel = uilabel(app.SegmentationandROCTab);
            app.IsosurfacethresholdEditFieldLabel.Text = 'Isosurface threshold';
            app.IsosurfacethresholdEditField = uieditfield(app.SegmentationandROCTab, 'numeric');
            app.IsosurfacethresholdEditField.Limits = [-6 30];
            app.IsosurfacethresholdEditField.ValueChangedFcn = createCallbackFcn(app, @IsosurfacethresholdEditFieldValueChanged, true);
            app.IsosurfacethresholdEditField.Value = -2;
            app.TransverseLabel = uilabel(app.SegmentationandROCTab);
            app.TransverseLabel.Text = 'Transverse';
            app.SagittalLabel = uilabel(app.SegmentationandROCTab);
            app.SagittalLabel.Text = 'Sagittal';
            app.CoronalLabel = uilabel(app.SegmentationandROCTab);
            app.CoronalLabel.Text = 'Coronal';
            app.XsliceEditFieldLabel = uilabel(app.SegmentationandROCTab);
            app.XsliceEditFieldLabel.Text = 'X slice:';
            app.XsliceEditField = uieditfield(app.SegmentationandROCTab, 'numeric');
            app.XsliceEditField.ValueChangedFcn = createCallbackFcn(app, @XsliceEditFieldValueChanged, true);
            app.XsliceEditField.Value = 10;
            app.ZsliceEditFieldLabel = uilabel(app.SegmentationandROCTab);
            app.ZsliceEditFieldLabel.Text = 'Z slice:';
            app.ZsliceEditField = uieditfield(app.SegmentationandROCTab, 'numeric');
            app.ZsliceEditField.ValueChangedFcn = createCallbackFcn(app, @ZsliceEditFieldValueChanged, true);
            app.ZsliceEditField.Value = 10;
            app.ShowpostopimageonlyButton = uibutton(app.SegmentationandROCTab, 'push');
            app.ShowpostopimageonlyButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpostopimageonlyButtonPushed, true);
            app.ShowpostopimageonlyButton.Text = 'Show post-op image only';
            app.ZLabel = uilabel(app.SegmentationandROCTab);
            app.ZLabel.Text = 'Z:';
            app.XLabel = uilabel(app.SegmentationandROCTab);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.SegmentationandROCTab);
            app.YLabel.Text = 'Y:';
            app.LoadregistereddecorrelationIBSimagesButton = uibutton(app.SegmentationandROCTab, 'push');
            app.LoadregistereddecorrelationIBSimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadregistereddecorrelationIBSimagesButtonPushed, true);
            app.LoadregistereddecorrelationIBSimagesButton.Text = 'Load registered decorrelation/IBS images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radiology_ablation_data_processing_v10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end