classdef radiology_ablation_data_processing_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RegistrationTab               matlab.ui.container.Tab
        Button_5                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        SliceSlider_6                 matlab.ui.control.Slider
        SliceSlider_6Label            matlab.ui.control.Label
        RegistrationPanel             matlab.ui.container.Panel
        ApplytranslationButton        matlab.ui.control.Button
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        TranslateinXorYLabel          matlab.ui.control.Label
        NeedletrackslicenumberEditField  matlab.ui.control.NumericEditField
        NeedletrackslicenumberEditFieldLabel  matlab.ui.control.Label
        ApplytoLRUSButton             matlab.ui.control.Button
        RotationanglesdegLabel        matlab.ui.control.Label
        ApextoneedleinZmmEditField    matlab.ui.control.NumericEditField
        ApextoneedleinZmmEditFieldLabel  matlab.ui.control.Label
        AlphaSlider                   matlab.ui.control.Slider
        AlphaSliderLabel              matlab.ui.control.Label
        OverlayultrasoundButton       matlab.ui.control.Button
        SliceSlider_7                 matlab.ui.control.Slider
        SliceSlider_7Label            matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        UpdateregistrationButton      matlab.ui.control.Button
        EchoapexonMRLabel             matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel_2         matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        OrderofrotationDropDown       matlab.ui.control.DropDown
        OrderofrotationDropDownLabel  matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        YawEditField                  matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        UIAxes_18                     matlab.ui.control.UIAxes
        UIAxes_15                     matlab.ui.control.UIAxes
        UIAxes_14                     matlab.ui.control.UIAxes
        UIAxes_13                     matlab.ui.control.UIAxes
        Button                        matlab.ui.control.Button
        DateDropDown                  matlab.ui.control.DropDown
        DateDropDownLabel             matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        SliceSliderLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SegmentationandROCTab         matlab.ui.container.Tab
        Lamp_2                        matlab.ui.control.Lamp
        SliceSlider_8                 matlab.ui.control.Slider
        SliceSlider_8Label            matlab.ui.control.Label
        ROCcurveanalysisPanel         matlab.ui.container.Panel
        DisplayROCcurvesButton        matlab.ui.control.Button
        AUC3EditField                 matlab.ui.control.NumericEditField
        AUC3EditFieldLabel            matlab.ui.control.Label
        AUC2EditField                 matlab.ui.control.NumericEditField
        AUC2EditFieldLabel            matlab.ui.control.Label
        ROIradiusEditField            matlab.ui.control.NumericEditField
        ROIradiusEditFieldLabel       matlab.ui.control.Label
        UIAxes_17                     matlab.ui.control.UIAxes
        SegmentationPanel             matlab.ui.container.Panel
        FinishsegmentationButton      matlab.ui.control.Button
        CreatemaskButton              matlab.ui.control.Button
        EditpolygonButton             matlab.ui.control.Button
        Slider_3                      matlab.ui.control.Slider
        Slider_3Label                 matlab.ui.control.Label
        Slider_2                      matlab.ui.control.Slider
        Slider_2Label                 matlab.ui.control.Label
        SelectablatedregionButton     matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
        AblationlastsliceEditField    matlab.ui.control.NumericEditField
        AblationlastsliceEditFieldLabel  matlab.ui.control.Label
        AblationfirstsliceEditField   matlab.ui.control.NumericEditField
        AblationfirstsliceEditFieldLabel  matlab.ui.control.Label
        DisplayMRIButton              matlab.ui.control.Button
        UIAxes_16                     matlab.ui.control.UIAxes
        ApplyregistrationButton       matlab.ui.control.Button
        UIAxes_12                     matlab.ui.control.UIAxes
        UIAxes_11                     matlab.ui.control.UIAxes
        UIAxes_10                     matlab.ui.control.UIAxes
        UIAxes_9                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        case_path % Description
        US_path
        photo_names
        table_vals
        trial_name
        cum_decorr
        cum_decorr_motion_corr
        xyz_range
        Bmode
        trial_number
        case_number
        suite_CT_vol
        post_abl_1day_obj
        post_abl_1day_vol
        apex_loc = []
        apex_to_needle_dist
        rotation_order_choice
        rotation_angles
        IQdata
        IQdata_LR
        registered_US_flag = false
        registered_US_LR_flag = false
        registered_US
        registered_US_LR
        overlay_flag = false
        overlay_slice = 30
        green
        overlay_threshold = -10
        slider7value = 30
        slider8value = 30
        temp_registered_US
        x_translation_flag = false
        y_translation_flag = false
        decorrs_flag = false
        trial_name_LR
        table_vals_LR
        xyz_range_LR
        Bmode_LR
        registered_decorr
        registered_motion_corr_decorr
    end

    properties (Access = public, Constant = true)
        base_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/'
        folder_extension = ' radiology MW ablation and followup/'
        procedure_photos_extension = 'Procedure photos/'
        date = {'03182021_T1_LR',...
            '03182021_T2_LR',...
            '08242021_T1_HR',...
            '08242021_T1_LR',...
            '08242021_T2_HR',...
            '08242021_T2_LR',...
            '12032021_T1_HR',...
            '12032021_T1_LR'} % Date of ablation and trial number % Description
        case_dates = {'03182021',...
            '08242021',...
            '12032021'}
        suite_CT_address = {'111',...
            '111',...
            'SE000003'}
        post_abl_1day_address = {'111',...
            '111',...
            'SE000015'}

        rotation_order    = {'x -> y -> z',...
            'x -> z -> y',...
            'y -> x -> z',...
            'y -> z -> x',...
            'z -> x -> y',...
            'z -> y -> x'}
        max_decorr             = .1;
        dyn_range              = 3;
        max_Bmode              = 0.08;
        dB_range               = 65;
        default_IQfile_number  = 3;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DateDropDown
        function DateDropDownOpening(app, event)
            app.DateDropDown.Items = app.date;
            value = app.DateDropDown.Value
            app.case_path = [app.base_path,value(1:8),app.folder_extension];
            app.trial_name = value
            app.trial_number = value(10:11);

            switch value(1:8)
                case app.case_dates{1}
                    app.case_number = 1;
                case app.case_dates{2}
                    app.case_number = 2;
                case app.case_dates{3}
                    app.case_number = 3;
            end

        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            value = app.DateDropDown.Value;
            app.case_path = [app.base_path,value(1:8),app.folder_extension];
            app.trial_name = value
            app.trial_number = value(10:11);
            switch value(1:8)
                case app.case_dates{1}
                    app.case_number = 1;
                case app.case_dates{2}
                    app.case_number = 2;
                case app.case_dates{3}
                    app.case_number = 3;
            end

            US_info_table = readtable([app.base_path,'ablation_information_for_app.xlsx']);

            table_params = US_info_table.Properties.VariableNames;
            app.UITable.Data = {table_params{2};table_params{3};...
                table_params{4};table_params{5}};

            app.table_vals = ...
                US_info_table{find(strcmp(US_info_table{:,1}, {value})),2:6};
            app.UITable.Data(:,2) = {app.table_vals(1);...
                app.table_vals(2);app.table_vals(3);app.table_vals(4)};
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            directory = [app.case_path,app.procedure_photos_extension];
            app.photo_names = uigetfile([directory,'*.jpg'],...
                'MultiSelect','on','Select 2 photos from procedure');

            photo1 = imread([directory,app.photo_names{1}]);
            photo2 = imread([directory,app.photo_names{2}]);

            imshow(photo1,'Parent',app.UIAxes)
            axis(app.UIAxes,'equal','tight');
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');

            imshow(photo2,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'equal','tight');
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.US_path = [app.case_path,'ultrasound/'];
            %             [rawData_cart, app.cum_decorr, app.cum_decorr_motion_corr, app.xyz_range] = ...
            app.IQdata = process_US(app.trial_name, app.US_path, app.table_vals);

            rawData_cart = app.IQdata{1};
            app.xyz_range = app.IQdata{2};

            app.Bmode = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
            app.Bmode = flip(app.Bmode,2);
            app.Bmode = flip(app.Bmode,3);

            imagesc(app.UIAxes_3,app.Bmode(:,:,30,app.default_IQfile_number))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSlider.Limits = [1 size(app.Bmode,3)];
            app.SliceSlider.Value = 30;
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_3,app.Bmode(:,:,round(changingValue),app.default_IQfile_number))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSliderLabel.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            address = [app.case_path,app.trial_number,'_suite CT/',app.suite_CT_address{app.case_number}];
            app.suite_CT_vol = read_suite_CT(address)

            imagesc(app.UIAxes_4,app.suite_CT_vol(:,:,30))
            axis(app.UIAxes_4,'equal','tight');
            colormap(app.UIAxes_4,'gray');
            app.SliceSlider_6.Limits = [1 size(app.suite_CT_vol,3)];
            app.SliceSlider_6.Value = 30;
        end

        % Value changing function: SliceSlider_6
        function SliceSlider_6ValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_4,app.suite_CT_vol(:,:,round(changingValue)));
            axis(app.UIAxes_4,'image');
            colormap(app.UIAxes_4,'gray');
            app.SliceSlider_6Label.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.UIAxes_18.Visible = 'off';
            address = [app.case_path,app.trial_number,'_1day_post_ablation/',app.post_abl_1day_address{app.case_number}];
            app.post_abl_1day_obj = read_1day_post_ablation(address)
            app.post_abl_1day_vol = app.post_abl_1day_obj.vol;

            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,30))
            axis(app.UIAxes_13,'equal','tight');
            colormap(app.UIAxes_13,'gray');
            app.SliceSlider_7.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_7.Value = 79;
        end

        % Value changing function: SliceSlider_7
        function SliceSlider_7ValueChanging(app, event)
            app.slider7value = event.Value;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);

            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,round(app.slider7value)));
            axis(app.UIAxes_13,'image');
            colormap(app.UIAxes_13,'gray');

            if app.registered_US_flag
                imagesc(app.UIAxes_14,app.registered_US(:,:,round(app.slider7value)))
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15,app.registered_US_LR(:,:,round(app.overlay_slice)))
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', ...
                    app.temp_registered_US(:,:,app.overlay_slice)>=round(app.overlay_threshold))
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [app.apex_loc(1), app.apex_loc(2)] = getpts(app.UIAxes_13)
            app.apex_loc = round(app.apex_loc);
        end

        % Drop down opening function: OrderofrotationDropDown
        function OrderofrotationDropDownOpening(app, event)
            app.OrderofrotationDropDown.Items = app.rotation_order;
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value
        end

        % Value changed function: OrderofrotationDropDown
        function OrderofrotationDropDownValueChanged(app, event)
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value
        end

        % Value changed function: ApextoneedleinZmmEditField
        function ApextoneedleinZmmEditFieldValueChanged(app, event)
            app.apex_to_needle_dist = app.ApextoneedleinZmmEditField.Value;
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.rotation_angles(1) = app.YawEditField.Value;
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.rotation_angles(2) = app.PitchEditField.Value;
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.rotation_angles(3) = app.RollEditField.Value;
        end

        % Button pushed function: UpdateregistrationButton
        function UpdateregistrationButtonPushed(app, event)
            app.Lamp.Color = 'red';
            app.EditField.Value = 'Registration in progress...';
            %             app.registered_US = register(app.post_abl_1day_obj, ...
            %                             app.Bmode, app.xyz_range, app.apex_loc,...
            %                             app.apex_to_needle_dist,app.rotation_order_choice,...
            %                             app.rotation_angles,...
            %                                     'Bmode');
            app.registered_US = load('/Users/elmiraghahramani/Desktop/registUS.mat');
            app.registered_US = app.registered_US.temp;
            app.apex_loc = round([80.45, 147.27, 194]);
            app.apex_to_needle_dist = 0;
            app.Lamp.Color = 'green';
            app.EditField.Value = 'Registration is now complete!';

            imagesc(app.UIAxes_14,app.registered_US(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');

            app.registered_US_flag = true;
            app.XSlider.Limits = [-100 100];
            app.YSlider.Limits = [-100 100];
            %             inside_echo = app.registered_US ~= NaN;
            app.post_abl_1day_obj
            app.temp_registered_US = app.registered_US;%(app.apex_loc(2), app.apex_loc(1));
        end

        % Value changed function: NeedletrackslicenumberEditField
        function NeedletrackslicenumberEditFieldValueChanged(app, event)
            value = app.NeedletrackslicenumberEditField.Value;
            app.apex_loc(3) = round(value);
        end

        % Button pushed function: OverlayultrasoundButton
        function OverlayultrasoundButtonPushed(app, event)
            app.UIAxes_18.Visible = 'on';
            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));
                %
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.green = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));

                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', app.temp_registered_US(:,:,app.overlay_slice)>=app.overlay_threshold)
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,round(app.overlay_slice)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end

            app.AlphaSlider.Limits = [min(app.registered_US(:)) max(app.registered_US(:))];

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                app.OverlayultrasoundButton.Text = 'Remove overlay';
                app.overlay_flag = true;
            else
                app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
                app.overlay_flag = false;
            end
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            if app.overlay_flag || app.rotatio_flag
                app.overlay_threshold = event.Value;
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');

                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', ...
                    app.temp_registered_US(:,:,app.overlay_slice)>=round(app.overlay_threshold))
            end
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            if app.overlay_flag
                app.x_translation_flag = true;
                changingValue = round(event.Value);
                if app.y_translation_flag || app.rotation_flag
                    temp = app.temp_registered_US;
                else
                    temp = NaN(size(app.registered_US));
                end

                if changingValue >= 0
                    temp(:,changingValue+1:end,:) = app.registered_US(:,1:end-changingValue,:);
                else
                    temp(:,1:end-abs(changingValue),:) = app.registered_US(:,abs(changingValue)+1:end,:);
                end
                app.temp_registered_US = temp;

                % Overlaid US Axes_13
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');

                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', ...
                    app.temp_registered_US(:,:,app.overlay_slice)>=round(app.overlay_threshold))
            end
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            if app.overlay_flag
                app.y_translation_flag = true;
                changingValue = round(event.Value);
                if app.x_translation_flag || app.rotation_flag
                    temp = app.temp_registered_US;
                else
                    temp = NaN(size(app.registered_US));
                end

                if changingValue >= 0
                    temp(changingValue+1:end,:,:) = app.registered_US(1:end-changingValue,:,:);
                else
                    temp(1:end-abs(changingValue),:,:) = app.registered_US(abs(changingValue)+1:end,:,:);
                end
                app.temp_registered_US = temp;

                % Overlaid US Axes_13
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');

                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', ...
                    app.temp_registered_US(:,:,app.overlay_slice)>=round(app.overlay_threshold))
            end
        end

        % Button pushed function: ApplytranslationButton
        function ApplytranslationButtonPushed(app, event)
            app.registered_US = app.temp_registered_US;

            imagesc(app.UIAxes_14,app.registered_US(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');
        end

        % Button pushed function: ApplytoLRUSButton
        function ApplytoLRUSButtonPushed(app, event)
            app.US_path = [app.case_path,'ultrasound/'];
            app.trial_name_LR = app.trial_name;
            app.trial_name_LR(end-1:end) = 'LR';

            US_info_table = readtable([app.base_path,'ablation_information_for_app.xlsx']);
            app.table_vals_LR = ...
                US_info_table{find(strcmp(US_info_table{:,1}, {app.trial_name_LR})),2:6};

            app.IQdata_LR = process_US(app.trial_name_LR, app.US_path, app.table_vals_LR);

            rawData_cart = app.IQdata_LR{1};
            app.xyz_range_LR = app.IQdata_LR{2};

            app.Bmode_LR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
            app.Bmode_LR = flip(app.Bmode_LR,2);
            app.Bmode_LR = flip(app.Bmode_LR,3);

            app.Lamp.Color = 'red';
            app.EditField.Value = 'Registration in progress...';
            app.registered_US_LR = register(app.post_abl_1day_obj, ...
                app.Bmode_LR, app.xyz_range_LR, app.apex_loc,...
                app.apex_to_needle_dist,app.rotation_order_choice,...
                app.rotation_angles, 'Bmode');
            %             app.registered_US = load('/Users/elmiraghahramani/Desktop/registUS.mat');
            %             app.registered_US = app.registered_US.temp;
            app.Lamp.Color = 'green';
            app.EditField.Value = 'Registration is now complete!';

            imagesc(app.UIAxes_15,app.registered_US_LR(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_15,'equal','tight');
            colormap(app.UIAxes_15,'gray');

            imagesc(app.UIAxes_12,app.registered_US_LR(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_12,'equal','tight');
            colormap(app.UIAxes_12,'gray');

            app.registered_US_LR_flag = true;
            app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol,3)];
        end

        % Button pushed function: ApplyregistrationButton
        function ApplyregistrationButtonPushed(app, event)
            app.Lamp_2.Color = 'red';
            app.registered_decorr = register(app.post_abl_1day_obj, ...
                app.IQdata_LR{3}, app.xyz_range_LR, app.apex_loc,...
                app.apex_to_needle_dist,app.rotation_order_choice,...
                app.rotation_angles, 'decorr');

            app.registered_motion_corr_decorr = register(app.post_abl_1day_obj, ...
                app.IQdata_LR{4}, app.xyz_range_LR, app.apex_loc,...
                app.apex_to_needle_dist,app.rotation_order_choice,...
                app.rotation_angles, 'decorr');

            %             app.registered_US = load('/Users/elmiraghahramani/Desktop/registUS.mat');
            %             app.registered_US = app.registered_US.temp;

            imagesc(app.UIAxes_9,...
                app.registered_decorr(:,:,round(app.slider8value)));
            axis(app.UIAxes_9,'equal','tight');
            colormap(app.UIAxes_9,'hot');

            imagesc(app.UIAxes_10,...
                app.registered_motion_corr_decorr(:,:,round(app.slider8value)));
            axis(app.UIAxes_10,'equal','tight');
            colormap(app.UIAxes_10,'hot');

            imagesc(app.UIAxes_11,app.post_abl_1day_vol(:,:,round(app.slider8value)));
            axis(app.UIAxes_11,'image');
            colormap(app.UIAxes_11,'gray');

            app.Lamp_2.Color = 'green';
            app.decorrs_flag = true;
        end

        % Value changing function: SliceSlider_8
        function SliceSlider_8ValueChanging(app, event)
            app.slider8value = event.Value;
            app.SliceSlider_8Label.Text = num2str(round(app.slider8value));

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_12,app.registered_US_LR(:,:,round(app.slider8value)))
                axis(app.UIAxes_12,'equal','tight');
                colormap(app.UIAxes_12,'gray');
            end

            if app.decorrs_flag
                imagesc(app.UIAxes_9,...
                    app.registered_decorr(:,:,round(app.slider8value)));
                axis(app.UIAxes_9,'equal','tight');
                colormap(app.UIAxes_9,'hot');

                imagesc(app.UIAxes_10,...
                    app.registered_motion_corr_decorr(:,:,round(app.slider8value)));
                axis(app.UIAxes_10,'equal','tight');
                colormap(app.UIAxes_10,'hot');
            end

            imagesc(app.UIAxes_11,app.post_abl_1day_vol(:,:,round(app.slider8value)));
            axis(app.UIAxes_11,'image');
            colormap(app.UIAxes_11,'gray');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.UIAxes = uiaxes(app.RegistrationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RegistrationTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RegistrationTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RegistrationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Image = uiimage(app.RegistrationTab);
            app.Image.ImageSource = 'axes_photo.jpeg';
            app.UITable = uitable(app.RegistrationTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.SliceSliderLabel = uilabel(app.RegistrationTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.RegistrationTab);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.DateDropDownLabel = uilabel(app.RegistrationTab);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.RegistrationTab);
            app.DateDropDown.Items = {};
            app.DateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DateDropDownOpening, true);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.Button = uibutton(app.RegistrationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'CT.png';
            app.Button.Text = '';
            app.RegistrationPanel = uipanel(app.RegistrationTab);
            app.RegistrationPanel.Title = 'Registration';
            app.UIAxes_13 = uiaxes(app.RegistrationPanel);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.RegistrationPanel);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.RegistrationPanel);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.UIAxes_18 = uiaxes(app.RegistrationPanel);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.YawEditFieldLabel = uilabel(app.RegistrationPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.Button_2 = uibutton(app.RegistrationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'apex.png';
            app.Button_2.Text = '';
            app.OrderofrotationDropDownLabel = uilabel(app.RegistrationPanel);
            app.OrderofrotationDropDownLabel.Text = 'Order of rotation';
            app.OrderofrotationDropDown = uidropdown(app.RegistrationPanel);
            app.OrderofrotationDropDown.Items = {};
            app.OrderofrotationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OrderofrotationDropDownOpening, true);
            app.OrderofrotationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderofrotationDropDownValueChanged, true);
            app.OrderofrotationDropDown.Value = {};
            app.PitchEditFieldLabel = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditFieldLabel_2 = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel_2.Text = 'Roll';
            app.RollEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.EchoapexonMRLabel = uilabel(app.RegistrationPanel);
            app.EchoapexonMRLabel.Text = 'Echo apex on MR';
            app.UpdateregistrationButton = uibutton(app.RegistrationPanel, 'push');
            app.UpdateregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateregistrationButtonPushed, true);
            app.UpdateregistrationButton.Text = 'Update registration';
            app.Lamp = uilamp(app.RegistrationPanel);
            app.EditField = uieditfield(app.RegistrationPanel, 'text');
            app.SliceSlider_7Label = uilabel(app.RegistrationPanel);
            app.SliceSlider_7Label.Text = 'Slice';
            app.SliceSlider_7 = uislider(app.RegistrationPanel);
            app.SliceSlider_7.MajorTicks = [];
            app.SliceSlider_7.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_7ValueChanging, true);
            app.SliceSlider_7.MinorTicks = [];
            app.OverlayultrasoundButton = uibutton(app.RegistrationPanel, 'push');
            app.OverlayultrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayultrasoundButtonPushed, true);
            app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
            app.AlphaSliderLabel = uilabel(app.RegistrationPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.RegistrationPanel);
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.MinorTicks = [];
            app.ApextoneedleinZmmEditFieldLabel = uilabel(app.RegistrationPanel);
            app.ApextoneedleinZmmEditFieldLabel.Text = 'Apex to needle in Z (mm)';
            app.ApextoneedleinZmmEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.ApextoneedleinZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ApextoneedleinZmmEditFieldValueChanged, true);
            app.RotationanglesdegLabel = uilabel(app.RegistrationPanel);
            app.RotationanglesdegLabel.Text = 'Rotation angles (deg):';
            app.ApplytoLRUSButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytoLRUSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoLRUSButtonPushed, true);
            app.ApplytoLRUSButton.Text = 'Apply to LR US';
            app.NeedletrackslicenumberEditFieldLabel = uilabel(app.RegistrationPanel);
            app.NeedletrackslicenumberEditFieldLabel.Text = 'Needle track slice number';
            app.NeedletrackslicenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.NeedletrackslicenumberEditField.ValueChangedFcn = createCallbackFcn(app, @NeedletrackslicenumberEditFieldValueChanged, true);
            app.TranslateinXorYLabel = uilabel(app.RegistrationPanel);
            app.TranslateinXorYLabel.Text = 'Translate in X or Y:';
            app.XSliderLabel = uilabel(app.RegistrationPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.RegistrationPanel);
            app.XSlider.Limits = [-100 100];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [];
            app.YSliderLabel = uilabel(app.RegistrationPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.RegistrationPanel);
            app.YSlider.Limits = [-100 100];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.MinorTicks = [];
            app.ApplytranslationButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytranslationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytranslationButtonPushed, true);
            app.ApplytranslationButton.Text = 'Apply translation';
            app.SliceSlider_6Label = uilabel(app.RegistrationTab);
            app.SliceSlider_6Label.Text = 'Slice';
            app.SliceSlider_6 = uislider(app.RegistrationTab);
            app.SliceSlider_6.MajorTicks = [];
            app.SliceSlider_6.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_6ValueChanging, true);
            app.SliceSlider_6.MinorTicks = [];
            app.Button_3 = uibutton(app.RegistrationTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'US.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.RegistrationTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'photo.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.RegistrationTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'mri.png';
            app.Button_5.Text = '';
            app.SegmentationandROCTab = uitab(app.TabGroup);
            app.SegmentationandROCTab.Title = 'Segmentation and ROC';
            app.UIAxes_9 = uiaxes(app.SegmentationandROCTab);
            title(app.UIAxes_9, 'Registered delta')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.SegmentationandROCTab);
            title(app.UIAxes_10, 'Registered deltacorr')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.SegmentationandROCTab);
            title(app.UIAxes_11, 'MR')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.SegmentationandROCTab);
            title(app.UIAxes_12, 'Registered LR')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ApplyregistrationButton = uibutton(app.SegmentationandROCTab, 'push');
            app.ApplyregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyregistrationButtonPushed, true);
            app.ApplyregistrationButton.Text = 'Apply registration';
            app.SegmentationPanel = uipanel(app.SegmentationandROCTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UIAxes_16 = uiaxes(app.SegmentationPanel);
            title(app.UIAxes_16, 'MR')
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.DisplayMRIButton = uibutton(app.SegmentationPanel, 'push');
            app.DisplayMRIButton.Text = 'Display MRI';
            app.AblationfirstsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationfirstsliceEditFieldLabel.Text = 'Ablation first slice';
            app.AblationfirstsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationlastsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationlastsliceEditFieldLabel.Text = 'Ablation last slice';
            app.AblationlastsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.SliderLabel = uilabel(app.SegmentationPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.SegmentationPanel);
            app.Slider.MajorTicks = [];
            app.Slider.MinorTicks = [];
            app.SelectablatedregionButton = uibutton(app.SegmentationPanel, 'push');
            app.SelectablatedregionButton.Text = 'Select ablated region';
            app.Slider_2Label = uilabel(app.SegmentationPanel);
            app.Slider_2Label.Text = 'Slider';
            app.Slider_2 = uislider(app.SegmentationPanel);
            app.Slider_2.MajorTicks = [];
            app.Slider_2.MinorTicks = [];
            app.Slider_3Label = uilabel(app.SegmentationPanel);
            app.Slider_3Label.Text = 'Slider';
            app.Slider_3 = uislider(app.SegmentationPanel);
            app.Slider_3.MajorTicks = [];
            app.Slider_3.MinorTicks = [];
            app.EditpolygonButton = uibutton(app.SegmentationPanel, 'push');
            app.EditpolygonButton.Text = 'Edit polygon';
            app.CreatemaskButton = uibutton(app.SegmentationPanel, 'push');
            app.CreatemaskButton.Text = 'Create mask';
            app.FinishsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.FinishsegmentationButton.Text = 'Finish segmentation';
            app.ROCcurveanalysisPanel = uipanel(app.SegmentationandROCTab);
            app.ROCcurveanalysisPanel.Title = 'ROC curve analysis';
            app.UIAxes_17 = uiaxes(app.ROCcurveanalysisPanel);
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.ROIradiusEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.ROIradiusEditFieldLabel.Text = 'ROI radius';
            app.ROIradiusEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUC2EditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUC2EditFieldLabel.Text = 'AUC 2';
            app.AUC2EditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUC3EditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUC3EditFieldLabel.Text = 'AUC 3';
            app.AUC3EditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.DisplayROCcurvesButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.DisplayROCcurvesButton.Text = 'Display ROC curves';
            app.SliceSlider_8Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_8Label.Text = 'Slice';
            app.SliceSlider_8 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_8.MajorTicks = [];
            app.SliceSlider_8.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_8ValueChanging, true);
            app.SliceSlider_8.MinorTicks = [];
            app.Lamp_2 = uilamp(app.SegmentationandROCTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radiology_ablation_data_processing_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end