classdef radiology_ablation_data_processing_v5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RegistrationTab               matlab.ui.container.Tab
        Button_5                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        SliceSlider_6                 matlab.ui.control.Slider
        SliceSlider_6Label            matlab.ui.control.Label
        RegistrationPanel             matlab.ui.container.Panel
        ApplytranslationButton        matlab.ui.control.Button
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        TranslateinXorYLabel          matlab.ui.control.Label
        NeedletrackslicenumberEditField  matlab.ui.control.NumericEditField
        NeedletrackslicenumberEditFieldLabel  matlab.ui.control.Label
        ApplytoLRUSButton             matlab.ui.control.Button
        RotationanglesdegLabel        matlab.ui.control.Label
        ApextoneedleinZmmEditField    matlab.ui.control.NumericEditField
        ApextoneedleinZmmEditFieldLabel  matlab.ui.control.Label
        AlphaSlider                   matlab.ui.control.Slider
        AlphaSliderLabel              matlab.ui.control.Label
        OverlayultrasoundButton       matlab.ui.control.Button
        SliceSlider_7                 matlab.ui.control.Slider
        SliceSlider_7Label            matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        UpdateregistrationButton      matlab.ui.control.Button
        EchoapexonMRLabel             matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel_2         matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        OrderofrotationDropDown       matlab.ui.control.DropDown
        OrderofrotationDropDownLabel  matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        YawEditField                  matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        UIAxes_15                     matlab.ui.control.UIAxes
        UIAxes_14                     matlab.ui.control.UIAxes
        UIAxes_13                     matlab.ui.control.UIAxes
        Button                        matlab.ui.control.Button
        DateDropDown                  matlab.ui.control.DropDown
        DateDropDownLabel             matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        SliceSliderLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SegmentationandROCTab         matlab.ui.container.Tab
        Update3DfigureButton          matlab.ui.control.Button
        AlphaSlider_3                 matlab.ui.control.Slider
        AlphaSlider_3Label            matlab.ui.control.Label
        DisplaydecorrelationDropDown  matlab.ui.control.DropDown
        DisplaydecorrelationDropDownLabel  matlab.ui.control.Label
        SliceSlider_11                matlab.ui.control.Slider
        SliceSlider_11Label           matlab.ui.control.Label
        SliceSlider_10                matlab.ui.control.Slider
        SliceSlider_10Label           matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        SliceSlider_8                 matlab.ui.control.Slider
        SliceSlider_8Label            matlab.ui.control.Label
        ROCcurveanalysisPanel         matlab.ui.container.Panel
        SavefigureButton              matlab.ui.control.Button
        TrialsDropDown                matlab.ui.control.DropDown
        TrialsDropDownLabel           matlab.ui.control.Label
        DisplayROCcurvesButton        matlab.ui.control.Button
        MotioncorrectedAUCEditField   matlab.ui.control.NumericEditField
        MotioncorrectedAUCEditFieldLabel  matlab.ui.control.Label
        AUCEditField                  matlab.ui.control.NumericEditField
        AUCEditFieldLabel             matlab.ui.control.Label
        UIAxes_17                     matlab.ui.control.UIAxes
        SegmentationPanel             matlab.ui.container.Panel
        SliceSpinner                  matlab.ui.control.Spinner
        SliceSpinnerLabel             matlab.ui.control.Label
        FinishsegmentationButton      matlab.ui.control.Button
        EditpolygonButton             matlab.ui.control.Button
        DreconstructlesionButton      matlab.ui.control.Button
        AblationlastsliceEditField    matlab.ui.control.NumericEditField
        AblationlastsliceEditFieldLabel  matlab.ui.control.Label
        AblationfirstsliceEditField   matlab.ui.control.NumericEditField
        AblationfirstsliceEditFieldLabel  matlab.ui.control.Label
        DisplayMRIButton              matlab.ui.control.Button
        UIAxes_18                     matlab.ui.control.UIAxes
        UIAxes_16                     matlab.ui.control.UIAxes
        ApplyregistrationButton       matlab.ui.control.Button
        UIAxes_12                     matlab.ui.control.UIAxes
        UIAxes_11                     matlab.ui.control.UIAxes
        UIAxes_10                     matlab.ui.control.UIAxes
        UIAxes_9                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        case_path % Description
        US_path
        photo_names
        table_vals
        trial_name
        cum_decorr
        cum_decorr_motion_corr
        xyz_range
        Bmode
        trial_number
        case_number
        suite_CT_vol
        post_abl_1day_obj
        post_abl_1day_vol
        post_abl_1day_vol_image
        apex_loc = []
        apex_to_needle_dist
        rotation_order_choice
        rotation_angles
        IQdata
        IQdata_LR
        registered_US_flag = false
        registered_US_LR_flag = false
        registered_US
        registered_US_LR
        overlay_flag = false
        overlay_slice = 30
        green
        overlay_threshold = 0
        slider7value = 10
        slider8value = 10
        slider10value = 10
        slider11value = 10
        temp_registered_US
        x_translation_flag = false
        y_translation_flag = false
        decorrs_flag = false
        trial_name_LR
        table_vals_LR
        xyz_range_LR
        Bmode_LR
        registered_decorr
        registered_decorr_scaled
        registered_motion_corr_decorr
        registered_motion_corr_decorr_scaled
        US_overlay
        SliceSpinnervalue
        lesion_slice = [0,0]
        mask
        mask3D = []
        mask3D_gauss
        mask_number
        SliceSlider_9value
        alpha_decorr_value = 0.5
        display_decorr
        alpha3value = 0.5
        max_decorr = 0.1
        dyn_range = 3
        dicom_dxdydz
        dicom_x
        dicom_y
        dicom_z
        dicom_xmesh
        dicom_ymesh
        dicom_zmesh
        ROC_trials
    end

    properties (Access = public, Constant = true)
        base_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/'
        folder_extension = ' radiology MW ablation and followup/'
        procedure_photos_extension = 'Procedure photos/'
        date = {'03182021_T1_LR',...
            '03182021_T2_LR',...
            '08242021_T1_HR',...
            '08242021_T1_LR',...
            '08242021_T2_HR',...
            '08242021_T2_LR',...
            '12032021_T1_HR',...
            '12032021_T1_LR'} % Date of ablation and trial number % Description
        case_dates = {'03182021',...
            '08242021',...
            '12032021'}
        suite_CT_address = {'111',...
            '111',...
            'SE000003'}
        post_abl_1day_address = {'111',...
            '111',...
            'SE000015'}

        rotation_order    = {'x -> y -> z',...
            'x -> z -> y',...
            'y -> x -> z',...
            'y -> z -> x',...
            'z -> x -> y',...
            'z -> y -> x'}
        %         max_decorr             = .1;
        %         dyn_range              = 3;
        max_Bmode              = 0.08;
        dB_range               = 65;
        default_IQfile_number  = 3;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DateDropDown
        function DateDropDownOpening(app, event)
            app.DateDropDown.Items = app.date;
            value = app.DateDropDown.Value
            app.case_path = [app.base_path,value(1:8),app.folder_extension];
            app.trial_name = value
            app.trial_number = value(10:11);

            switch value(1:8)
                case app.case_dates{1}
                    app.case_number = 1;
                case app.case_dates{2}
                    app.case_number = 2;
                case app.case_dates{3}
                    app.case_number = 3;
            end

        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            value = app.DateDropDown.Value;
            app.case_path = [app.base_path,value(1:8),app.folder_extension];
            app.trial_name = value
            app.trial_number = value(10:11);
            switch value(1:8)
                case app.case_dates{1}
                    app.case_number = 1;
                case app.case_dates{2}
                    app.case_number = 2;
                case app.case_dates{3}
                    app.case_number = 3;
            end

            US_info_table = readtable([app.base_path,'ablation_information_for_app.xlsx']);

            table_params = US_info_table.Properties.VariableNames;
            app.UITable.Data = {table_params{2};table_params{3};...
                table_params{4};table_params{5}};

            app.table_vals = ...
                US_info_table{find(strcmp(US_info_table{:,1}, {value})),2:6};
            app.UITable.Data(:,2) = {app.table_vals(1);...
                app.table_vals(2);app.table_vals(3);app.table_vals(4)};

            app.dicom_dxdydz = [US_info_table.dicom_dx(8) US_info_table.dicom_dy(8) US_info_table.dicom_dz(8)];

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            directory = [app.case_path,app.procedure_photos_extension];
            app.photo_names = uigetfile([directory,'*.jpg'],...
                'MultiSelect','on','Select 2 photos from procedure');

            photo1 = imread([directory,app.photo_names{1}]);
            photo2 = imread([directory,app.photo_names{2}]);

            imshow(photo1,'Parent',app.UIAxes)
            axis(app.UIAxes,'equal','tight');
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');

            imshow(photo2,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'equal','tight');
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.US_path = [app.case_path,'ultrasound/'];
            %             [rawData_cart, app.cum_decorr, app.cum_decorr_motion_corr, app.xyz_range] = ...
            app.IQdata = process_US(app.trial_name, app.US_path, app.table_vals);

            rawData_cart = app.IQdata{1};
            app.xyz_range = app.IQdata{2};

            app.Bmode = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
            app.Bmode = flip(app.Bmode,2);
            app.Bmode = flip(app.Bmode,3);

            imagesc(app.UIAxes_3,app.Bmode(:,:,30,app.default_IQfile_number))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSlider.Limits = [1 size(app.Bmode,3)];
            app.SliceSlider.Value = 30;
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_3,app.Bmode(:,:,round(changingValue),app.default_IQfile_number))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSliderLabel.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            address = [app.case_path,app.trial_number,'_suite CT/',app.suite_CT_address{app.case_number}];
            app.suite_CT_vol = read_suite_CT(address)

            imagesc(app.UIAxes_4,app.suite_CT_vol(:,:,30))
            axis(app.UIAxes_4,'equal','tight');
            colormap(app.UIAxes_4,'gray');
            app.SliceSlider_6.Limits = [1 size(app.suite_CT_vol,3)];
            app.SliceSlider_6.Value = 30;
        end

        % Value changing function: SliceSlider_6
        function SliceSlider_6ValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_4,app.suite_CT_vol(:,:,round(changingValue)));
            axis(app.UIAxes_4,'image');
            colormap(app.UIAxes_4,'gray');
            app.SliceSlider_6Label.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            address = [app.case_path,app.trial_number,'_1day_post_ablation/',app.post_abl_1day_address{app.case_number}];
            app.post_abl_1day_obj = read_1day_post_ablation(address);
            app.post_abl_1day_vol = app.post_abl_1day_obj.vol;
            app.post_abl_1day_vol_image = read_1day_post_ablation_image(address);
            %             app.post_abl_1day_vol_gray = repmat(mat2gray(app.post_abl_1day_vol,[0 1]),[1,1,1,3]);
            %             app.mask3D = zeros(size(app.post_abl_1day_vol));
            app.dicom_x = (1:size(app.post_abl_1day_vol,2))*app.dicom_dxdydz(1);
            app.dicom_y = (1:size(app.post_abl_1day_vol,1))*app.dicom_dxdydz(2);
            app.dicom_z = (1:size(app.post_abl_1day_vol,3))*app.dicom_dxdydz(3);
            [app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh] = meshgrid(app.dicom_x, app.dicom_y, app.dicom_z);

            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,30))
            axis(app.UIAxes_13,'equal','tight');
            colormap(app.UIAxes_13,'gray');
            app.SliceSlider_7.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_7.Value = 79;

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, app.post_abl_1day_vol(:,:,30));
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'gray');

            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_y, squeeze(app.post_abl_1day_vol(:,10,:)));
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'gray');

            imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, rot90(squeeze(app.post_abl_1day_vol(10,:,:))));
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'gray');
        end

        % Value changing function: SliceSlider_7
        function SliceSlider_7ValueChanging(app, event)
            app.slider7value = event.Value;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);

            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,round(app.slider7value)));
            axis(app.UIAxes_13,'image');
            colormap(app.UIAxes_13,'gray');

            if app.registered_US_flag
                imagesc(app.UIAxes_14,app.registered_US(:,:,round(app.slider7value)))
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15,app.registered_US_LR(:,:,round(app.overlay_slice)))
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay(:,:,2) = app.temp_registered_US(:,:,round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:,:,2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:,:,2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %             [app.apex_loc(1), app.apex_loc(2)] = getpts(app.UIAxes_13);
            %             app.apex_loc = round(app.apex_loc);
            app.apex_loc(1) = 76;
            app.apex_loc(2) = 135;
            app.apex_loc(3) = 187;
        end

        % Drop down opening function: OrderofrotationDropDown
        function OrderofrotationDropDownOpening(app, event)
            app.OrderofrotationDropDown.Items = app.rotation_order;
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value
        end

        % Value changed function: OrderofrotationDropDown
        function OrderofrotationDropDownValueChanged(app, event)
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value
        end

        % Value changed function: ApextoneedleinZmmEditField
        function ApextoneedleinZmmEditFieldValueChanged(app, event)
            app.apex_to_needle_dist = app.ApextoneedleinZmmEditField.Value;
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.rotation_angles(1) = app.YawEditField.Value;
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.rotation_angles(2) = app.PitchEditField.Value;
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.rotation_angles(3) = app.RollEditField.Value;
        end

        % Button pushed function: UpdateregistrationButton
        function UpdateregistrationButtonPushed(app, event)
            app.Lamp.Color = 'red';
            app.EditField.Value = 'Registration in progress...';
            %             app.registered_US = register(app.post_abl_1day_obj, ...
            %                             app.Bmode, app.xyz_range, app.apex_loc,...
            %                             app.apex_to_needle_dist,app.rotation_order_choice,...
            %                             app.rotation_angles,...
            %                                     'Bmode');
            app.registered_US = load('/Users/elmiraghahramani/Desktop/registUS.mat');
            app.registered_US = app.registered_US.temp;
            app.apex_loc = round([80.45, 147.27, 194]);
            app.apex_to_needle_dist = 0;
            app.Lamp.Color = 'green';
            app.EditField.Value = 'Registration is now complete!';

            imagesc(app.UIAxes_14,app.registered_US(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');

            app.registered_US_flag = true;
            app.XSlider.Limits = [-100 100];
            app.YSlider.Limits = [-100 100];
            %             inside_echo = app.registered_US ~= NaN;
            app.post_abl_1day_obj
            app.temp_registered_US = app.registered_US;%(app.apex_loc(2), app.apex_loc(1));
        end

        % Value changed function: NeedletrackslicenumberEditField
        function NeedletrackslicenumberEditFieldValueChanged(app, event)
            value = app.NeedletrackslicenumberEditField.Value;
            app.apex_loc(3) = round(value);
        end

        % Button pushed function: OverlayultrasoundButton
        function OverlayultrasoundButtonPushed(app, event)
            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));
                %
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.temp_registered_US(:,:,round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:,:,2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:,:,2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,round(app.overlay_slice)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end

            app.AlphaSlider.Limits = [0 1];%[min(app.registered_US(:)) max(app.registered_US(:))];

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                app.OverlayultrasoundButton.Text = 'Remove overlay';
                app.overlay_flag = true;
            else
                app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
                app.overlay_flag = false;
            end
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            if app.overlay_flag || app.rotatio_flag
                app.overlay_threshold = event.Value;
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay(:,:,2) = app.temp_registered_US(:,:,round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:,:,2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:,:,2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            end
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            if app.overlay_flag
                app.x_translation_flag = true;
                changingValue = round(event.Value);
                if app.y_translation_flag || app.rotation_flag
                    temp = app.temp_registered_US;
                else
                    temp = NaN(size(app.registered_US));
                end

                if changingValue >= 0
                    temp(:,changingValue+1:end,:) = app.registered_US(:,1:end-changingValue,:);
                else
                    temp(:,1:end-abs(changingValue),:) = app.registered_US(:,abs(changingValue)+1:end,:);
                end
                app.temp_registered_US = temp;

                % Overlaid US Axes_13
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');

                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', ...
                    app.temp_registered_US(:,:,app.overlay_slice)>=round(app.overlay_threshold))
            end
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            if app.overlay_flag
                app.y_translation_flag = true;
                changingValue = round(event.Value);
                if app.x_translation_flag || app.rotation_flag
                    temp = app.temp_registered_US;
                else
                    temp = NaN(size(app.registered_US));
                end

                if changingValue >= 0
                    temp(changingValue+1:end,:,:) = app.registered_US(1:end-changingValue,:,:);
                else
                    temp(1:end-abs(changingValue),:,:) = app.registered_US(abs(changingValue)+1:end,:,:);
                end
                app.temp_registered_US = temp;

                % Overlaid US Axes_13
                vol = app.post_abl_1day_vol(:,:,round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');

                %                 hold(app.UIAxes_13,'on');
                h = imshow(app.green,'Parent',app.UIAxes_18);
                %                 hold(app.UIAxes_13,'off');
                set(h, 'AlphaData', ...
                    app.temp_registered_US(:,:,app.overlay_slice)>=round(app.overlay_threshold))
            end
        end

        % Button pushed function: ApplytranslationButton
        function ApplytranslationButtonPushed(app, event)
            app.registered_US = app.temp_registered_US;

            imagesc(app.UIAxes_14,app.registered_US(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');
        end

        % Button pushed function: ApplytoLRUSButton
        function ApplytoLRUSButtonPushed(app, event)
            app.US_path = [app.case_path,'ultrasound/'];
            app.trial_name_LR = app.trial_name;
            app.trial_name_LR(end-1:end) = 'LR';

            US_info_table = readtable([app.base_path,'ablation_information_for_app.xlsx']);
            app.table_vals_LR = ...
                US_info_table{find(strcmp(US_info_table{:,1}, {app.trial_name_LR})),2:6};

            %             app.IQdata_LR = process_US(app.trial_name_LR, app.US_path, app.table_vals_LR);
            %
            %             rawData_cart = app.IQdata_LR{1};
            %             app.xyz_range_LR = app.IQdata_LR{2};
            %
            %             app.Bmode_LR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
            %             app.Bmode_LR = flip(app.Bmode_LR,2);
            %             app.Bmode_LR = flip(app.Bmode_LR,3);
            %
            %             app.Lamp.Color = 'red';
            %             app.EditField.Value = 'Registration in progress...';
            %             app.registered_US_LR = register(app.post_abl_1day_obj, ...
            %                 app.Bmode_LR, app.xyz_range_LR, app.apex_loc,...
            %                 app.apex_to_needle_dist,app.rotation_order_choice,...
            %                 app.rotation_angles, 'Bmode');

            app.registered_US_LR = load('/Users/elmiraghahramani/Desktop/registUS_LR.mat');
            app.registered_US_LR = app.registered_US_LR.registered_US;
            app.Lamp.Color = 'green';
            app.EditField.Value = 'Registration is now complete!';

            imagesc(app.UIAxes_15,app.registered_US_LR(:,:,round(app.overlay_slice)))
            axis(app.UIAxes_15,'equal','tight');
            colormap(app.UIAxes_15,'gray');

            %             imagesc(app.UIAxes_12,app.registered_US_LR(:,:,round(app.overlay_slice)))
            %             axis(app.UIAxes_12,'equal','tight');
            %             colormap(app.UIAxes_12,'gray');

            app.registered_US_LR_flag = true;
            app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_10.Limits = [1 size(app.post_abl_1day_vol,2)];
            app.SliceSlider_11.Limits = [1 size(app.post_abl_1day_vol,1)];
            app.AlphaSlider_3.Limits = [0 1];
        end

        % Button pushed function: ApplyregistrationButton
        function ApplyregistrationButtonPushed(app, event)
            app.Lamp_2.Color = 'red';
            %             a = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/US_alldatafor_12032021_T1_LR.mat');
            %             app.registered_decorr = register(app.post_abl_1day_obj, ...
            %                 a.cum_decorr, a.xyz_range, app.apex_loc,...
            %                 app.apex_to_needle_dist,app.rotation_order_choice,...
            %                 app.rotation_angles, 'decorr');
            %
            %             app.registered_motion_corr_decorr = register(app.post_abl_1day_obj, ...
            %                 a.cum_decorr_motion_corr, a.xyz_range, app.apex_loc,...
            %                 app.apex_to_needle_dist,app.rotation_order_choice,...
            %                 app.rotation_angles, 'mot corr decorr');
            %             app.registered_decorr = register(app.post_abl_1day_obj, ...
            %                 app.IQdata_LR{3}, app.xyz_range_LR, app.apex_loc,...
            %                 app.apex_to_needle_dist,app.rotation_order_choice,...
            %                 app.rotation_angles, 'decorr');
            %
            %             app.registered_motion_corr_decorr = register(app.post_abl_1day_obj, ...
            %                 app.IQdata_LR{4}, app.xyz_range_LR, app.apex_loc,...
            %                 app.apex_to_needle_dist,app.rotation_order_choice,...
            %                 app.rotation_angles, 'mot corr decorr');
            app.registered_decorr = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/registdecorr.mat');
            app.registered_decorr = app.registered_decorr.registered_US;
            app.registered_motion_corr_decorr = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/registmotcorrdecorr.mat');
            app.registered_motion_corr_decorr = app.registered_motion_corr_decorr.registered_US;
            %             app.registered_US = load('/Users/elmiraghahramani/Desktop/registUS.mat');
            %             app.registered_US = app.registered_US.temp;
            app.registered_decorr_scaled = app.registered_decorr/app.max_decorr;
            app.registered_decorr_scaled(find(app.registered_decorr_scaled<=0)) = 1.e-16;
            app.registered_decorr_scaled = (log10(app.registered_decorr_scaled)+app.dyn_range)/app.dyn_range; %DR MAST
            app.registered_decorr_scaled(find(app.registered_decorr_scaled>1)) = 1;
            app.registered_decorr_scaled(find(app.registered_decorr_scaled<0)) = 0;
            app.registered_decorr_scaled(isnan(app.registered_decorr_scaled)) = 0;

            app.registered_motion_corr_decorr_scaled = app.registered_motion_corr_decorr/app.max_decorr;
            app.registered_motion_corr_decorr_scaled(find(app.registered_motion_corr_decorr_scaled<=0)) = 1.e-16;
            app.registered_motion_corr_decorr_scaled = (log10(app.registered_motion_corr_decorr_scaled)+app.dyn_range)/app.dyn_range; %DR MAST
            app.registered_motion_corr_decorr_scaled(find(app.registered_motion_corr_decorr_scaled>1)) = 1;
            app.registered_motion_corr_decorr_scaled(find(app.registered_motion_corr_decorr_scaled<0)) = 0;
            app.registered_motion_corr_decorr_scaled(isnan(app.registered_motion_corr_decorr_scaled)) = 0;

            %             imagesc(app.UIAxes_9,...
            %                 app.registered_decorr(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_9,'equal','tight');
            %             colormap(app.UIAxes_9,'hot');
            %
            %             imagesc(app.UIAxes_10,...
            %                 app.registered_motion_corr_decorr(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_10,'equal','tight');
            %             colormap(app.UIAxes_10,'hot');
            %
            %             imagesc(app.UIAxes_11,app.post_abl_1day_vol(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_11,'image');
            %             colormap(app.UIAxes_11,'gray');

            app.Lamp_2.Color = 'green';
            app.decorrs_flag = true;
        end

        % Value changing function: SliceSlider_8
        function SliceSlider_8ValueChanging(app, event)
            app.slider8value = event.Value;
            app.SliceSlider_8Label.Text = num2str(round(app.slider8value));
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            %             imagesc(app.UIAxes_12,app.post_abl_1day_vol(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_12,'image');
            %             colormap(app.UIAxes_12,'gray');
            %
            %             if app.decorrs_flag
            %                 imagesc(app.UIAxes_9,...
            %                     app.registered_decorr(:,:,round(app.slider8value)));
            %                 axis(app.UIAxes_9,'equal','tight');
            %                 colormap(app.UIAxes_9,'hot');
            %
            %                 imagesc(app.UIAxes_10,...
            %                     app.registered_motion_corr_decorr(:,:,round(app.slider8value)));
            %                 axis(app.UIAxes_10,'equal','tight');
            %                 colormap(app.UIAxes_10,'hot');
            %             end
            %
            %             imagesc(app.UIAxes_11,app.post_abl_1day_vol(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_11,'image');
            %             colormap(app.UIAxes_11,'gray');
        end

        % Button pushed function: DisplayMRIButton
        function DisplayMRIButtonPushed(app, event)
            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,30))
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');
            app.SliceSpinner.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSpinner.Value = 79;
            app.mask3D = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/ablation_mask3D.mat');
            app.mask3D = app.mask3D.ablation_mask3D;
        end

        % Value changed function: AblationfirstsliceEditField
        function AblationfirstsliceEditFieldValueChanged(app, event)
            app.lesion_slice(1) = app.AblationfirstsliceEditField.Value;
        end

        % Value changed function: AblationlastsliceEditField
        function AblationlastsliceEditFieldValueChanged(app, event)
            app.lesion_slice(2) = app.AblationlastsliceEditField.Value;
        end

        % Button pushed function: EditpolygonButton
        function EditpolygonButtonPushed(app, event)
            roiPoly = impoly(app.UIAxes_16);
            app.mask = createMask(roiPoly);
            mask2D = app.mask;

            imagesc(app.UIAxes_16,app.mask);
            axis(app.UIAxes_16,'image');
            colormap(app.UIAxes_16,'gray');

            app.mask_number = app.SliceSpinnervalue - app.lesion_slice(1) + 1;
            save(['/Users/elmiraghahramani/Desktop/temp/seg',num2str(app.mask_number),'.mat'],'mask2D');
            app.mask3D(:,:,app.mask_number) = app.mask;
            clear mask2D
            pause(1);

            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.SliceSpinnervalue)));
            axis(app.UIAxes_16,'image');
            colormap(app.UIAxes_16,'gray');
        end

        % Button pushed function: FinishsegmentationButton
        function FinishsegmentationButtonPushed(app, event)
            %             save(['/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/Segmented 1day postop/seg',num2str(app.mask_number),'.mat'],'app.mask');
            %             app.mask3D = flip(app.mask3D,3);
            %             save('/Users/elmiraghahramani/Desktop/temp','app.mask3D');

        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            app.SliceSpinnervalue = event.Value;

            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.SliceSpinnervalue)));
            axis(app.UIAxes_16,'image');
            colormap(app.UIAxes_16,'gray');
        end

        % Button pushed function: DreconstructlesionButton
        function DreconstructlesionButtonPushed(app, event)
            b = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/ablation_mask3D.mat');
            app.mask3D = b.ablation_mask3D;
            app.mask3D_gauss = imgaussfilt3(app.mask3D,2);

            %             cla(app.UIAxes_16);
            p = patch(app.UIAxes_18,isosurface(app.mask3D_gauss,.2));
            p.FaceColor = 'red';
            p.EdgeColor = 'none';
            app.UIAxes_18.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_18, 3);
            axis(app.UIAxes_18,'tight');
            camlight(app.UIAxes_18);
            lighting gouraud
            app.UIAxes_18.XLim = [0 size(app.mask3D_gauss,1)];
            app.UIAxes_18.YLim = [0 size(app.mask3D_gauss,2)];
            app.UIAxes_18.ZLim = [0 size(app.mask3D_gauss,3)];
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            %             app.UIAxes_16.XTick = [];
            %             app.UIAxes_16.YTick = [];
        end

        % Button pushed function: DisplayROCcurvesButton
        function DisplayROCcurvesButtonPushed(app, event)
            if strcmp(app.ROC_trials,'All trials')
                app.ROC_trials = app.Date;
            else
                app.ROC_trials = app.trial_name;
            end
            
            [output_fptpAUC, output_motcorr_fptpAUC] = ...
                ROC_analysis(app.registered_decorr, app.registered_motion_corr_decorr, app.mask3D, app.ROC_trials)

            not_corr_fprate_ablatedliverAll = output_fptpAUC(:,1);
            not_corr_tprate_ablatedliverAll = output_fptpAUC(:,2);
            not_corr_ROCarea_ablatedliverAll  = output_fptpAUC(1,3);

            mot_corr_fprate_ablatedliverAll = output_motcorr_fptpAUC(:,1);
            mot_corr_tprate_ablatedliverAll = output_motcorr_fptpAUC(:,2);
            mot_corr_ROCarea_ablatedliverAll  = output_motcorr_fptpAUC(1,3);

            plot(app.UIAxes_17,not_corr_fprate_ablatedliverAll,not_corr_tprate_ablatedliverAll,'-r',...
                mot_corr_fprate_ablatedliverAll,mot_corr_tprate_ablatedliverAll,'-b',...
                [0 1],[0 1],'--k','LineWidth',1);
            app.UIAxes_17.XLim = [-.001 1.001];
            app.UIAxes_17.YLim = [-.001 1.001];
            axis(app.UIAxes_17,'equal','tight');
            legend(app.UIAxes_17,['Cum Dec, AUROC=',num2str(round(not_corr_ROCarea_ablatedliverAll,3))],...
                ['Mot Corr Cum Dec, AUROC=',num2str(round(mot_corr_ROCarea_ablatedliverAll,3))],...
                'Location','SouthEast')
            grid(app.UIAxes_17,'on');

            app.AUCEditField.Value = round(not_corr_ROCarea_ablatedliverAll,3);
            app.MotioncorrectedAUCEditField.Value = round(mot_corr_ROCarea_ablatedliverAll,3);

            %             saveas(app.UIAxes_17,'ROC_total_w_wo_motion_corr_rhoScalFact2.png');
            %             saveas(app.UIAxes_17,'ROC_total_w_wo_motion_corr_rhoScalFact2.fig');
        end

        % Value changing function: SliceSlider_10
        function SliceSlider_10ValueChanging(app, event)
            app.slider10value = event.Value;
            app.SliceSlider_10Label.Text = num2str(round(app.slider10value));

            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');
        end

        % Value changing function: SliceSlider_11
        function SliceSlider_11ValueChanging(app, event)
            app.slider11value = event.Value;
            app.SliceSlider_11Label.Text = num2str(round(app.slider11value));
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            imagesc(app.UIAxes_10, app.dicom_z, app.dicom_x, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Value changed function: DisplaydecorrelationDropDown
        function DisplaydecorrelationDropDownValueChanged(app, event)
            app.display_decorr = app.DisplaydecorrelationDropDown.Value

            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');

            imagesc(app.UIAxes_10, app.dicom_z, app.dicom_x, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Value changing function: AlphaSlider_3
        function AlphaSlider_3ValueChanging(app, event)
            app.alpha3value = event.Value;
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');

            imagesc(app.UIAxes_10, app.dicom_z, app.dicom_x, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Button pushed function: Update3DfigureButton
        function Update3DfigureButtonPushed(app, event)
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end
            
            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, app.post_abl_1day_vol,round(app.slider10value)*app.dicom_dxdydz(1),0,round(app.slider8value)*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5), colormap(app.UIAxes_11,'gray'),
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');
            
            cum_decorr_gauss = imgaussfilt3(cum_decorrelation,2);
            p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, imgaussfilt3(cum_decorr_gauss,.2)));%squeeze(hybrid(predictor,:,:,:,2)),threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
%             app.UIAxes_11.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
%             app.UIAxes_11.XLim = [0 size(cum_decorr_gauss,1)];
%             app.UIAxes_11.YLim = [0 size(cum_decorr_gauss,2)];
%             app.UIAxes_11.ZLim = [0 size(cum_decorr_gauss,3)];
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
        end

        % Drop down opening function: TrialsDropDown
        function TrialsDropDownOpening(app, event)
%             app.TrialsDropDown.Items = app.date;
            app.ROC_trials = app.TrialsDropDown.Value;
        end

        % Value changed function: TrialsDropDown
        function TrialsDropDownValueChanged(app, event)
            app.ROC_trials = app.TrialsDropDown.Value;
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.UIAxes = uiaxes(app.RegistrationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RegistrationTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RegistrationTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RegistrationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Image = uiimage(app.RegistrationTab);
            app.Image.ImageSource = 'axes_photo.jpeg';
            app.UITable = uitable(app.RegistrationTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.SliceSliderLabel = uilabel(app.RegistrationTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.RegistrationTab);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.DateDropDownLabel = uilabel(app.RegistrationTab);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.RegistrationTab);
            app.DateDropDown.Items = {};
            app.DateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DateDropDownOpening, true);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.Button = uibutton(app.RegistrationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'CT.png';
            app.Button.Text = '';
            app.RegistrationPanel = uipanel(app.RegistrationTab);
            app.RegistrationPanel.Title = 'Registration';
            app.UIAxes_13 = uiaxes(app.RegistrationPanel);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.RegistrationPanel);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.RegistrationPanel);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.YawEditFieldLabel = uilabel(app.RegistrationPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.Button_2 = uibutton(app.RegistrationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'apex.png';
            app.Button_2.Text = '';
            app.OrderofrotationDropDownLabel = uilabel(app.RegistrationPanel);
            app.OrderofrotationDropDownLabel.Text = 'Order of rotation';
            app.OrderofrotationDropDown = uidropdown(app.RegistrationPanel);
            app.OrderofrotationDropDown.Items = {};
            app.OrderofrotationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OrderofrotationDropDownOpening, true);
            app.OrderofrotationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderofrotationDropDownValueChanged, true);
            app.OrderofrotationDropDown.Value = {};
            app.PitchEditFieldLabel = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditFieldLabel_2 = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel_2.Text = 'Roll';
            app.RollEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.EchoapexonMRLabel = uilabel(app.RegistrationPanel);
            app.EchoapexonMRLabel.Text = 'Echo apex on MR';
            app.UpdateregistrationButton = uibutton(app.RegistrationPanel, 'push');
            app.UpdateregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateregistrationButtonPushed, true);
            app.UpdateregistrationButton.Text = 'Update registration';
            app.Lamp = uilamp(app.RegistrationPanel);
            app.EditField = uieditfield(app.RegistrationPanel, 'text');
            app.SliceSlider_7Label = uilabel(app.RegistrationPanel);
            app.SliceSlider_7Label.Text = 'Slice';
            app.SliceSlider_7 = uislider(app.RegistrationPanel);
            app.SliceSlider_7.MajorTicks = [];
            app.SliceSlider_7.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_7ValueChanging, true);
            app.SliceSlider_7.MinorTicks = [];
            app.OverlayultrasoundButton = uibutton(app.RegistrationPanel, 'push');
            app.OverlayultrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayultrasoundButtonPushed, true);
            app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
            app.AlphaSliderLabel = uilabel(app.RegistrationPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.RegistrationPanel);
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.MinorTicks = [];
            app.ApextoneedleinZmmEditFieldLabel = uilabel(app.RegistrationPanel);
            app.ApextoneedleinZmmEditFieldLabel.Text = 'Apex to needle in Z (mm)';
            app.ApextoneedleinZmmEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.ApextoneedleinZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ApextoneedleinZmmEditFieldValueChanged, true);
            app.RotationanglesdegLabel = uilabel(app.RegistrationPanel);
            app.RotationanglesdegLabel.Text = 'Rotation angles (deg):';
            app.ApplytoLRUSButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytoLRUSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoLRUSButtonPushed, true);
            app.ApplytoLRUSButton.Text = 'Apply to LR US';
            app.NeedletrackslicenumberEditFieldLabel = uilabel(app.RegistrationPanel);
            app.NeedletrackslicenumberEditFieldLabel.Text = 'Needle track slice number';
            app.NeedletrackslicenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.NeedletrackslicenumberEditField.ValueChangedFcn = createCallbackFcn(app, @NeedletrackslicenumberEditFieldValueChanged, true);
            app.TranslateinXorYLabel = uilabel(app.RegistrationPanel);
            app.TranslateinXorYLabel.Text = 'Translate in X or Y:';
            app.XSliderLabel = uilabel(app.RegistrationPanel);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.RegistrationPanel);
            app.XSlider.Limits = [-100 100];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [];
            app.YSliderLabel = uilabel(app.RegistrationPanel);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.RegistrationPanel);
            app.YSlider.Limits = [-100 100];
            app.YSlider.MajorTicks = [];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YSlider.MinorTicks = [];
            app.ApplytranslationButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytranslationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytranslationButtonPushed, true);
            app.ApplytranslationButton.Text = 'Apply translation';
            app.SliceSlider_6Label = uilabel(app.RegistrationTab);
            app.SliceSlider_6Label.Text = 'Slice';
            app.SliceSlider_6 = uislider(app.RegistrationTab);
            app.SliceSlider_6.MajorTicks = [];
            app.SliceSlider_6.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_6ValueChanging, true);
            app.SliceSlider_6.MinorTicks = [];
            app.Button_3 = uibutton(app.RegistrationTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'US.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.RegistrationTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'photo.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.RegistrationTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'mri.png';
            app.Button_5.Text = '';
            app.SegmentationandROCTab = uitab(app.TabGroup);
            app.SegmentationandROCTab.Title = 'Segmentation and ROC';
            app.UIAxes_9 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_9, 'Z (mm)')
            ylabel(app.UIAxes_9, 'Y (mm)')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_10, 'X (mm)')
            ylabel(app.UIAxes_10, 'Z (mm)')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.SegmentationandROCTab);
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_12, 'X (mm)')
            ylabel(app.UIAxes_12, 'Y (mm)')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ApplyregistrationButton = uibutton(app.SegmentationandROCTab, 'push');
            app.ApplyregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyregistrationButtonPushed, true);
            app.ApplyregistrationButton.Text = 'Apply registration';
            app.SegmentationPanel = uipanel(app.SegmentationandROCTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UIAxes_16 = uiaxes(app.SegmentationPanel);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_18 = uiaxes(app.SegmentationPanel);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.DisplayMRIButton = uibutton(app.SegmentationPanel, 'push');
            app.DisplayMRIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMRIButtonPushed, true);
            app.DisplayMRIButton.Text = 'Display MRI';
            app.AblationfirstsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationfirstsliceEditFieldLabel.Text = 'Ablation first slice';
            app.AblationfirstsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationfirstsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationfirstsliceEditFieldValueChanged, true);
            app.AblationlastsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationlastsliceEditFieldLabel.Text = 'Ablation last slice';
            app.AblationlastsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationlastsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationlastsliceEditFieldValueChanged, true);
            app.DreconstructlesionButton = uibutton(app.SegmentationPanel, 'push');
            app.DreconstructlesionButton.ButtonPushedFcn = createCallbackFcn(app, @DreconstructlesionButtonPushed, true);
            app.DreconstructlesionButton.Text = '3D reconstruct lesion';
            app.EditpolygonButton = uibutton(app.SegmentationPanel, 'push');
            app.EditpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @EditpolygonButtonPushed, true);
            app.EditpolygonButton.Text = 'Edit polygon';
            app.FinishsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.FinishsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @FinishsegmentationButtonPushed, true);
            app.FinishsegmentationButton.Text = 'Finish segmentation';
            app.SliceSpinnerLabel = uilabel(app.SegmentationPanel);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.SegmentationPanel);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.ROCcurveanalysisPanel = uipanel(app.SegmentationandROCTab);
            app.ROCcurveanalysisPanel.Title = 'ROC curve analysis';
            app.UIAxes_17 = uiaxes(app.ROCcurveanalysisPanel);
            xlabel(app.UIAxes_17, '1-Specificity')
            ylabel(app.UIAxes_17, 'Sensitivity')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.AUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUCEditFieldLabel.Text = 'AUC';
            app.AUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUCEditField.ValueChangedFcn = createCallbackFcn(app, @AUC2EditFieldValueChanged, true);
            app.MotioncorrectedAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.MotioncorrectedAUCEditFieldLabel.Text = 'Motion corrected AUC';
            app.MotioncorrectedAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.DisplayROCcurvesButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.DisplayROCcurvesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROCcurvesButtonPushed, true);
            app.DisplayROCcurvesButton.Text = 'Display ROC curves';
            app.TrialsDropDownLabel = uilabel(app.ROCcurveanalysisPanel);
            app.TrialsDropDownLabel.Text = 'Trials';
            app.TrialsDropDown = uidropdown(app.ROCcurveanalysisPanel);
            app.TrialsDropDown.Items = {'Current trial', 'All trials'};
            app.TrialsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrialsDropDownOpening, true);
            app.TrialsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialsDropDownValueChanged, true);
            app.TrialsDropDown.Value = 'Current trial';
            app.SavefigureButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.SliceSlider_8Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_8Label.Text = 'Slice';
            app.SliceSlider_8 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_8.MajorTicks = [];
            app.SliceSlider_8.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_8ValueChanging, true);
            app.SliceSlider_8.MinorTicks = [];
            app.Lamp_2 = uilamp(app.SegmentationandROCTab);
            app.SliceSlider_10Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_10Label.Text = 'Slice';
            app.SliceSlider_10 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_10.MajorTicks = [];
            app.SliceSlider_10.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_10ValueChanging, true);
            app.SliceSlider_10.MinorTicks = [];
            app.SliceSlider_11Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_11Label.Text = 'Slice';
            app.SliceSlider_11 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_11.MajorTicks = [];
            app.SliceSlider_11.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_11ValueChanging, true);
            app.SliceSlider_11.MinorTicks = [];
            app.DisplaydecorrelationDropDownLabel = uilabel(app.SegmentationandROCTab);
            app.DisplaydecorrelationDropDownLabel.Text = 'Display decorrelation';
            app.DisplaydecorrelationDropDown = uidropdown(app.SegmentationandROCTab);
            app.DisplaydecorrelationDropDown.Items = {'Without motion correction', 'With motion correction'};
            app.DisplaydecorrelationDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydecorrelationDropDownValueChanged, true);
            app.DisplaydecorrelationDropDown.Value = 'Without motion correction';
            app.AlphaSlider_3Label = uilabel(app.SegmentationandROCTab);
            app.AlphaSlider_3Label.Text = 'Alpha';
            app.AlphaSlider_3 = uislider(app.SegmentationandROCTab);
            app.AlphaSlider_3.MajorTicks = [];
            app.AlphaSlider_3.ValueChangingFcn = createCallbackFcn(app, @AlphaSlider_3ValueChanging, true);
            app.AlphaSlider_3.MinorTicks = [];
            app.Update3DfigureButton = uibutton(app.SegmentationandROCTab, 'push');
            app.Update3DfigureButton.ButtonPushedFcn = createCallbackFcn(app, @Update3DfigureButtonPushed, true);
            app.Update3DfigureButton.Text = 'Update 3D figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radiology_ablation_data_processing_v5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end