classdef radiology_ablation_data_processing_v8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RegistrationTab               matlab.ui.container.Tab
        Button_5                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        RegistrationPanel             matlab.ui.container.Panel
        DrawrectangularROIButton_2    matlab.ui.control.Button
        NeedletrackslicenumberEditField  matlab.ui.control.NumericEditField
        NeedletrackslicenumberEditFieldLabel  matlab.ui.control.Label
        ApplytoLRUSButton             matlab.ui.control.Button
        RotationanglesdegLabel        matlab.ui.control.Label
        ApextoneedleinZmmEditField    matlab.ui.control.NumericEditField
        ApextoneedleinZmmEditFieldLabel  matlab.ui.control.Label
        AlphaSlider                   matlab.ui.control.Slider
        AlphaSliderLabel              matlab.ui.control.Label
        OverlayultrasoundButton       matlab.ui.control.Button
        SliceSlider_7                 matlab.ui.control.Slider
        SliceSlider_7Label            matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        UpdateregistrationButton      matlab.ui.control.Button
        EchoapexonMRLabel             matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel_2         matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        OrderofrotationDropDown       matlab.ui.control.DropDown
        OrderofrotationDropDownLabel  matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        YawEditField                  matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        UIAxes_15                     matlab.ui.control.UIAxes
        UIAxes_14                     matlab.ui.control.UIAxes
        UIAxes_13                     matlab.ui.control.UIAxes
        Button                        matlab.ui.control.Button
        DateDropDown                  matlab.ui.control.DropDown
        DateDropDownLabel             matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        SliceSliderLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SegmentationandROCTab         matlab.ui.container.Tab
        Update3DfigureButton          matlab.ui.control.Button
        AlphaSlider_3                 matlab.ui.control.Slider
        AlphaSlider_3Label            matlab.ui.control.Label
        DisplaydecorrelationDropDown  matlab.ui.control.DropDown
        DisplaydecorrelationDropDownLabel  matlab.ui.control.Label
        SliceSlider_11                matlab.ui.control.Slider
        SliceSlider_11Label           matlab.ui.control.Label
        SliceSlider_10                matlab.ui.control.Slider
        SliceSlider_10Label           matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        SliceSlider_8                 matlab.ui.control.Slider
        SliceSlider_8Label            matlab.ui.control.Label
        ROCcurveanalysisPanel         matlab.ui.container.Panel
        SavefigureButton              matlab.ui.control.Button
        TrialsDropDown                matlab.ui.control.DropDown
        TrialsDropDownLabel           matlab.ui.control.Label
        DisplayROCcurvesButton        matlab.ui.control.Button
        MotioncorrectedAUCEditField   matlab.ui.control.NumericEditField
        MotioncorrectedAUCEditFieldLabel  matlab.ui.control.Label
        AUCEditField                  matlab.ui.control.NumericEditField
        AUCEditFieldLabel             matlab.ui.control.Label
        UIAxes_17                     matlab.ui.control.UIAxes
        SegmentationPanel             matlab.ui.container.Panel
        DrawrectangularROIButton      matlab.ui.control.Button
        SliceSpinner                  matlab.ui.control.Spinner
        SliceSpinnerLabel             matlab.ui.control.Label
        FinishsegmentationButton      matlab.ui.control.Button
        EditpolygonButton             matlab.ui.control.Button
        DreconstructlesionButton      matlab.ui.control.Button
        AblationlastsliceEditField    matlab.ui.control.NumericEditField
        AblationlastsliceEditFieldLabel  matlab.ui.control.Label
        AblationfirstsliceEditField   matlab.ui.control.NumericEditField
        AblationfirstsliceEditFieldLabel  matlab.ui.control.Label
        DisplayMRIButton              matlab.ui.control.Button
        UIAxes_18                     matlab.ui.control.UIAxes
        UIAxes_16                     matlab.ui.control.UIAxes
        ApplyregistrationButton       matlab.ui.control.Button
        UIAxes_12                     matlab.ui.control.UIAxes
        UIAxes_11                     matlab.ui.control.UIAxes
        UIAxes_10                     matlab.ui.control.UIAxes
        UIAxes_9                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        drop_down_val
        US_decorr_xyz
        case_path % Description
        US_path
        photo_names
        table_vals
        trial_name
        cum_decorr
        cum_decorr_motion_corr
        xyz_range
        Bmode
        trial_number
        tumor_no
        suite_CT_vol
        post_abl_1day_obj
        post_abl_1day_vol
        post_abl_1day_vol_image
        apex_loc = []
        apex_to_needle_dist
        rotation_order_choice
        rotation_angles
        US_imaging_xyz
        registered_US_flag = false
        registered_US_LR_flag = false
        registered_US
        registered_US_LR
        overlay_flag = false
        overlay_slice = 30
        green
        overlay_threshold = 0
        slider7value = 10
        slider8value = 1
        slider10value = 10
        slider11value = 10
        decorrs_flag = false
        trial_name_LR
        table_vals_LR
        xyz_range_LR
        Bmode_LR
        registered_decorr_unscaled
        registered_decorr_scaled
        registered_motion_corr_decorr_unscaled
        registered_motion_corr_decorr_scaled
        US_overlay
        lesion_slice = [0,0]
        mask
        mask3D = []
        mask3D_gauss
        mask_number
        SliceSlider_9value
        alpha_decorr_value = 0.5
        display_decorr
        alpha3value = 0.5
        max_decorr = .1;
        dyn_range = 3;
        max_Bmode = 0.08;
        dB_range = 65;
        dicom_dxdydz
        dicom_x
        dicom_y
        dicom_z
        dicom_xmesh
        dicom_ymesh
        dicom_zmesh
        ROC_trials
        US_info_table
        cum_decorr_lastframe
        cum_decorr_motcorr_lastframe
        post_abl_1day_segment_inROI
        segment_ROI_azimuth
        segment_ROI_range
        post_abl_1day_segment_ROI_flag = false
        post_abl_1day_tab1_inROI
        tab1_ROI_azimuth
        tab1_ROI_range
        post_abl_1day_tab1_ROI_flag = false
    end

    properties (Access = public, Constant = true)
        %         base_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/';
        %         folder_extension = ' radiology MW ablation and followup/';
        input_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/input/';
        procedure_photos_extension = 'photos_of_procedures/';
        US_decorr_extension = 'ultrasound + decorr/';
        postop_1day_extension = '1day_postop/postop_1day_';
        suiteCT_extension = 'suiteCT/suiteCT_';
        
        output_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/';
        ablation_mask3D_extension = 'ablation_masks/ablation_mask3D_';
        registered_US_decorr_extension = 'registered_US_decorr/registered_';
        registration_parameters_extension = 'registration_parameters';
        ROC_extension = 'ROC_curves/ROC_w_wo_motcorr_';


%         date = {'03182021_T1_LR',...
%             '03182021_T2_LR',...
%             '08242021_T1_HR',...
%             '08242021_T1_LR',...
%             '08242021_T2_HR',...
%             '08242021_T2_LR',...
%             '12032021_T1_HR',...
%             '12032021_T1_LR'}; % Date of ablation and trial number % Description
        
        input_US_decorr =  {'03182021_tumor1_LR_IQcart_decorr_xyz',...
                            '03182021_tumor2_LR_IQcart_decorr_xyz',...
                            '08242021_tumor3_HR_IQcart_xyz',...
                            '08242021_tumor3_LR_IQcart_decorr_xyz',...
                            '08242021_tumor4_HR_IQcart_xyz',...
                            '08242021_tumor4_LR_IQcart_decorr_xyz',...
                            '12032021_tumor5_HR_IQcart_xyz',...
                            '12032021_tumor5_LR_IQcart_decorr_xyz',...
                            '04222022_tumor6_HR_IQcart_xyz',...
                            '04222022_tumor6_LR_IQcart_decorr_xyz'};
        
        case_dates = {'03182021',...
                      '03182021',...
                      '08242021',...
                      '08242021',...
                      '12032021',...
                      '04222022'};

%         suite_CT_address = {'111',...
%             '111',...
%             'SE000003'};
% 
%         post_abl_1day_address = {'111',...
%             '111',...
%             'SE000015'};

        rotation_order    = {'x -> y -> z',...
                             'x -> z -> y',...
                             'y -> x -> z',...
                             'y -> z -> x',...
                             'z -> x -> y',...
                             'z -> y -> x'};
        
        default_IQfile_number  = [1, 1, 1, 1, 3, 1];
        postop_1day_needle_sl = [1, 1, 1, 1, 30, 21];
%         apex_loc
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DateDropDown
        function DateDropDownOpening(app, event)
            app.DateDropDown.Items = app.input_US_decorr;
%             value = app.DateDropDown.Value
%             app.case_path = [app.base_path,value(1:8),app.folder_extension];
%             app.trial_name = value;
%             app.trial_number = value(10:11);
%             app.case_number = value{18};
%             switch value(1:8)
%                 case app.case_dates{1}
%                     app.case_number = 1;
%                 case app.case_dates{2}
%                     app.case_number = 2;
%                 case app.case_dates{3}
%                     app.case_number = 3;
%                 case app.case_dates{4}
%                     app.case_number = 4;
%             end

        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            app.drop_down_val = app.DateDropDown.Value;
%             app.case_path = [app.base_path,value(1:8),app.folder_extension];
%             app.trial_name = value;
%             app.trial_number = value(10:11);
            app.tumor_no = str2double(app.drop_down_val(15));
%             switch value(1:8)
%                 case app.case_dates{1}
%                     app.case_number = 1;
%                 case app.case_dates{2}
%                     app.case_number = 2;
%                 case app.case_dates{3}
%                     app.case_number = 3;
%             end

            app.US_info_table = readtable([app.input_path,'ablation_information_for_app.xlsx']);

            table_params = app.US_info_table.Properties.VariableNames;
            
            app.UITable.Data = {table_params{2};table_params{3};...
                table_params{4};table_params{5}};

            app.table_vals = ...
                app.US_info_table{find(strcmp(app.US_info_table{:,1}, {app.drop_down_val})),2:6};
            
            app.UITable.Data(:,2) = {app.table_vals(1);...
                app.table_vals(2);app.table_vals(3);app.table_vals(4)};

            app.dicom_dxdydz = ...
                [app.US_info_table.dicom_dx(find(strcmp(app.US_info_table{:,1}, {app.drop_down_val }))),...
                app.US_info_table.dicom_dy((find(strcmp(app.US_info_table{:,1}, {app.drop_down_val })))),...
                app.US_info_table.dicom_dz((find(strcmp(app.US_info_table{:,1}, {app.drop_down_val }))))];

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            directory1 = [app.input_path, app.procedure_photos_extension, ...
                app.drop_down_val(1:15), '_', num2str(1), '.jpeg'];
            directory2 = [app.input_path, app.procedure_photos_extension, ...
                app.drop_down_val(1:15), '_', num2str(2), '.jpeg'];
%             app.photo_names = uigetfile([directory,'*.jpg'],...
%                 'MultiSelect','on','Select 2 photos from procedure');

            photo1 = imread(directory1);
            photo2 = imread(directory2);

            imshow(photo1,'Parent',app.UIAxes)
            axis(app.UIAxes,'equal','tight');
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');

            imshow(photo2,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'equal','tight');
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
%             app.US_path = [app.case_path,'ultrasound/'];
%             %             [rawData_cart, app.cum_decorr, app.cum_decorr_motion_corr, app.xyz_range] = ...
%             app.IQdata = process_US(app.trial_name, app.US_path, app.table_vals);
            directory = strcat(app.input_path, app.US_decorr_extension, app.drop_down_val, '.mat');
            app.US_imaging_xyz = load(directory);
            
            rawData_cart = app.US_imaging_xyz.rawData_cart(:,:,:,2,end);
            app.xyz_range{1} = app.US_imaging_xyz.x_range;
            app.xyz_range{2} = app.US_imaging_xyz.y_range;
            app.xyz_range{3} = app.US_imaging_xyz.z_range;
            
            app.Bmode = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
            app.Bmode = flip(app.Bmode,2);
            app.Bmode = flip(app.Bmode,3);

            elevation_sl = ceil(size(app.Bmode, 3) / 2);
            imagesc(app.UIAxes_3,app.Bmode(:, :, elevation_sl, app.default_IQfile_number(app.tumor_no)))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSlider.Limits = [1 size(app.Bmode,3)];
            app.SliceSlider.Value = 30;
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_3,app.Bmode(:,:,round(changingValue),app.default_IQfile_number(app.tumor_no)))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSliderLabel.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             address = [app.case_path,app.trial_number,'_suite CT/',app.suite_CT_address{app.case_number}];
%             app.suite_CT_vol = read_suite_CT(address)
            directory = strcat(app.input_path, app.suiteCT_extension, app.drop_down_val(1:15), '.jpg');
            suitCT_image = imread(directory);
            
%             imagesc(app.UIAxes_4,app.suite_CT_vol(:,:,30))
            imagesc(app.UIAxes_4, suitCT_image)
            axis(app.UIAxes_4,'equal','tight');
            colormap(app.UIAxes_4,'gray');
%             app.SliceSlider_6.Limits = [1 size(app.suite_CT_vol,3)];
%             app.SliceSlider_6.Value = 30;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
%             address = [app.case_path,app.trial_number,'_1day_post_ablation/',app.post_abl_1day_address{app.case_number}];
%             app.post_abl_1day_obj = read_1day_post_ablation(address);
%             app.post_abl_1day_vol = app.post_abl_1day_obj.vol;
%             app.post_abl_1day_vol_image = read_1day_post_ablation_image(address);
%             %             app.post_abl_1day_vol_gray = repmat(mat2gray(app.post_abl_1day_vol,[0 1]),[1,1,1,3]);
%             %             app.mask3D = zeros(size(app.post_abl_1day_vol));
%             directory = strcat(app.input_path, app.postop_1day_extension, app.drop_down_val(1:15), '.mat');
%             temp = load(directory);
%             app.post_abl_1day_vol  = temp.MRvol;
            
            directory = strcat(app.input_path, app.postop_1day_extension, app.drop_down_val(1:15), '_images');
            app.post_abl_1day_vol_image = read_1day_post_ablation_image(directory);
            app.post_abl_1day_vol = single(0.2989 * app.post_abl_1day_vol_image(:,:,:,1) + ...
                                           0.5870 * app.post_abl_1day_vol_image(:,:,:,2) + ...
                                           0.1140 * app.post_abl_1day_vol_image(:,:,:,3)); 
            
            app.dicom_x = (1:size(app.post_abl_1day_vol,2))*app.dicom_dxdydz(1);
            app.dicom_y = (1:size(app.post_abl_1day_vol,1))*app.dicom_dxdydz(2);
            app.dicom_z = (1:size(app.post_abl_1day_vol,3))*app.dicom_dxdydz(3);
            [app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh] = ...
                meshgrid(app.dicom_x, app.dicom_y, app.dicom_z);
            
            app.post_abl_1day_obj.vol = app.post_abl_1day_vol;
            app.post_abl_1day_obj.dxdydz = app.dicom_dxdydz;
            
            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,app.postop_1day_needle_sl(app.tumor_no)))
            axis(app.UIAxes_13,'equal','tight');
            colormap(app.UIAxes_13,'gray');
            app.SliceSlider_7.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_7.Value = app.postop_1day_needle_sl(app.tumor_no);

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, app.post_abl_1day_vol(:,:,app.postop_1day_needle_sl(app.tumor_no)));
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'gray');
            
            azimuth_sl = ceil(size(app.post_abl_1day_vol, 2) / 2);
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_y, squeeze(app.post_abl_1day_vol(:,azimuth_sl,:)));
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'gray');
            
            range_sl = ceil(size(app.post_abl_1day_vol, 1) / 2);
            imagesc(app.UIAxes_10,app.dicom_x, app.dicom_z, rot90(squeeze(app.post_abl_1day_vol(range_sl,:,:))));
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'gray');
            
            app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_10.Limits = [1 size(app.post_abl_1day_vol,2)];
            app.SliceSlider_11.Limits = [1 size(app.post_abl_1day_vol,1)];
            app.AlphaSlider_3.Limits = [0 1];
        end

        % Value changing function: SliceSlider_7
        function SliceSlider_7ValueChanging(app, event)
            app.slider7value = event.Value;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);
            
            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end
            
            if app.registered_US_flag
                imagesc(app.UIAxes_14,app.registered_US(range, azimuth, round(app.slider7value)))
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15,app.registered_US_LR(range, azimuth, round(app.overlay_slice)))
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:, :, 2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:, :, 2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:, :, 2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
%             [app.apex_loc(1), app.apex_loc(2)] = getpts(app.UIAxes_13);
%             app.apex_loc = round(app.apex_loc);
            app.apex_loc(1) = 23;
            app.apex_loc(2) = 236;
            app.apex_loc(3) = 21;
        end

        % Drop down opening function: OrderofrotationDropDown
        function OrderofrotationDropDownOpening(app, event)
            app.OrderofrotationDropDown.Items = app.rotation_order;
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value;
        end

        % Value changed function: OrderofrotationDropDown
        function OrderofrotationDropDownValueChanged(app, event)
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value;
        end

        % Value changed function: ApextoneedleinZmmEditField
        function ApextoneedleinZmmEditFieldValueChanged(app, event)
            app.apex_to_needle_dist = app.ApextoneedleinZmmEditField.Value;
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.rotation_angles(1) = app.YawEditField.Value;
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.rotation_angles(2) = app.PitchEditField.Value;
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.rotation_angles(3) = app.RollEditField.Value;
        end

        % Button pushed function: UpdateregistrationButton
        function UpdateregistrationButtonPushed(app, event)
            app.Lamp.Color = 'red';
            pause(0.5);
            app.EditField.Value = 'Registration in progress...';
            output_directory = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.drop_down_val(1:18));
            
            app.registered_US = register(app.post_abl_1day_obj, ...
                app.Bmode, app.xyz_range, app.apex_loc,...
                app.apex_to_needle_dist, app.rotation_order_choice,...
                app.rotation_angles, output_directory, 'Bmode');

            app.Lamp.Color = 'green';
            app.EditField.Value = 'Registration is now complete!';
            
            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end
            
            imagesc(app.UIAxes_14,app.registered_US(range, azimuth, round(app.overlay_slice)))
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');

            app.registered_US_flag = true;
            %             inside_echo = app.registered_US ~= NaN;
%             app.post_abl_1day_obj
%             app.temp_registered_US = app.registered_US;%(app.apex_loc(2), app.apex_loc(1));
        end

        % Value changed function: NeedletrackslicenumberEditField
        function NeedletrackslicenumberEditFieldValueChanged(app, event)
            value = app.NeedletrackslicenumberEditField.Value;
            app.apex_loc(3) = round(value);
        end

        % Button pushed function: OverlayultrasoundButton
        function OverlayultrasoundButtonPushed(app, event)
            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                
                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end
                
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                %
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end

            app.AlphaSlider.Limits = [0 1];%[min(app.registered_US(:)) max(app.registered_US(:))];

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                app.OverlayultrasoundButton.Text = 'Remove overlay';
                app.overlay_flag = true;
            else
                app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
                app.overlay_flag = false;
            end
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            if app.overlay_flag
                app.overlay_threshold = event.Value;
                
                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end
                
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            end
        end

        % Button pushed function: ApplytoLRUSButton
        function ApplytoLRUSButtonPushed(app, event)
            if strcmp(app.drop_down_val(17:18),'HR')
                temp = find(contains(app.input_US_decorr,app.drop_down_val)) + 1;
                directory = strcat(app.input_path, app.US_decorr_extension, app.input_US_decorr{temp}, '.mat');
                app.US_imaging_xyz = load(directory);

                rawData_cart = app.US_imaging_xyz.rawData_cart(:,:,:,2,end);
                app.xyz_range_LR{1} = app.US_imaging_xyz.x_range;
                app.xyz_range_LR{2} = app.US_imaging_xyz.y_range;
                app.xyz_range_LR{3} = app.US_imaging_xyz.z_range;
                
                app.Bmode_LR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
                app.Bmode_LR = flip(app.Bmode_LR,2);
                app.Bmode_LR = flip(app.Bmode_LR,3);
                
                app.cum_decorr_lastframe = app.US_imaging_xyz.cum_decorr(:,:,:,end);
                app.cum_decorr_motcorr_lastframe = app.US_imaging_xyz.cum_decorr_motcorr(:,:,:,end);
                
%                 app.table_vals_LR = ...
%                     app.US_info_table{find(strcmp(app.US_info_table{:,1}, {app.trial_name_LR})),2:6};

                %             app.IQdata_LR = process_US(app.trial_name_LR, app.US_path, app.table_vals_LR);
                %
                %             rawData_cart = app.IQdata_LR{1};
                %             app.xyz_range_LR = app.IQdata_LR{2};
                %
                
                
                app.Lamp.Color = 'red';
                pause(0.5);
                app.EditField.Value = 'Registration in progress...';
                output_directory = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.input_US_decorr{temp}(1:18));
                
                app.registered_US_LR = register(app.post_abl_1day_obj, ...
                    app.Bmode_LR, app.xyz_range_LR, app.apex_loc,...
                    app.apex_to_needle_dist,app.rotation_order_choice,...
                    app.rotation_angles, output_directory,  'Bmode');

%                 app.registered_US_LR = load('/Users/elmiraghahramani/Desktop/registUS_LR.mat');
%                 app.registered_US_LR = app.registered_US_LR.registered_US;
                app.Lamp.Color = 'green';
                app.EditField.Value = 'Registration is now complete!';

                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end
                
                vol = app.registered_US_LR(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_15, vol)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');

                %             imagesc(app.UIAxes_12,app.registered_US_LR(:,:,round(app.overlay_slice)))
                %             axis(app.UIAxes_12,'equal','tight');
                %             colormap(app.UIAxes_12,'gray');

                app.registered_US_LR_flag = true;
                app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol, 3)];
                app.SliceSlider_10.Limits = [1 size(app.post_abl_1day_vol, 2)];
                app.SliceSlider_11.Limits = [1 size(app.post_abl_1day_vol, 1)];
                app.AlphaSlider_3.Limits = [0 1];
            else
                msgbox("Registration already applied to low-resolution B-mode data.","Error","error");
            end
        end

        % Button pushed function: ApplyregistrationButton
        function ApplyregistrationButtonPushed(app, event)
            app.Lamp_2.Color = 'red';
            pause(0.5);
            temp = find(contains(app.input_US_decorr,app.drop_down_val)) + 1;
            output_directory = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.input_US_decorr{temp}(1:18));

            app.registered_decorr_unscaled = register(app.post_abl_1day_obj, ...
                app.cum_decorr_lastframe, app.xyz_range_LR, app.apex_loc,...
                app.apex_to_needle_dist,app.rotation_order_choice,...
                app.rotation_angles, output_directory, 'decorr');

            app.registered_motion_corr_decorr_unscaled = register(app.post_abl_1day_obj, ...
                app.cum_decorr_motcorr_lastframe, app.xyz_range_LR, app.apex_loc,...
                app.apex_to_needle_dist,app.rotation_order_choice,...
                app.rotation_angles, output_directory, 'mot corr decorr');

            app.registered_decorr_scaled = app.registered_decorr_unscaled/app.max_decorr;
            app.registered_decorr_scaled(find(app.registered_decorr_scaled<=0)) = 1.e-16;
            app.registered_decorr_scaled = (log10(app.registered_decorr_scaled)+app.dyn_range)/app.dyn_range; %DR MAST
            app.registered_decorr_scaled(find(app.registered_decorr_scaled>1)) = 1;
            app.registered_decorr_scaled(find(app.registered_decorr_scaled<0)) = 0;
            app.registered_decorr_scaled(isnan(app.registered_decorr_scaled)) = 0;

            app.registered_motion_corr_decorr_scaled = app.registered_motion_corr_decorr_unscaled/app.max_decorr;
            app.registered_motion_corr_decorr_scaled(find(app.registered_motion_corr_decorr_scaled<=0)) = 1.e-16;
            app.registered_motion_corr_decorr_scaled = (log10(app.registered_motion_corr_decorr_scaled)+app.dyn_range)/app.dyn_range; %DR MAST
            app.registered_motion_corr_decorr_scaled(find(app.registered_motion_corr_decorr_scaled>1)) = 1;
            app.registered_motion_corr_decorr_scaled(find(app.registered_motion_corr_decorr_scaled<0)) = 0;
            app.registered_motion_corr_decorr_scaled(isnan(app.registered_motion_corr_decorr_scaled)) = 0;

            %             imagesc(app.UIAxes_9,...
            %                 app.registered_decorr(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_9,'equal','tight');
            %             colormap(app.UIAxes_9,'hot');
            %
            %             imagesc(app.UIAxes_10,...
            %                 app.registered_motion_corr_decorr(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_10,'equal','tight');
            %             colormap(app.UIAxes_10,'hot');
            %
            %             imagesc(app.UIAxes_11,app.post_abl_1day_vol(:,:,round(app.slider8value)));
            %             axis(app.UIAxes_11,'image');
            %             colormap(app.UIAxes_11,'gray');

            app.Lamp_2.Color = 'green';
            app.decorrs_flag = true;
        end

        % Value changing function: SliceSlider_8
        function SliceSlider_8ValueChanging(app, event)
            app.slider8value = event.Value;
            app.SliceSlider_8Label.Text = num2str(round(app.slider8value));
            
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_decorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_motcorrdecorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            end
%             cum_decorrelation = cum_decorrelation/app.max_decorr;
%             cum_decorrelation(find(cum_decorrelation<=0)) = 1.e-16;
%             cum_decorrelation = (log10(cum_decorrelation)+app.dyn_range)/app.dyn_range; %DR MAST
%             cum_decorrelation(find(cum_decorrelation>1)) = 1;
%             cum_decorrelation(find(cum_decorrelation<0)) = 0;
%             cum_decorrelation(isnan(cum_decorrelation)) = 0;
%             
%             alphaMap = cum_decorrelation;%-threshold;
%             alphaMap(alphaMap<0.08) = 0;
%             alphaMap(alphaMap>1) = 1;
%             
%             mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider8value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider8value))),'XY',app.dicom_x,app.dicom_y, app.UIAxes_12);
%             
%             mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider10value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider10value))),'XY',app.dicom_z,app.dicom_y, app.UIAxes_9);
%             
%             mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider11value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider11value))),'XY',app.dicom_x,app.dicom_z, app.UIAxes_10);
            
            
%             if strcmp(app.display_decorr,'Without motion correction')
%                 cum_decorrelation = app.registered_decorr_scaled;
%             else
%                 cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%             end
% 
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');
% 
%             %             imagesc(app.UIAxes_12,app.post_abl_1day_vol(:,:,round(app.slider8value)));
%             %             axis(app.UIAxes_12,'image');
%             %             colormap(app.UIAxes_12,'gray');
%             %
%             %             if app.decorrs_flag
%             %                 imagesc(app.UIAxes_9,...
%             %                     app.registered_decorr(:,:,round(app.slider8value)));
%             %                 axis(app.UIAxes_9,'equal','tight');
%             %                 colormap(app.UIAxes_9,'hot');
%             %
%             %                 imagesc(app.UIAxes_10,...
%             %                     app.registered_motion_corr_decorr(:,:,round(app.slider8value)));
%             %                 axis(app.UIAxes_10,'equal','tight');
%             %                 colormap(app.UIAxes_10,'hot');
%             %             end
%             %
%             %             imagesc(app.UIAxes_11,app.post_abl_1day_vol(:,:,round(app.slider8value)));
%             %             axis(app.UIAxes_11,'image');
%             %             colormap(app.UIAxes_11,'gray');
        end

        % Button pushed function: DisplayMRIButton
        function DisplayMRIButtonPushed(app, event)
            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.slider8value)))
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');
            
            app.SliceSpinner.Limits = [1 size(app.post_abl_1day_vol,3)];
%             app.mask3D = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/ablation_mask3D.mat');
%             app.mask3D = app.mask3D.ablation_mask3D;
        end

        % Value changed function: AblationfirstsliceEditField
        function AblationfirstsliceEditFieldValueChanged(app, event)
            app.lesion_slice(1) = app.AblationfirstsliceEditField.Value;
            
            app.SliceSpinner.Limits = [app.lesion_slice(1) app.SliceSpinner.Limits(2)];
            app.SliceSpinner.Value = app.lesion_slice(1);
            
            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.lesion_slice(1))))
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');
        end

        % Value changed function: AblationlastsliceEditField
        function AblationlastsliceEditFieldValueChanged(app, event)
            app.lesion_slice(2) = app.AblationlastsliceEditField.Value;
            
            app.SliceSpinner.Limits = [app.SliceSpinner.Limits(1) app.lesion_slice(2)];
        end

        % Button pushed function: EditpolygonButton
        function EditpolygonButtonPushed(app, event)
            roiPoly = drawpolygon(app.UIAxes_16);
            app.mask = createMask(roiPoly);
%             mask2D = app.mask;

            imagesc(app.UIAxes_18,app.mask);
            axis(app.UIAxes_18,'image');
            colormap(app.UIAxes_18,'gray');

            app.mask_number = app.SliceSpinner.Value - app.lesion_slice(1) + 1;
%             save(['/Users/elmiraghahramani/Desktop/temp/seg',num2str(app.mask_number),'.mat'],'mask2D');
            app.mask3D(:,:,app.mask_number) = app.mask;
%             clear mask2D
            pause(1);

            imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)+1));
            axis(app.UIAxes_16,'image');
            colormap(app.UIAxes_16,'gray');
            if app.SliceSpinner.Value < app.SliceSpinner.Limits (2)
                app.SliceSpinner.Value = app.SliceSpinner.Value + 1;
            end
        end

        % Button pushed function: FinishsegmentationButton
        function FinishsegmentationButtonPushed(app, event)
            %             save(['/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/Segmented 1day postop/seg',num2str(app.mask_number),'.mat'],'app.mask');
            %             app.mask3D = flip(app.mask3D,3);
            %             save('/Users/elmiraghahramani/Desktop/temp','app.mask3D');
            ablation_mask3D = zeros(size(app.post_abl_1day_vol));
            
            if app.post_abl_1day_segment_ROI_flag
                ablation_mask3D(app.segment_ROI_range, app.segment_ROI_azimuth, app.AblationfirstsliceEditField.Value:app.AblationlastsliceEditField.Value) = app.mask3D;
            else
                ablation_mask3D(:, :, app.AblationfirstsliceEditField.Value:app.AblationlastsliceEditField.Value) = app.mask3D;
            end
            app.mask3D = ablation_mask3D;
            
            quest = 'The 3D mask already exists. Do you want to overwrite?';
            dlgtitle = 'Overwriting warning';
            btn1 = 'No';
            btn2 = 'Yes';
            defbtn = 'No';
            output_directory = strcat(app.output_path, ...
                app.ablation_mask3D_extension, app.drop_down_val(1:15), '.mat');

            if exist(output_directory, 'file')
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                if strcmp(answer, 'Yes')
                    save(output_directory,'ablation_mask3D');
                end
            else
                save(output_directory,'ablation_mask3D');
            end
            
            app.post_abl_1day_segment_ROI_flag = false;
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            if app.post_abl_1day_segment_ROI_flag

                imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)));
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');

            else

                imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.SliceSpinner.Value)));
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');

            end
        end

        % Button pushed function: DreconstructlesionButton
        function DreconstructlesionButtonPushed(app, event)
            cla(app.UIAxes_18);
            output_directory = strcat(app.output_path, ...
                app.ablation_mask3D_extension, app.drop_down_val(1:15), '.mat');

            if exist(output_directory, 'file')
                disp('Hey')
                temp = load(output_directory);
                app.mask3D = temp.ablation_mask3D;
            end
            app.mask3D_gauss = imgaussfilt3(app.mask3D,2);

            %             cla(app.UIAxes_16);
            p = patch(app.UIAxes_18,isosurface(app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, app.mask3D_gauss,.2));
            p.FaceColor = 'red';
            p.EdgeColor = 'none';
            app.UIAxes_18.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_18, 3);
            axis(app.UIAxes_18,'tight');
            camlight(app.UIAxes_18);
            lighting gouraud
            app.UIAxes_18.XLim = [0 max(app.dicom_xmesh(:))];
            app.UIAxes_18.YLim = [0 max(app.dicom_ymesh(:))];
            app.UIAxes_18.ZLim = [0 max(app.dicom_zmesh(:))];
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            %             app.UIAxes_16.XTick = [];
            %             app.UIAxes_16.YTick = [];
        end

        % Button pushed function: DisplayROCcurvesButton
        function DisplayROCcurvesButtonPushed(app, event)
            if strcmp(app.ROC_trials,'All trials')
                app.ROC_trials = 'all';%app.Date;
            else
                app.ROC_trials = app.drop_down_val(1:15);%app.trial_name;
            end
            input_directory_regist_decorrs = strcat(app.output_path, ...
                app.registered_US_decorr_extension(1:20));
            input_directory_ablation_masks = strcat(app.output_path, ...
                app.ablation_mask3D_extension(1:14));
            output_directory = strcat(app.output_path, ...
                app.ROC_extension, app.ROC_trials);
            
            [output_fptpAUC, output_motcorr_fptpAUC] = ...
                ROC_analysis(app.registered_decorr_unscaled, ...
                app.registered_motion_corr_decorr_unscaled, ...
                app.mask3D, app.ROC_trials, input_directory_regist_decorrs, ...
                input_directory_ablation_masks, output_directory);

            not_corr_fprate_ablatedliverAll = output_fptpAUC(:,1);
            not_corr_tprate_ablatedliverAll = output_fptpAUC(:,2);
            not_corr_ROCarea_ablatedliverAll  = output_fptpAUC(1,3);

            mot_corr_fprate_ablatedliverAll = output_motcorr_fptpAUC(:,1);
            mot_corr_tprate_ablatedliverAll = output_motcorr_fptpAUC(:,2);
            mot_corr_ROCarea_ablatedliverAll  = output_motcorr_fptpAUC(1,3);

            plot(app.UIAxes_17,not_corr_fprate_ablatedliverAll,not_corr_tprate_ablatedliverAll,'-r',...
                mot_corr_fprate_ablatedliverAll,mot_corr_tprate_ablatedliverAll,'-b',...
                [0 1],[0 1],'--k','LineWidth',1);
            app.UIAxes_17.XLim = [-.001 1.001];
            app.UIAxes_17.YLim = [-.001 1.001];
            axis(app.UIAxes_17,'equal','tight');
            legend(app.UIAxes_17,['Cum Dec, AUROC=',num2str(round(not_corr_ROCarea_ablatedliverAll,3))],...
                ['Mot Corr Cum Dec, AUROC=',num2str(round(mot_corr_ROCarea_ablatedliverAll,3))],...
                'Location','SouthEast')
            grid(app.UIAxes_17,'on');

            app.AUCEditField.Value = round(not_corr_ROCarea_ablatedliverAll,3);
            app.MotioncorrectedAUCEditField.Value = round(mot_corr_ROCarea_ablatedliverAll,3);

            %             saveas(app.UIAxes_17,'ROC_total_w_wo_motion_corr_rhoScalFact2.png');
            %             saveas(app.UIAxes_17,'ROC_total_w_wo_motion_corr_rhoScalFact2.fig');
        end

        % Value changing function: SliceSlider_10
        function SliceSlider_10ValueChanging(app, event)
            app.slider10value = event.Value;
            app.SliceSlider_10Label.Text = num2str(round(app.slider10value));
            
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_decorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_motcorrdecorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            end
%             cum_decorrelation = cum_decorrelation/app.max_decorr;
%             cum_decorrelation(find(cum_decorrelation<=0)) = 1.e-16;
%             cum_decorrelation = (log10(cum_decorrelation)+app.dyn_range)/app.dyn_range; %DR MAST
%             cum_decorrelation(find(cum_decorrelation>1)) = 1;
%             cum_decorrelation(find(cum_decorrelation<0)) = 0;
%             cum_decorrelation(isnan(cum_decorrelation)) = 0;
%             
%             alphaMap = cum_decorrelation;%-threshold;
%             alphaMap(alphaMap<0.08) = 0;
%             alphaMap(alphaMap>1) = 1;
%             
%             mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider8value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider8value))),'XY',app.dicom_x,app.dicom_y, app.UIAxes_12);
%             
%             mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider10value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider10value))),'XY',app.dicom_z,app.dicom_y, app.UIAxes_9);
%             
%             mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider11value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider11value))),'XY',app.dicom_x,app.dicom_z, app.UIAxes_10);
            

%             if strcmp(app.display_decorr,'Without motion correction')
%                 cum_decorrelation = app.registered_decorr_scaled;
%             else
%                 cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%             end

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');
        end

        % Value changing function: SliceSlider_11
        function SliceSlider_11ValueChanging(app, event)
            app.slider11value = event.Value;
            app.SliceSlider_11Label.Text = num2str(round(app.slider11value));
            
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_decorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_motcorrdecorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            end
%             cum_decorrelation = cum_decorrelation/app.max_decorr;
%             cum_decorrelation(find(cum_decorrelation<=0)) = 1.e-16;
%             cum_decorrelation = (log10(cum_decorrelation)+app.dyn_range)/app.dyn_range; %DR MAST
%             cum_decorrelation(find(cum_decorrelation>1)) = 1;
%             cum_decorrelation(find(cum_decorrelation<0)) = 0;
%             cum_decorrelation(isnan(cum_decorrelation)) = 0;
%             
%             alphaMap = cum_decorrelation;%-threshold;
%             alphaMap(alphaMap<0.08) = 0;
%             alphaMap(alphaMap>1) = 1;
%             
%             mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider8value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider8value))),'XY',app.dicom_x,app.dicom_y, app.UIAxes_12);
%             
%             mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider10value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider10value))),'XY',app.dicom_z,app.dicom_y, app.UIAxes_9);
%             
%             mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider11value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider11value))),'XY',app.dicom_x,app.dicom_z, app.UIAxes_10);
            
            
%             if strcmp(app.display_decorr,'Without motion correction')
%                 cum_decorrelation = app.registered_decorr_scaled;
%             else
%                 cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%             end
% 
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10,app.dicom_x, app.dicom_z, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10,app.dicom_x, app.dicom_z, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Value changed function: DisplaydecorrelationDropDown
        function DisplaydecorrelationDropDownValueChanged(app, event)
            app.display_decorr = app.DisplaydecorrelationDropDown.Value

            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_decorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
%                 temp = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/registered_US_decorr/registered_04222022_tumor6_LR_IQcart_decorr_xyz_motcorrdecorr.mat');
%                 cum_decorrelation = temp.registered_vol;
            end
%             cum_decorrelation = cum_decorrelation/app.max_decorr;
%             cum_decorrelation(find(cum_decorrelation<=0)) = 1.e-16;
%             cum_decorrelation = (log10(cum_decorrelation)+app.dyn_range)/app.dyn_range; %DR MAST
%             cum_decorrelation(find(cum_decorrelation>1)) = 1;
%             cum_decorrelation(find(cum_decorrelation<0)) = 0;
%             cum_decorrelation(isnan(cum_decorrelation)) = 0;
            
            alphaMap = cum_decorrelation;%-threshold;
            alphaMap(alphaMap<0.08) = 0;
            alphaMap(alphaMap>1) = 1;
            
%             mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider8value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider8value))),'XY',app.dicom_x,app.dicom_y, app.UIAxes_12);
%             
%             mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider10value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider10value))),'XY',app.dicom_z,app.dicom_y, app.UIAxes_9);
%             
%             mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:)));
%             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider11value))),...
%                 squeeze(mapdecorr),[0 1],[],...
%                 'hot',squeeze(alphaMap(:,:,round(app.slider11value))),'XY',app.dicom_x,app.dicom_z, app.UIAxes_10);
            
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');

            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Value changing function: AlphaSlider_3
        function AlphaSlider_3ValueChanging(app, event)
            app.alpha3value = event.Value;
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');

            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10,app.dicom_x, app.dicom_z, mapdecorr,'AlphaData',app.alpha3value.*mapdecorr);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10,app.dicom_x, app.dicom_z, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Button pushed function: Update3DfigureButton
        function Update3DfigureButtonPushed(app, event)
            if strcmp(app.display_decorr,'Without motion correction')
                cum_decorrelation = app.registered_decorr_scaled;
            else
                cum_decorrelation = app.registered_motion_corr_decorr_scaled;
            end

            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, app.post_abl_1day_vol,round(app.slider10value)*app.dicom_dxdydz(1),0,round(app.slider8value)*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5), colormap(app.UIAxes_11,'gray'),
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');

            cum_decorr_gauss = imgaussfilt3(cum_decorrelation,2);
            p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, imgaussfilt3(cum_decorr_gauss,.2)));%squeeze(hybrid(predictor,:,:,:,2)),threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
            %             app.UIAxes_11.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
%                         app.UIAxes_11.XLim = [0 size(cum_decorr_gauss,1)];
%                         app.UIAxes_11.YLim = [0 size(cum_decorr_gauss,2)];
%                         app.UIAxes_11.ZLim = [0 size(cum_decorr_gauss,3)];
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
        end

        % Drop down opening function: TrialsDropDown
        function TrialsDropDownOpening(app, event)
            %             app.TrialsDropDown.Items = app.date;
            app.ROC_trials = app.TrialsDropDown.Value;
        end

        % Value changed function: TrialsDropDown
        function TrialsDropDownValueChanged(app, event)
            app.ROC_trials = app.TrialsDropDown.Value;
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            output_directory = strcat(app.output_path, ...
                app.ROC_extension, app.drop_down_val(1:15), '_rhoScalFact2.fig');
            saveas(app.UIAxes_17, output_directory);
        end

        % Button pushed function: DrawrectangularROIButton
        function DrawrectangularROIButtonPushed(app, event)
            if ~app.post_abl_1day_segment_ROI_flag
                app.post_abl_1day_segment_ROI_flag = true;
                roiRect = drawrectangle(app.UIAxes_16);
                post_abl_1day_segment_ROI_pos = round(roiRect.Position);
                app.segment_ROI_azimuth = post_abl_1day_segment_ROI_pos(1) : post_abl_1day_segment_ROI_pos(1) + post_abl_1day_segment_ROI_pos(3);
                app.segment_ROI_range = post_abl_1day_segment_ROI_pos(2) : post_abl_1day_segment_ROI_pos(2) + post_abl_1day_segment_ROI_pos(4);
                app.post_abl_1day_segment_inROI = ...
                    app.post_abl_1day_vol(app.segment_ROI_range, app.segment_ROI_azimuth, :);

                imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)));
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');
            end
        end

        % Button pushed function: DrawrectangularROIButton_2
        function DrawrectangularROIButton_2Pushed(app, event)
            if ~app.post_abl_1day_tab1_ROI_flag
                roiRect = drawrectangle(app.UIAxes_13);
                post_abl_1day_tab1_ROI_pos = round(roiRect.Position);
                app.tab1_ROI_azimuth = post_abl_1day_tab1_ROI_pos(1) : post_abl_1day_tab1_ROI_pos(1) + post_abl_1day_tab1_ROI_pos(3);
                app.tab1_ROI_range = post_abl_1day_tab1_ROI_pos(2) : post_abl_1day_tab1_ROI_pos(2) + post_abl_1day_tab1_ROI_pos(4);
                app.post_abl_1day_tab1_inROI = ...
                    app.post_abl_1day_vol(app.tab1_ROI_range, app.tab1_ROI_azimuth, :);
            end
            
            if strcmp(app.DrawrectangularROIButton_2.Text,'Draw rectangular ROI')
                app.DrawrectangularROIButton_2.Text = 'Original size images';
                app.post_abl_1day_tab1_ROI_flag = true;
            else
                app.DrawrectangularROIButton_2.Text = 'Draw rectangular ROI';
                app.post_abl_1day_tab1_ROI_flag = false;
            end
            
            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end
            
            if app.registered_US_flag
                imagesc(app.UIAxes_14,app.registered_US(range, azimuth, round(app.slider7value)))
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15,app.registered_US_LR(range, azimuth, round(app.overlay_slice)))
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:, :, 2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:, :, 2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:, :, 2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.UIAxes = uiaxes(app.RegistrationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RegistrationTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RegistrationTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RegistrationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.Image = uiimage(app.RegistrationTab);
            app.Image.ImageSource = 'axes_photo.jpeg';
            app.UITable = uitable(app.RegistrationTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.SliceSliderLabel = uilabel(app.RegistrationTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.RegistrationTab);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.DateDropDownLabel = uilabel(app.RegistrationTab);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.RegistrationTab);
            app.DateDropDown.Items = {};
            app.DateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DateDropDownOpening, true);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.Button = uibutton(app.RegistrationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'CT.png';
            app.Button.Text = '';
            app.RegistrationPanel = uipanel(app.RegistrationTab);
            app.RegistrationPanel.Title = 'Registration';
            app.UIAxes_13 = uiaxes(app.RegistrationPanel);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.RegistrationPanel);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.RegistrationPanel);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.YawEditFieldLabel = uilabel(app.RegistrationPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.Button_2 = uibutton(app.RegistrationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'apex.png';
            app.Button_2.Text = '';
            app.OrderofrotationDropDownLabel = uilabel(app.RegistrationPanel);
            app.OrderofrotationDropDownLabel.Text = 'Order of rotation';
            app.OrderofrotationDropDown = uidropdown(app.RegistrationPanel);
            app.OrderofrotationDropDown.Items = {};
            app.OrderofrotationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OrderofrotationDropDownOpening, true);
            app.OrderofrotationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderofrotationDropDownValueChanged, true);
            app.OrderofrotationDropDown.Value = {};
            app.PitchEditFieldLabel = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditFieldLabel_2 = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel_2.Text = 'Roll';
            app.RollEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.EchoapexonMRLabel = uilabel(app.RegistrationPanel);
            app.EchoapexonMRLabel.Text = 'Echo apex on MR';
            app.UpdateregistrationButton = uibutton(app.RegistrationPanel, 'push');
            app.UpdateregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateregistrationButtonPushed, true);
            app.UpdateregistrationButton.Text = 'Update registration';
            app.Lamp = uilamp(app.RegistrationPanel);
            app.EditField = uieditfield(app.RegistrationPanel, 'text');
            app.SliceSlider_7Label = uilabel(app.RegistrationPanel);
            app.SliceSlider_7Label.Text = 'Slice';
            app.SliceSlider_7 = uislider(app.RegistrationPanel);
            app.SliceSlider_7.MajorTicks = [];
            app.SliceSlider_7.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_7ValueChanging, true);
            app.SliceSlider_7.MinorTicks = [];
            app.OverlayultrasoundButton = uibutton(app.RegistrationPanel, 'push');
            app.OverlayultrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayultrasoundButtonPushed, true);
            app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
            app.AlphaSliderLabel = uilabel(app.RegistrationPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.RegistrationPanel);
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.MinorTicks = [];
            app.ApextoneedleinZmmEditFieldLabel = uilabel(app.RegistrationPanel);
            app.ApextoneedleinZmmEditFieldLabel.Text = 'Apex to needle in Z (mm)';
            app.ApextoneedleinZmmEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.ApextoneedleinZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ApextoneedleinZmmEditFieldValueChanged, true);
            app.RotationanglesdegLabel = uilabel(app.RegistrationPanel);
            app.RotationanglesdegLabel.Text = 'Rotation angles (deg):';
            app.ApplytoLRUSButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytoLRUSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoLRUSButtonPushed, true);
            app.ApplytoLRUSButton.Text = 'Apply to LR US';
            app.NeedletrackslicenumberEditFieldLabel = uilabel(app.RegistrationPanel);
            app.NeedletrackslicenumberEditFieldLabel.Text = 'Needle track slice number';
            app.NeedletrackslicenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.NeedletrackslicenumberEditField.ValueChangedFcn = createCallbackFcn(app, @NeedletrackslicenumberEditFieldValueChanged, true);
            app.DrawrectangularROIButton_2 = uibutton(app.RegistrationPanel, 'push');
            app.DrawrectangularROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangularROIButton_2Pushed, true);
            app.DrawrectangularROIButton_2.Text = 'Draw rectangular ROI';
            app.Button_3 = uibutton(app.RegistrationTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'US.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.RegistrationTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'photo.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.RegistrationTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'mri.png';
            app.Button_5.Text = '';
            app.SegmentationandROCTab = uitab(app.TabGroup);
            app.SegmentationandROCTab.Title = 'Segmentation and ROC';
            app.UIAxes_9 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_9, 'Z (mm)')
            ylabel(app.UIAxes_9, 'Y (mm)')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_10, 'X (mm)')
            ylabel(app.UIAxes_10, 'Z (mm)')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.SegmentationandROCTab);
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_12, 'X (mm)')
            ylabel(app.UIAxes_12, 'Y (mm)')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ApplyregistrationButton = uibutton(app.SegmentationandROCTab, 'push');
            app.ApplyregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyregistrationButtonPushed, true);
            app.ApplyregistrationButton.Text = 'Apply registration';
            app.SegmentationPanel = uipanel(app.SegmentationandROCTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UIAxes_16 = uiaxes(app.SegmentationPanel);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_18 = uiaxes(app.SegmentationPanel);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.DisplayMRIButton = uibutton(app.SegmentationPanel, 'push');
            app.DisplayMRIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMRIButtonPushed, true);
            app.DisplayMRIButton.Text = 'Display MRI';
            app.AblationfirstsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationfirstsliceEditFieldLabel.Text = 'Ablation first slice';
            app.AblationfirstsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationfirstsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationfirstsliceEditFieldValueChanged, true);
            app.AblationlastsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationlastsliceEditFieldLabel.Text = 'Ablation last slice';
            app.AblationlastsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationlastsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationlastsliceEditFieldValueChanged, true);
            app.DreconstructlesionButton = uibutton(app.SegmentationPanel, 'push');
            app.DreconstructlesionButton.ButtonPushedFcn = createCallbackFcn(app, @DreconstructlesionButtonPushed, true);
            app.DreconstructlesionButton.Text = '3D reconstruct lesion';
            app.EditpolygonButton = uibutton(app.SegmentationPanel, 'push');
            app.EditpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @EditpolygonButtonPushed, true);
            app.EditpolygonButton.Text = 'Edit polygon';
            app.FinishsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.FinishsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @FinishsegmentationButtonPushed, true);
            app.FinishsegmentationButton.Text = 'Finish segmentation';
            app.SliceSpinnerLabel = uilabel(app.SegmentationPanel);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.SegmentationPanel);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.DrawrectangularROIButton = uibutton(app.SegmentationPanel, 'push');
            app.DrawrectangularROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangularROIButtonPushed, true);
            app.DrawrectangularROIButton.Text = 'Draw rectangular ROI';
            app.ROCcurveanalysisPanel = uipanel(app.SegmentationandROCTab);
            app.ROCcurveanalysisPanel.Title = 'ROC curve analysis';
            app.UIAxes_17 = uiaxes(app.ROCcurveanalysisPanel);
            xlabel(app.UIAxes_17, '1-Specificity')
            ylabel(app.UIAxes_17, 'Sensitivity')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.AUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUCEditFieldLabel.Text = 'AUC';
            app.AUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUCEditField.ValueChangedFcn = createCallbackFcn(app, @AUC2EditFieldValueChanged, true);
            app.MotioncorrectedAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.MotioncorrectedAUCEditFieldLabel.Text = 'Motion corrected AUC';
            app.MotioncorrectedAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.DisplayROCcurvesButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.DisplayROCcurvesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROCcurvesButtonPushed, true);
            app.DisplayROCcurvesButton.Text = 'Display ROC curves';
            app.TrialsDropDownLabel = uilabel(app.ROCcurveanalysisPanel);
            app.TrialsDropDownLabel.Text = 'Trials';
            app.TrialsDropDown = uidropdown(app.ROCcurveanalysisPanel);
            app.TrialsDropDown.Items = {'Current trial', 'All trials'};
            app.TrialsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrialsDropDownOpening, true);
            app.TrialsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialsDropDownValueChanged, true);
            app.TrialsDropDown.Value = 'Current trial';
            app.SavefigureButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.SliceSlider_8Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_8Label.Text = 'Slice';
            app.SliceSlider_8 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_8.MajorTicks = [];
            app.SliceSlider_8.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_8ValueChanging, true);
            app.SliceSlider_8.MinorTicks = [];
            app.Lamp_2 = uilamp(app.SegmentationandROCTab);
            app.SliceSlider_10Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_10Label.Text = 'Slice';
            app.SliceSlider_10 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_10.MajorTicks = [];
            app.SliceSlider_10.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_10ValueChanging, true);
            app.SliceSlider_10.MinorTicks = [];
            app.SliceSlider_11Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_11Label.Text = 'Slice';
            app.SliceSlider_11 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_11.MajorTicks = [];
            app.SliceSlider_11.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_11ValueChanging, true);
            app.SliceSlider_11.MinorTicks = [];
            app.DisplaydecorrelationDropDownLabel = uilabel(app.SegmentationandROCTab);
            app.DisplaydecorrelationDropDownLabel.Text = 'Display decorrelation';
            app.DisplaydecorrelationDropDown = uidropdown(app.SegmentationandROCTab);
            app.DisplaydecorrelationDropDown.Items = {'Without motion correction', 'With motion correction'};
            app.DisplaydecorrelationDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydecorrelationDropDownValueChanged, true);
            app.DisplaydecorrelationDropDown.Value = 'Without motion correction';
            app.AlphaSlider_3Label = uilabel(app.SegmentationandROCTab);
            app.AlphaSlider_3Label.Text = 'Alpha';
            app.AlphaSlider_3 = uislider(app.SegmentationandROCTab);
            app.AlphaSlider_3.MajorTicks = [];
            app.AlphaSlider_3.ValueChangingFcn = createCallbackFcn(app, @AlphaSlider_3ValueChanging, true);
            app.AlphaSlider_3.MinorTicks = [];
            app.Update3DfigureButton = uibutton(app.SegmentationandROCTab, 'push');
            app.Update3DfigureButton.ButtonPushedFcn = createCallbackFcn(app, @Update3DfigureButtonPushed, true);
            app.Update3DfigureButton.Text = 'Update 3D figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radiology_ablation_data_processing_v8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end