classdef radiology_ablation_data_processing_v9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RegistrationTab               matlab.ui.container.Tab
        ZoomSlider_2                  matlab.ui.control.Slider
        ZoomSlider_2Label             matlab.ui.control.Label
        ZoomSlider                    matlab.ui.control.Slider
        ZoomSliderLabel               matlab.ui.control.Label
        IQdataSlider                  matlab.ui.control.Slider
        IQdataSliderLabel             matlab.ui.control.Label
        Button_5                      matlab.ui.control.Button
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        RegistrationPanel             matlab.ui.container.Panel
        UITable2                      matlab.ui.control.Table
        LowresolutionUSIQfilenumberEditField  matlab.ui.control.NumericEditField
        LowresolutionUSIQfilenumberLabel  matlab.ui.control.Label
        DrawrectangularROIButton_2    matlab.ui.control.Button
        NeedletrackslicenumberEditField  matlab.ui.control.NumericEditField
        NeedletrackslicenumberEditFieldLabel  matlab.ui.control.Label
        ApplytoLRUSButton             matlab.ui.control.Button
        RotationanglesdegLabel        matlab.ui.control.Label
        ApextoneedleinZmmEditField    matlab.ui.control.NumericEditField
        ApextoneedleinZmmEditFieldLabel  matlab.ui.control.Label
        AlphaSlider                   matlab.ui.control.Slider
        AlphaSliderLabel              matlab.ui.control.Label
        OverlayultrasoundButton       matlab.ui.control.Button
        SliceSlider_7                 matlab.ui.control.Slider
        SliceSlider_7Label            matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        Lamp                          matlab.ui.control.Lamp
        UpdateregistrationButton      matlab.ui.control.Button
        EchoapexonMRLabel             matlab.ui.control.Label
        RollEditField                 matlab.ui.control.NumericEditField
        PitchEditFieldLabel_2         matlab.ui.control.Label
        PitchEditField                matlab.ui.control.NumericEditField
        PitchEditFieldLabel           matlab.ui.control.Label
        OrderofrotationDropDown       matlab.ui.control.DropDown
        OrderofrotationDropDownLabel  matlab.ui.control.Label
        Button_2                      matlab.ui.control.Button
        YawEditField                  matlab.ui.control.NumericEditField
        YawEditFieldLabel             matlab.ui.control.Label
        UIAxes_15                     matlab.ui.control.UIAxes
        UIAxes_14                     matlab.ui.control.UIAxes
        UIAxes_13                     matlab.ui.control.UIAxes
        Button                        matlab.ui.control.Button
        DateDropDown                  matlab.ui.control.DropDown
        DateDropDownLabel             matlab.ui.control.Label
        SliceSlider                   matlab.ui.control.Slider
        SliceSliderLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SegmentationandROCTab         matlab.ui.container.Tab
        EditField_2                   matlab.ui.control.EditField
        SavefigureincludingallimagingparametersButton  matlab.ui.control.Button
        AlphaSlider_3                 matlab.ui.control.Slider
        AlphaSlider_3Label            matlab.ui.control.Label
        DisplaydecorrelationDropDown  matlab.ui.control.DropDown
        DisplaydecorrelationDropDownLabel  matlab.ui.control.Label
        SliceSlider_11                matlab.ui.control.Slider
        SliceSlider_11Label           matlab.ui.control.Label
        SliceSlider_10                matlab.ui.control.Slider
        SliceSlider_10Label           matlab.ui.control.Label
        Lamp_2                        matlab.ui.control.Lamp
        SliceSlider_8                 matlab.ui.control.Slider
        SliceSlider_8Label            matlab.ui.control.Label
        ROCcurveanalysisPanel         matlab.ui.container.Panel
        SavefigureButton              matlab.ui.control.Button
        TrialsDropDown                matlab.ui.control.DropDown
        TrialsDropDownLabel           matlab.ui.control.Label
        DisplayROCcurvesButton        matlab.ui.control.Button
        MotioncorrectedAUCEditField   matlab.ui.control.NumericEditField
        MotioncorrectedAUCEditFieldLabel  matlab.ui.control.Label
        AUCEditField                  matlab.ui.control.NumericEditField
        AUCEditFieldLabel             matlab.ui.control.Label
        UIAxes_17                     matlab.ui.control.UIAxes
        SegmentationPanel             matlab.ui.container.Panel
        DrawrectangularROIButton      matlab.ui.control.Button
        SliceSpinner                  matlab.ui.control.Spinner
        SliceSpinnerLabel             matlab.ui.control.Label
        FinishsegmentationButton      matlab.ui.control.Button
        EditpolygonButton             matlab.ui.control.Button
        DreconstructlesionButton      matlab.ui.control.Button
        AblationlastsliceEditField    matlab.ui.control.NumericEditField
        AblationlastsliceEditFieldLabel  matlab.ui.control.Label
        AblationfirstsliceEditField   matlab.ui.control.NumericEditField
        AblationfirstsliceEditFieldLabel  matlab.ui.control.Label
        DisplayMRIButton              matlab.ui.control.Button
        UIAxes_18                     matlab.ui.control.UIAxes
        UIAxes_16                     matlab.ui.control.UIAxes
        ApplyregistrationtoechodecorrelationandIBSButton  matlab.ui.control.Button
        UIAxes_12                     matlab.ui.control.UIAxes
        UIAxes_11                     matlab.ui.control.UIAxes
        UIAxes_10                     matlab.ui.control.UIAxes
        UIAxes_9                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        drop_down_val
        US_decorr_xyz
        case_path % Description
        US_path
        photo_names
        trial_name
        cum_decorr
        cum_decorr_motion_corr
        xyz_range_HR
        xyz_range_LR
        Bmode_HR
        Bmode_vol_LR
        trial_number
        suite_CT_vol
        post_abl_1day_obj
        post_abl_1day_vol
        post_abl_1day_vol_image
        apex_loc
        apex_to_needle_dist
        rotation_order_choice
        rotation_angles
        registered_US_flag = false
        registered_US_LR_flag = false
        registered_US
        registered_US_LR
        overlay_flag = false
        overlay_slice = 30
        green
        overlay_threshold = 0
        slider7value = 10
        slider8value = 1
        slider10value = 10
        slider11value = 10
        decorrs_flag = false
        trial_name_LR

        registered_decorr_unscaled
        registered_decorr_scaled
        registered_motion_corr_decorr_unscaled
        registered_motion_corr_decorr_scaled

        registered_local_decorr_unscaled
        registered_local_decorr_scaled
        registered_local_motion_corr_decorr_unscaled
        registered_local_motion_corr_decorr_scaled

        registered_global_decorr_unscaled
        registered_global_decorr_scaled
        registered_global_motion_corr_decorr_unscaled
        registered_global_motion_corr_decorr_scaled

        registered_IBS

        US_overlay
        lesion_slice = [0,0]
        mask
        mask3D = []
        mask3D_gauss
        mask_number
        SliceSlider_9value
        alpha_decorr_value = 0.5
        display_decorr
        alpha3value = 0.5
        max_decorr = .1;
        dyn_range = 3;
        max_Bmode = 0.08;
        dB_range = 65;
        dicom_dxdydz
        dicom_x
        dicom_y
        dicom_z
        dicom_xmesh
        dicom_ymesh
        dicom_zmesh
        ROC_trials
        US_info_table
        cum_decorr_lastframe
        cum_decorr_motcorr_lastframe
        post_abl_1day_segment_inROI
        segment_ROI_azimuth
        segment_ROI_range
        post_abl_1day_segment_ROI_flag = false
        post_abl_1day_tab1_inROI
        tab1_ROI_azimuth
        tab1_ROI_range
        post_abl_1day_tab1_ROI_flag = false
        photo1
        photo2
    end

    properties (Access = public, Constant = true)
        %         base_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/';
        %         folder_extension = ' radiology MW ablation and followup/';
        input_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/input/';
        info_table_extension = 'ablation_information_for_app.xlsx';
        procedure_photos_extension = 'photos_of_procedures/';
        US_decorr_extension = 'ultrasound + decorr/';
        low_res_file_extension = '_IQcart_decorr_xyz.mat';
        high_res_file_extension = '_IQcart_xyz.mat';
        postop_1day_extension = '1day_postop/';
        postop_1day_subfolder_extension = '_postop_1day_images';
        suiteCT_extension = 'suiteCT/';
        suiteCT_file_extension = '_suiteCT';

        output_path = '/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/Registration GUI/output/';
        ablation_mask3D_extension = 'ablation_masks/ablation_mask3D_';
        registered_US_decorr_extension = 'registered_US_decorr/';
        registration_parameters_extension = 'registration_parameters/';
        registration_parameters_file_extension = '_registration_parameters.mat';
        decorr_IBS_figure_extension = 'figures/';
        decorr_IBS_figure_file_extension = '_decorr_normalizations_andIBS.fig';
        decorr_IBS_3Dfigure_file_extension = '_3D_decorr_normalizations_andIBS.fig';
        ROC_extension = 'ROC_curves/ROC_w_wo_motcorr_';

        input_US_decorr =  {'trial1_03182021_LR',...
            'trial2_03182021_LR',...
            'trial3_08242021_HR',...
            'trial4_08242021_HR',...
            'trial5_12032021_HR',...
            'trial6_04222022_HR',...
            'trial7_11252022_HR',...
            'trial8_12132022_HR',...
            'trial9_01052023_HR'};

        case_dates = {'03182021',...
            '03182021',...
            '08242021',...
            '08242021',...
            '12032021',...
            '04222022',...
            '11252022',...
            '12132022',...
            '01052023'};

        rotation_order    = {'x -> y -> z',...
            'x -> z -> y',...
            'y -> x -> z',...
            'y -> z -> x',...
            'z -> x -> y',...
            'z -> y -> x'};

        default_HR_IQfile_number  = [1, 1, 1, 1, 3, 1, 3, 1, 1];
        postop_1day_needle_sl = [1, 1, 1, 1, 30, 21, 1, 1, 1];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DateDropDown
        function DateDropDownOpening(app, event)
            app.DateDropDown.Items = app.input_US_decorr;
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            app.drop_down_val = app.DateDropDown.Value;
            app.trial_number = str2double(app.drop_down_val(6));
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');

            app.US_info_table = readtable([app.input_path,app.info_table_extension]);

            table_params = app.US_info_table.Properties.VariableNames;

            app.UITable.Data = {table_params{2};table_params{3};...
                table_params{4};table_params{5}};

            table_vals_HR = ...
                app.US_info_table{find(strcmp(app.US_info_table{:,1}, {app.drop_down_val})),2:6};

            temp = app.drop_down_val;
            temp(end-1) = 'L';

            table_vals_LR = ...
                app.US_info_table{find(strcmp(app.US_info_table{:,1}, {temp})),2:6};

            app.UITable.Data(:,2) = {table_vals_HR(1);...
                table_vals_HR(2); table_vals_HR(3); table_vals_HR(4)};

            app.UITable.Data(:,3) = {table_vals_LR(1);...
                table_vals_LR(2); table_vals_LR(3); table_vals_LR(4)};

            app.dicom_dxdydz = ...
                [app.US_info_table.dicom_dx(find(strcmp(app.US_info_table{:,1}, {app.drop_down_val}))),...
                app.US_info_table.dicom_dy((find(strcmp(app.US_info_table{:,1}, {app.drop_down_val})))),...
                app.US_info_table.dicom_dz((find(strcmp(app.US_info_table{:,1}, {app.drop_down_val}))))];

            app.UITable2.Data = {'Echo apex on MR';...
                'Needle track slice number';...
                'Apex to needle in Z(mm)';...
                'Order of rotation';...
                'Yaw, pitch, roll'};
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            directory1 = [app.input_path, app.procedure_photos_extension, ...
                app.drop_down_val(1:15), '_', num2str(1), '.jpeg'];
            directory2 = [app.input_path, app.procedure_photos_extension, ...
                app.drop_down_val(1:15), '_', num2str(2), '.jpeg'];

            app.photo1 = imread(directory1);
            app.photo2 = imread(directory2);

            imshow(app.photo1,'Parent',app.UIAxes)
            axis(app.UIAxes,'fill');
            pan(app.UIAxes,'off');
            zoom(app.UIAxes,'off');

            imshow(app.photo2,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'image');
            pan(app.UIAxes_2,'off');
            zoom(app.UIAxes_2,'off');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            answer = 'Yes';
            if ~isempty(app.xyz_range_HR)
                answer = questdlg('The US images are loaded. Do you want to reload them?', ...
                    'Warning', 'Yes','No','No');
            elseif isempty(app.xyz_range_HR) || strcmp(answer, 'Yes')
                directory = strcat(app.input_path, app.US_decorr_extension, app.drop_down_val, app.high_res_file_extension, '.mat');
                app.EditField.Value = 'Loading US images ...';
                pause(.1);
                US_imaging_xyz_HR = load(directory);
                app.EditField.Value = '';

                rawData_cart = squeeze(US_imaging_xyz_HR.rawData_cart(:,:,:,2,app.default_HR_IQfile_number(app.trial_number)));
                app.xyz_range_HR{1} = US_imaging_xyz_HR.x_range;
                app.xyz_range_HR{2} = US_imaging_xyz_HR.y_range;
                app.xyz_range_HR{3} = US_imaging_xyz_HR.z_range;

                app.Bmode_HR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;

                elevation_sl = ceil(size(app.Bmode_HR, 3) / 2);
                imagesc(app.UIAxes_3, app.Bmode_HR(:, :, elevation_sl))
                axis(app.UIAxes_3,'equal','tight');
                colormap(app.UIAxes_3,'gray');
                app.SliceSlider.Limits = [1 size(app.Bmode_HR,3)];
                app.SliceSlider.Value = 30;

                temp = app.drop_down_val;
                temp(17) = 'L';
                directory = strcat(app.input_path, app.US_decorr_extension, temp, app.low_res_file_extension);
                US_imaging_xyz_LR = load(directory);

                rawData_cart = squeeze(US_imaging_xyz_LR.rawData_cart(:,:,:,2,:));
                app.xyz_range_LR{1} = US_imaging_xyz_LR.x_range;
                app.xyz_range_LR{2} = US_imaging_xyz_LR.y_range;
                app.xyz_range_LR{3} = US_imaging_xyz_LR.z_range;
                app.Bmode_vol_LR = (10*log10(abs(rawData_cart./app.max_Bmode).^2)+app.dyn_range)/app.dyn_range;
                app.cum_decorr_lastframe = US_imaging_xyz_LR.cum_decorr(:,:,:,end);
                app.cum_decorr_motcorr_lastframe = US_imaging_xyz_LR.cum_decorr_motcorr(:,:,:,end);

                elevation_sl = ceil(size(app.Bmode_vol_LR, 3) / 2);
                imagesc(app.UIAxes2, app.Bmode_vol_LR(:, :, elevation_sl, 1))
                axis(app.UIAxes2,'equal','tight');
                colormap(app.UIAxes2,'gray');
                app.IQdataSlider.Limits = [1 size(app.Bmode_vol_LR, 4)];
                app.IQdataSlider.Value = 1;
                app.IQdataSliderLabel.Text = num2str(app.IQdataSlider.Value);
            end
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            imagesc(app.UIAxes_3,app.Bmode_HR(:,:,round(changingValue)))
            axis(app.UIAxes_3,'equal','tight');
            colormap(app.UIAxes_3,'gray');
            app.SliceSliderLabel.Text = num2str(round(changingValue));
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            directory = strcat(app.input_path, app.suiteCT_extension, app.drop_down_val(1:end-3), app.suiteCT_file_extension, '.jpg');
            suitCT_image = imread(directory);

            imagesc(app.UIAxes_4, suitCT_image)
            axis(app.UIAxes_4,'equal','tight');
            colormap(app.UIAxes_4,'gray');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %             address = [app.case_path,app.trial_number,'_1day_post_ablation/',app.post_abl_1day_address{app.case_number}];
            %             app.post_abl_1day_obj = read_1day_post_ablation(address);
            %             app.post_abl_1day_vol = app.post_abl_1day_obj.vol;
            %             app.post_abl_1day_vol_image = read_1day_post_ablation_image(address);
            %             %             app.post_abl_1day_vol_gray = repmat(mat2gray(app.post_abl_1day_vol,[0 1]),[1,1,1,3]);
            %             %             app.mask3D = zeros(size(app.post_abl_1day_vol));
            %             directory = strcat(app.input_path, app.postop_1day_extension, app.drop_down_val(1:15), '.mat');
            %             temp = load(directory);
            %             app.post_abl_1day_vol  = temp.MRvol;
            app.SliceSlider_7.Value = app.postop_1day_needle_sl(app.trial_number);
            directory = strcat(app.input_path, app.postop_1day_extension, app.drop_down_val(1:end-3), app.postop_1day_subfolder_extension);
            app.post_abl_1day_vol = read_1day_post_ablation_image_vol(directory);

            app.dicom_x = (1:size(app.post_abl_1day_vol,2))*app.dicom_dxdydz(1);
            app.dicom_y = (1:size(app.post_abl_1day_vol,1))*app.dicom_dxdydz(2);
            app.dicom_z = (1:size(app.post_abl_1day_vol,3))*app.dicom_dxdydz(3);
            [app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh] = ...
                meshgrid(app.dicom_x, app.dicom_y, app.dicom_z);

            app.post_abl_1day_obj.vol = app.post_abl_1day_vol;
            app.post_abl_1day_obj.dxdydz = app.dicom_dxdydz;

            imagesc(app.UIAxes_13,app.post_abl_1day_vol(:,:,app.postop_1day_needle_sl(app.trial_number)))
            axis(app.UIAxes_13,'equal','tight');
            colormap(app.UIAxes_13,'gray');
            app.SliceSlider_7.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_7.Value = app.postop_1day_needle_sl(app.trial_number);

            % Tab 2 plots
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, app.post_abl_1day_vol(:,:,app.postop_1day_needle_sl(app.trial_number)));
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'gray');

            x_sl = ceil(size(app.post_abl_1day_vol, 2) / 2);
            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_y, squeeze(app.post_abl_1day_vol(:,x_sl,:)));
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'gray');

            y_sl = ceil(size(app.post_abl_1day_vol, 1) / 2);
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(squeeze(app.post_abl_1day_vol(y_sl,:,:))));
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'gray');

            app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol,3)];
            app.SliceSlider_10.Limits = [1 size(app.post_abl_1day_vol,2)];
            app.SliceSlider_11.Limits = [1 size(app.post_abl_1day_vol,1)];
            app.AlphaSlider_3.Limits = [0 1];
        end

        % Value changing function: SliceSlider_7
        function SliceSlider_7ValueChanging(app, event)
            app.slider7value = event.Value;
            app.SliceSlider_7Label.Text = num2str(round(app.slider7value));
            app.overlay_slice = round(app.slider7value);

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if app.registered_US_flag
                imagesc(app.UIAxes_14, app.registered_US(range, azimuth, round(app.slider7value)))
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15, app.registered_US_LR(range, azimuth, round(app.overlay_slice)))
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:, :, 2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:, :, 2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:, :, 2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [app.apex_loc(1), app.apex_loc(2)] = getpts(app.UIAxes_13);
            app.apex_loc = round(app.apex_loc);

            app.UITable2.Data(1,2) = {num2str([app.apex_loc(1), app.apex_loc(2)])};
            %             app.apex_loc(1) = 23;
            %             app.apex_loc(2) = 236;
            %             app.apex_loc(3) = 21;
        end

        % Drop down opening function: OrderofrotationDropDown
        function OrderofrotationDropDownOpening(app, event)
            app.OrderofrotationDropDown.Items = app.rotation_order;
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value;
        end

        % Value changed function: OrderofrotationDropDown
        function OrderofrotationDropDownValueChanged(app, event)
            value = app.OrderofrotationDropDown.Value;
            app.rotation_order_choice = value;

            app.UITable2.Data(4,2) = {app.rotation_order_choice};
        end

        % Value changed function: ApextoneedleinZmmEditField
        function ApextoneedleinZmmEditFieldValueChanged(app, event)
            app.apex_to_needle_dist = app.ApextoneedleinZmmEditField.Value;

            app.UITable2.Data(3,2) = {num2str(app.apex_to_needle_dist)};
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            app.rotation_angles(1) = app.YawEditField.Value;

            app.UITable2.Data(5,2) = {num2str(app.rotation_angles)};
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            app.rotation_angles(2) = app.PitchEditField.Value;

            app.UITable2.Data(5,2) = {num2str(app.rotation_angles)};
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            app.rotation_angles(3) = app.RollEditField.Value;

            app.UITable2.Data(5,2) = {num2str(app.rotation_angles)};
        end

        % Button pushed function: UpdateregistrationButton
        function UpdateregistrationButtonPushed(app, event)
            app.Lamp.Color = 'red';
            pause(0.1);
            app.EditField.Value = 'Registration in progress...';
            output_directory = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.drop_down_val(1:end-3));

            [app.registered_US, load_registration_parameters_flag] = register(app.post_abl_1day_obj, ...
                app.Bmode_HR, app.xyz_range_HR, app.apex_loc,...
                app.apex_to_needle_dist, app.rotation_order_choice,...
                app.rotation_angles, output_directory, 'HR Bmode');

            app.Lamp.Color = 'green';
            pause(0.1);
            app.EditField.Value = 'Registration is now complete!';

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            imagesc(app.UIAxes_14,app.registered_US(range, azimuth, round(app.overlay_slice)))
            axis(app.UIAxes_14,'equal','tight');
            colormap(app.UIAxes_14,'gray');

            app.registered_US_flag = true;
            %             inside_echo = app.registered_US ~= NaN;
            %             app.post_abl_1day_obj
            %             app.temp_registered_US = app.registered_US;%(app.apex_loc(2), app.apex_loc(1));
            if load_registration_parameters_flag
                directory = strcat(app.output_path, ...
                    app.registration_parameters_extension, ...
                    app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
                registration_parameters = load(directory);

                echo_apex_on_MRI = num2str(registration_parameters.echo_apex_on_MRI);
                needle_track_slice_number = num2str(registration_parameters.needle_track_slice_number);
                apex_to_needle_in_z = num2str(registration_parameters.apex_to_needle_in_z);
                order_of_rotation = registration_parameters.order_of_rotation;
                rot_angles = num2str(registration_parameters.rot_angles);
                app.UITable2.Data(:,2) = {echo_apex_on_MRI;...
                    needle_track_slice_number;...
                    apex_to_needle_in_z;...
                    order_of_rotation;...
                    rot_angles};
            end
        end

        % Value changed function: NeedletrackslicenumberEditField
        function NeedletrackslicenumberEditFieldValueChanged(app, event)
            value = app.NeedletrackslicenumberEditField.Value;
            app.apex_loc(3) = round(value);

            app.UITable2.Data(2,2) = {num2str(app.apex_loc(3))};
        end

        % Button pushed function: OverlayultrasoundButton
        function OverlayultrasoundButtonPushed(app, event)
            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                %
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end

            app.AlphaSlider.Limits = [0 1];%[min(app.registered_US(:)) max(app.registered_US(:))];

            if strcmp(app.OverlayultrasoundButton.Text,'Overlay ultrasound')
                app.OverlayultrasoundButton.Text = 'Remove overlay';
                app.overlay_flag = true;
            else
                app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
                app.overlay_flag = false;
            end
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            if app.overlay_flag
                app.overlay_threshold = event.Value;

                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end

                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));

                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:,:,2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:,:,2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:,:,2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            end
        end

        % Button pushed function: ApplytoLRUSButton
        function ApplytoLRUSButtonPushed(app, event)
            if app.LowresolutionUSIQfilenumberEditField.Value == 0
                warndlg('You have not specified the IQ file number for the low resolution US image to apply registration to.','Warning');
            else
                Bmode_LR = app.Bmode_vol_LR(:,:,:,app.LowresolutionUSIQfilenumberEditField.Value);

                app.Lamp.Color = 'red';
                pause(0.5);
                app.EditField.Value = 'Registration in progress...';
                output_directory = strcat(app.output_path, ...
                    app.registered_US_decorr_extension, ...
                    app.drop_down_val(1:end-3));

                [app.registered_US_LR, load_registration_parameters_flag] = register(app.post_abl_1day_obj, ...
                    Bmode_LR, app.xyz_range_LR, app.apex_loc,...
                    app.apex_to_needle_dist,app.rotation_order_choice,...
                    app.rotation_angles, output_directory,  'LR Bmode');

                app.Lamp.Color = 'green';
                app.EditField.Value = 'Registration is now complete!';

                if app.post_abl_1day_tab1_ROI_flag
                    range = app.tab1_ROI_range;
                    azimuth = app.tab1_ROI_azimuth;
                else
                    range = 1:size(app.post_abl_1day_vol,1);
                    azimuth = 1:size(app.post_abl_1day_vol,2);
                end

                vol = app.registered_US_LR(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_15, vol)
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');

                app.registered_US_LR_flag = true;
                app.SliceSlider_8.Limits = [1 size(app.post_abl_1day_vol, 3)];
                app.SliceSlider_10.Limits = [1 size(app.post_abl_1day_vol, 2)];
                app.SliceSlider_11.Limits = [1 size(app.post_abl_1day_vol, 1)];
                app.AlphaSlider_3.Limits = [0 1];
            end

            if load_registration_parameters_flag
                directory = strcat(app.output_path, ...
                    app.registration_parameters_extension, ...
                    app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
                registration_parameters = load(directory);

                echo_apex_on_MRI = num2str(registration_parameters.echo_apex_on_MRI);
                needle_track_slice_number = num2str(registration_parameters.needle_track_slice_number);
                apex_to_needle_in_z = num2str(registration_parameters.apex_to_needle_in_z);
                order_of_rotation = registration_parameters.order_of_rotation;
                rot_angles = num2str(registration_parameters.rot_angles);
                app.UITable2.Data(:,2) = {echo_apex_on_MRI;...
                    needle_track_slice_number;...
                    apex_to_needle_in_z;...
                    order_of_rotation;...
                    rot_angles};
            end
        end

        % Button pushed function: 
        % ApplyregistrationtoechodecorrelationandIBSButton
        function ApplyregistrationtoechodecorrelationandIBSButtonPushed(app, event)
            app.Lamp_2.Color = 'red';
            pause(0.5);
            output_directory = strcat(app.output_path, ...
                app.registered_US_decorr_extension, app.drop_down_val(1:end-3));
            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};
            for i = 1:length(imaging_parameters)

                app.EditField_2.Value = ['Applying registration to ', ...
                    imaging_parameters{i}, '(registratoin ', ...
                    num2str(i), ' out of ', num2str(length(imaging_parameters)), ')'];

                [vol, load_registration_parameters_flag] = register(app.post_abl_1day_obj, ...
                    app.cum_decorr_lastframe, app.xyz_range_LR, app.apex_loc,...
                    app.apex_to_needle_dist,app.rotation_order_choice,...
                    app.rotation_angles, output_directory, imaging_parameters{i});

                if i ~= 7
                    vol_scaled = scale_decorrelation_map(vol, app.max_decorr, app.dyn_range);
                end

                switch i
                    case 1
                        app.registered_decorr_unscaled = vol;
                        app.registered_decorr_scaled = vol_scaled;
                    case 2
                        app.registered_motion_corr_decorr_unscaled = vol;
                        app.registered_motion_corr_decorr_scaled = vol_scaled;
                    case 3
                        app.registered_local_decorr_unscaled = vol;
                        app.registered_local_decorr_scaled = vol_scaled;
                    case 4
                        app.registered_local_motion_corr_decorr_unscaled = vol;
                        app.registered_local_motion_corr_decorr_scaled = vol_scaled;
                    case 5
                        app.registered_global_decorr_unscaled = vol;
                        app.registered_global_decorr_scaled = vol_scaled;
                    case 6
                        app.registered_global_motion_corr_decorr_unscaled = vol;
                        app.registered_global_motion_corr_decorr_scaled = vol_scaled;
                    case 7
                        app.registered_IBS = vol;
                end
            end

            app.EditField_2.Value = 'All registrations are done! Choose an imaging parameter to show on the plots below.';

            app.Lamp_2.Color = 'green';
            app.decorrs_flag = true;

            if load_registration_parameters_flag
                directory = strcat(app.output_path, ...
                    app.registration_parameters_extension, ...
                    app.drop_down_val(1:end-3),app.registration_parameters_file_extension);
                registration_parameters = load(directory);

                echo_apex_on_MRI = num2str(registration_parameters.echo_apex_on_MRI);
                needle_track_slice_number = num2str(registration_parameters.needle_track_slice_number);
                apex_to_needle_in_z = num2str(registration_parameters.apex_to_needle_in_z);
                order_of_rotation = registration_parameters.order_of_rotation;
                rot_angles = num2str(registration_parameters.rot_angles);
                app.UITable2.Data(:,2) = {echo_apex_on_MRI;...
                    needle_track_slice_number;...
                    apex_to_needle_in_z;...
                    order_of_rotation;...
                    rot_angles};
            end
        end

        % Value changing function: SliceSlider_8
        function SliceSlider_8ValueChanging(app, event)
            app.slider8value = event.Value;
            app.SliceSlider_8Label.Text = num2str(round(app.slider8value));

            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};

            switch app.display_decorr
                case imaging_parameters{1}
                    image_vol = app.registered_decorr_scaled;
                case imaging_parameters{2}
                    image_vol = app.registered_motion_corr_decorr_scaled;
                case imaging_parameters{3}
                    image_vol = app.registered_local_decorr_scaled;
                case imaging_parameters{4}
                    image_vol = app.registered_local_motion_corr_decorr_scaled;
                case imaging_parameters{5}
                    image_vol = app.registered_global_decorr_scaled;
                case imaging_parameters{6}
                    image_vol = app.registered_global_motion_corr_decorr_scaled;
                case imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            image_map = squeeze(image_vol(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');
        end

        % Button pushed function: DisplayMRIButton
        function DisplayMRIButtonPushed(app, event)
            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.slider8value)))
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');

            app.SliceSpinner.Limits = [1 size(app.post_abl_1day_vol,3)];
            %             app.mask3D = load('/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/ultrasound/ablation_mask3D.mat');
            %             app.mask3D = app.mask3D.ablation_mask3D;
        end

        % Value changed function: AblationfirstsliceEditField
        function AblationfirstsliceEditFieldValueChanged(app, event)
            app.lesion_slice(1) = app.AblationfirstsliceEditField.Value;

            app.SliceSpinner.Limits = [app.lesion_slice(1) app.SliceSpinner.Limits(2)];
            app.SliceSpinner.Value = app.lesion_slice(1);

            cla(app.UIAxes_16);
            imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.lesion_slice(1))))
            axis(app.UIAxes_16,'equal','tight');
            colormap(app.UIAxes_16,'gray');
        end

        % Value changed function: AblationlastsliceEditField
        function AblationlastsliceEditFieldValueChanged(app, event)
            app.lesion_slice(2) = app.AblationlastsliceEditField.Value;

            app.SliceSpinner.Limits = [app.SliceSpinner.Limits(1) app.lesion_slice(2)];
        end

        % Button pushed function: EditpolygonButton
        function EditpolygonButtonPushed(app, event)
            roiPoly = drawpolygon(app.UIAxes_16);
            app.mask = createMask(roiPoly);
            %             mask2D = app.mask;

            imagesc(app.UIAxes_18,app.mask);
            axis(app.UIAxes_18,'image');
            colormap(app.UIAxes_18,'gray');

            app.mask_number = app.SliceSpinner.Value - app.lesion_slice(1) + 1;
            %             save(['/Users/elmiraghahramani/Desktop/temp/seg',num2str(app.mask_number),'.mat'],'mask2D');
            app.mask3D(:,:,app.mask_number) = app.mask;
            %             clear mask2D
            pause(1);

            imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)+1));
            axis(app.UIAxes_16,'image');
            colormap(app.UIAxes_16,'gray');
            if app.SliceSpinner.Value < app.SliceSpinner.Limits (2)
                app.SliceSpinner.Value = app.SliceSpinner.Value + 1;
            end
        end

        % Button pushed function: FinishsegmentationButton
        function FinishsegmentationButtonPushed(app, event)
            %             save(['/Users/elmiraghahramani/Library/Mobile Documents/com~apple~CloudDocs/UC/Lab/Echo Decorrelation/Radiology ablation cases/12032021 radiology MW ablation and followup/Segmented 1day postop/seg',num2str(app.mask_number),'.mat'],'app.mask');
            %             app.mask3D = flip(app.mask3D,3);
            %             save('/Users/elmiraghahramani/Desktop/temp','app.mask3D');
            ablation_mask3D = zeros(size(app.post_abl_1day_vol));

            if app.post_abl_1day_segment_ROI_flag
                ablation_mask3D(app.segment_ROI_range, app.segment_ROI_azimuth, app.AblationfirstsliceEditField.Value:app.AblationlastsliceEditField.Value) = app.mask3D;
            else
                ablation_mask3D(:, :, app.AblationfirstsliceEditField.Value:app.AblationlastsliceEditField.Value) = app.mask3D;
            end
            app.mask3D = ablation_mask3D;

            quest = 'The 3D mask already exists. Do you want to overwrite?';
            dlgtitle = 'Overwriting warning';
            btn1 = 'No';
            btn2 = 'Yes';
            defbtn = 'No';
            output_directory = strcat(app.output_path, ...
                app.ablation_mask3D_extension, app.drop_down_val(1:15), '.mat');

            if exist(output_directory, 'file')
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                if strcmp(answer, 'Yes')
                    save(output_directory,'ablation_mask3D');
                end
            else
                save(output_directory,'ablation_mask3D');
            end

            app.post_abl_1day_segment_ROI_flag = false;
        end

        % Value changing function: SliceSpinner
        function SliceSpinnerValueChanging(app, event)
            if app.post_abl_1day_segment_ROI_flag

                imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)));
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');

            else

                imagesc(app.UIAxes_16,app.post_abl_1day_vol(:,:,round(app.SliceSpinner.Value)));
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');

            end
        end

        % Button pushed function: DreconstructlesionButton
        function DreconstructlesionButtonPushed(app, event)
            cla(app.UIAxes_18);
            output_directory = strcat(app.output_path, ...
                app.ablation_mask3D_extension, app.drop_down_val(1:15), '.mat');

            if exist(output_directory, 'file')
                disp('Hey')
                temp = load(output_directory);
                app.mask3D = temp.ablation_mask3D;
            end
            app.mask3D_gauss = imgaussfilt3(app.mask3D,2);

            %             cla(app.UIAxes_16);
            p = patch(app.UIAxes_18,isosurface(app.dicom_xmesh, app.dicom_ymesh, app.dicom_zmesh, app.mask3D_gauss,.2));
            p.FaceColor = 'red';
            p.EdgeColor = 'none';
            app.UIAxes_18.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_18, 3);
            axis(app.UIAxes_18,'tight');
            camlight(app.UIAxes_18);
            lighting gouraud
            app.UIAxes_18.XLim = [0 max(app.dicom_xmesh(:))];
            app.UIAxes_18.YLim = [0 max(app.dicom_ymesh(:))];
            app.UIAxes_18.ZLim = [0 max(app.dicom_zmesh(:))];
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            %             app.UIAxes_16.XTick = [];
            %             app.UIAxes_16.YTick = [];
        end

        % Button pushed function: DisplayROCcurvesButton
        function DisplayROCcurvesButtonPushed(app, event)
            if strcmp(app.ROC_trials,'All trials')
                app.ROC_trials = 'all';%app.Date;
            else
                app.ROC_trials = app.drop_down_val(1:15);%app.trial_name;
            end
            input_directory_regist_decorrs = strcat(app.output_path, ...
                app.registered_US_decorr_extension(1:20));
            input_directory_ablation_masks = strcat(app.output_path, ...
                app.ablation_mask3D_extension(1:14));
            output_directory = strcat(app.output_path, ...
                app.ROC_extension, app.ROC_trials);

            [output_fptpAUC, output_motcorr_fptpAUC] = ...
                ROC_analysis(app.registered_decorr_unscaled, ...
                app.registered_motion_corr_decorr_unscaled, ...
                app.mask3D, app.ROC_trials, input_directory_regist_decorrs, ...
                input_directory_ablation_masks, output_directory);

            not_corr_fprate_ablatedliverAll = output_fptpAUC(:,1);
            not_corr_tprate_ablatedliverAll = output_fptpAUC(:,2);
            not_corr_ROCarea_ablatedliverAll  = output_fptpAUC(1,3);

            mot_corr_fprate_ablatedliverAll = output_motcorr_fptpAUC(:,1);
            mot_corr_tprate_ablatedliverAll = output_motcorr_fptpAUC(:,2);
            mot_corr_ROCarea_ablatedliverAll  = output_motcorr_fptpAUC(1,3);

            plot(app.UIAxes_17,not_corr_fprate_ablatedliverAll,not_corr_tprate_ablatedliverAll,'-r',...
                mot_corr_fprate_ablatedliverAll,mot_corr_tprate_ablatedliverAll,'-b',...
                [0 1],[0 1],'--k','LineWidth',1);
            app.UIAxes_17.XLim = [-.001 1.001];
            app.UIAxes_17.YLim = [-.001 1.001];
            axis(app.UIAxes_17,'equal','tight');
            legend(app.UIAxes_17,['Cum Dec, AUROC=',num2str(round(not_corr_ROCarea_ablatedliverAll,3))],...
                ['Mot Corr Cum Dec, AUROC=',num2str(round(mot_corr_ROCarea_ablatedliverAll,3))],...
                'Location','SouthEast')
            grid(app.UIAxes_17,'on');

            app.AUCEditField.Value = round(not_corr_ROCarea_ablatedliverAll,3);
            app.MotioncorrectedAUCEditField.Value = round(mot_corr_ROCarea_ablatedliverAll,3);

            %             saveas(app.UIAxes_17,'ROC_total_w_wo_motion_corr_rhoScalFact2.png');
            %             saveas(app.UIAxes_17,'ROC_total_w_wo_motion_corr_rhoScalFact2.fig');
        end

        % Value changing function: SliceSlider_10
        function SliceSlider_10ValueChanging(app, event)
            app.slider10value = event.Value;
            app.SliceSlider_10Label.Text = num2str(round(app.slider10value));

            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};

            switch app.display_decorr
                case imaging_parameters{1}
                    image_vol = app.registered_decorr_scaled;
                case imaging_parameters{2}
                    image_vol = app.registered_motion_corr_decorr_scaled;
                case imaging_parameters{3}
                    image_vol = app.registered_local_decorr_scaled;
                case imaging_parameters{4}
                    image_vol = app.registered_local_motion_corr_decorr_scaled;
                case imaging_parameters{5}
                    image_vol = app.registered_global_decorr_scaled;
                case imaging_parameters{6}
                    image_vol = app.registered_global_motion_corr_decorr_scaled;
                case imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            image_map = squeeze(image_vol(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');
        end

        % Value changing function: SliceSlider_11
        function SliceSlider_11ValueChanging(app, event)
            app.slider11value = event.Value;
            app.SliceSlider_11Label.Text = num2str(round(app.slider11value));

            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};

            switch app.display_decorr
                case imaging_parameters{1}
                    image_vol = app.registered_decorr_scaled;
                case imaging_parameters{2}
                    image_vol = app.registered_motion_corr_decorr_scaled;
                case imaging_parameters{3}
                    image_vol = app.registered_local_decorr_scaled;
                case imaging_parameters{4}
                    image_vol = app.registered_local_motion_corr_decorr_scaled;
                case imaging_parameters{5}
                    image_vol = app.registered_global_decorr_scaled;
                case imaging_parameters{6}
                    image_vol = app.registered_global_motion_corr_decorr_scaled;
                case imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            image_map = rot90(squeeze(image_vol(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Value changed function: DisplaydecorrelationDropDown
        function DisplaydecorrelationDropDownValueChanged(app, event)
            app.display_decorr = app.DisplaydecorrelationDropDown.Value;

            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};

            switch app.display_decorr
                case imaging_parameters{1}
                    image_vol = app.registered_decorr_scaled;
                case imaging_parameters{2}
                    image_vol = app.registered_motion_corr_decorr_scaled;
                case imaging_parameters{3}
                    image_vol = app.registered_local_decorr_scaled;
                case imaging_parameters{4}
                    image_vol = app.registered_local_motion_corr_decorr_scaled;
                case imaging_parameters{5}
                    image_vol = app.registered_global_decorr_scaled;
                case imaging_parameters{6}
                    image_vol = app.registered_global_motion_corr_decorr_scaled;
                case imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end


            %             alphaMap = image_vol;%-threshold;
            %             alphaMap(alphaMap<0.08) = 0;
            %             alphaMap(alphaMap>1) = 1;

            %             mapdecorr = squeeze(cum_decorrelation(:,:,round(app.slider8value)));
            %             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider8value))),...
            %                 squeeze(mapdecorr),[0 1],[],...
            %                 'hot',squeeze(alphaMap(:,:,round(app.slider8value))),'XY',app.dicom_x,app.dicom_y, app.UIAxes_12);
            %
            %             mapdecorr = squeeze(cum_decorrelation(:,round(app.slider10value),:));
            %             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider10value))),...
            %                 squeeze(mapdecorr),[0 1],[],...
            %                 'hot',squeeze(alphaMap(:,:,round(app.slider10value))),'XY',app.dicom_z,app.dicom_y, app.UIAxes_9);
            %
            %             mapdecorr = rot90(squeeze(cum_decorrelation(round(app.slider11value),:,:)));
            %             imoverlayPaperMRI(squeeze(app.post_abl_1day_vol(:,:,round(app.slider11value))),...
            %                 squeeze(mapdecorr),[0 1],[],...
            %                 'hot',squeeze(alphaMap(:,:,round(app.slider11value))),'XY',app.dicom_x,app.dicom_z, app.UIAxes_10);

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            image_map = squeeze(image_vol(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            image_map = squeeze(image_vol(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');

            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            image_map = rot90(squeeze(image_vol(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');


            % 3D image in the 4th pannel
            cla(app.UIAxes_11);
            p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, ...
                app.dicom_zmesh, app.post_abl_1day_vol,...
                round(app.slider10value)*app.dicom_dxdydz(1),0,...
                round(app.slider8value)*app.dicom_dxdydz(3));
            set(p1, 'EdgeColor','none', 'FaceColor','interp');
            alpha(p1,.5);
            colormap(app.UIAxes_11,'gray');
            axis(app.UIAxes_11,'image');
            set(app.UIAxes_11,'ZTick',[], 'YTick', [], 'XTick', []);
            set(app.UIAxes_11,'YDir','reverse');
            hold(app.UIAxes_11,'on');

            image_vol_gauss = imgaussfilt3(image_vol,2);
            p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, ...
                app.dicom_ymesh, app.dicom_zmesh, ...
                imgaussfilt3(image_vol_gauss,.2)));%squeeze(hybrid(predictor,:,:,:,2)),threshold));
            p2.FaceAlpha = .8;
            p2.FaceColor = 'red';
            p2.EdgeColor = 'none';
            %             app.UIAxes_11.PlotBoxAspectRatio = [1 1 1];
            view(app.UIAxes_11, 3);
            axis(app.UIAxes_11,'tight');
            camlight(app.UIAxes_11);
            lighting gouraud
            %                         app.UIAxes_11.XLim = [0 size(cum_decorr_gauss,1)];
            %                         app.UIAxes_11.YLim = [0 size(cum_decorr_gauss,2)];
            %                         app.UIAxes_11.ZLim = [0 size(cum_decorr_gauss,3)];
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
        end

        % Value changing function: AlphaSlider_3
        function AlphaSlider_3ValueChanging(app, event)
            app.alpha3value = event.Value;

            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};

            switch app.display_decorr
                case imaging_parameters{1}
                    image_vol = app.registered_decorr_scaled;
                case imaging_parameters{2}
                    image_vol = app.registered_motion_corr_decorr_scaled;
                case imaging_parameters{3}
                    image_vol = app.registered_local_decorr_scaled;
                case imaging_parameters{4}
                    image_vol = app.registered_local_motion_corr_decorr_scaled;
                case imaging_parameters{5}
                    image_vol = app.registered_global_decorr_scaled;
                case imaging_parameters{6}
                    image_vol = app.registered_global_motion_corr_decorr_scaled;
                case imaging_parameters{7}
                    image_vol = app.registered_IBS;
            end

            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:))));
            hold(app.UIAxes_12,'on');
            image_map = squeeze(image_vol(:,:,round(app.slider8value)));
            imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_12,'on');
                mask_edge = edge(app.mask3D(:,:,round(app.slider8value)));
                imagesc(app.UIAxes_12, app.dicom_x, app.dicom_y, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_12,'off');
            end
            axis(app.UIAxes_12,'image');
            colormap(app.UIAxes_12,'hot');
            hold(app.UIAxes_12,'off');

            imagesc(app.UIAxes_9, app.dicom_z, app.dicom_x, uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:))));
            hold(app.UIAxes_9,'on');
            image_map = squeeze(image_vol(:,round(app.slider10value),:,:));
            imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_9,'on');
                mask_edge = edge(squeeze(app.mask3D(:,round(app.slider10value),:)));
                imagesc(app.UIAxes_9,app.dicom_z, app.dicom_x, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_9,'off');
            end
            axis(app.UIAxes_9,'image');
            colormap(app.UIAxes_9,'hot');
            hold(app.UIAxes_9,'off');

            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:)))));
            hold(app.UIAxes_10,'on');
            image_map = rot90(squeeze(image_vol(round(app.slider11value),:,:,:)));
            imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, image_map,'AlphaData',app.alpha3value.*image_map);
            if ~isempty(app.mask3D)
                hold(app.UIAxes_10,'on');
                mask_edge = edge(rot90(squeeze(app.mask3D(round(app.slider11value),:,:))));
                imagesc(app.UIAxes_10, app.dicom_x, app.dicom_z, mask_edge,'AlphaData',mask_edge);
                hold(app.UIAxes_10,'off');
            end
            axis(app.UIAxes_10,'image');
            colormap(app.UIAxes_10,'hot');
            hold(app.UIAxes_10,'off');
        end

        % Drop down opening function: TrialsDropDown
        function TrialsDropDownOpening(app, event)
            %             app.TrialsDropDown.Items = app.date;
            app.ROC_trials = app.TrialsDropDown.Value;
        end

        % Value changed function: TrialsDropDown
        function TrialsDropDownValueChanged(app, event)
            app.ROC_trials = app.TrialsDropDown.Value;
        end

        % Button pushed function: SavefigureButton
        function SavefigureButtonPushed(app, event)
            output_directory = strcat(app.output_path, ...
                app.ROC_extension, app.drop_down_val(1:15), '_rhoScalFact2.fig');
            saveas(app.UIAxes_17, output_directory);
        end

        % Button pushed function: DrawrectangularROIButton
        function DrawrectangularROIButtonPushed(app, event)
            if ~app.post_abl_1day_segment_ROI_flag
                app.post_abl_1day_segment_ROI_flag = true;
                roiRect = drawrectangle(app.UIAxes_16);
                post_abl_1day_segment_ROI_pos = round(roiRect.Position);
                app.segment_ROI_azimuth = post_abl_1day_segment_ROI_pos(1) : post_abl_1day_segment_ROI_pos(1) + post_abl_1day_segment_ROI_pos(3);
                app.segment_ROI_range = post_abl_1day_segment_ROI_pos(2) : post_abl_1day_segment_ROI_pos(2) + post_abl_1day_segment_ROI_pos(4);
                app.post_abl_1day_segment_inROI = ...
                    app.post_abl_1day_vol(app.segment_ROI_range, app.segment_ROI_azimuth, :);

                imagesc(app.UIAxes_16,app.post_abl_1day_segment_inROI(:,:,round(app.SliceSpinner.Value)));
                axis(app.UIAxes_16,'image');
                colormap(app.UIAxes_16,'gray');
            end
        end

        % Button pushed function: DrawrectangularROIButton_2
        function DrawrectangularROIButton_2Pushed(app, event)
            if ~app.post_abl_1day_tab1_ROI_flag
                roiRect = drawrectangle(app.UIAxes_13);
                post_abl_1day_tab1_ROI_pos = round(roiRect.Position);
                app.tab1_ROI_azimuth = post_abl_1day_tab1_ROI_pos(1) : post_abl_1day_tab1_ROI_pos(1) + post_abl_1day_tab1_ROI_pos(3);
                app.tab1_ROI_range = post_abl_1day_tab1_ROI_pos(2) : post_abl_1day_tab1_ROI_pos(2) + post_abl_1day_tab1_ROI_pos(4);
                app.post_abl_1day_tab1_inROI = ...
                    app.post_abl_1day_vol(app.tab1_ROI_range, app.tab1_ROI_azimuth, :);
            end

            if strcmp(app.DrawrectangularROIButton_2.Text,'Draw rectangular ROI')
                app.DrawrectangularROIButton_2.Text = 'Original size images';
                app.post_abl_1day_tab1_ROI_flag = true;
            else
                app.DrawrectangularROIButton_2.Text = 'Draw rectangular ROI';
                app.post_abl_1day_tab1_ROI_flag = false;
            end

            if app.post_abl_1day_tab1_ROI_flag
                range = app.tab1_ROI_range;
                azimuth = app.tab1_ROI_azimuth;
            else
                range = 1:size(app.post_abl_1day_vol,1);
                azimuth = 1:size(app.post_abl_1day_vol,2);
            end

            if app.registered_US_flag
                imagesc(app.UIAxes_14,app.registered_US(range, azimuth, round(app.slider7value)))
                axis(app.UIAxes_14,'equal','tight');
                colormap(app.UIAxes_14,'gray');
            end

            if app.registered_US_LR_flag
                imagesc(app.UIAxes_15,app.registered_US_LR(range, azimuth, round(app.overlay_slice)))
                axis(app.UIAxes_15,'equal','tight');
                colormap(app.UIAxes_15,'gray');
            end

            if app.overlay_flag
                vol = app.post_abl_1day_vol(range, azimuth, round(app.overlay_slice));
                imagesc(app.UIAxes_13,vol)
                colormap(app.UIAxes_13,'gray');
                app.US_overlay = cat(3,zeros(size(vol)),ones(size(vol)),zeros(size(vol)));
                app.US_overlay(:, :, 2) = app.registered_US(range, azimuth, round(app.overlay_slice));
                app.US_overlay(:, :, 2)  = app.US_overlay(:, :, 2) - min(app.US_overlay(:));
                app.US_overlay(:, :, 2) = app.US_overlay(:, :, 2)/max(app.US_overlay(:));

                hold(app.UIAxes_13,'on');
                imagesc(app.UIAxes_13,app.US_overlay,"AlphaData",app.overlay_threshold)
                hold(app.UIAxes_13,'off');
            else
                imagesc(app.UIAxes_13,app.post_abl_1day_vol(range, azimuth, round(app.slider7value)));
                axis(app.UIAxes_13,'image');
                colormap(app.UIAxes_13,'gray');
            end
        end

        % Value changing function: IQdataSlider
        function IQdataSliderValueChanging(app, event)
            changingValue = round(event.Value);
            elevation_sl = ceil(size(app.Bmode_vol_LR, 3) / 2);
            imagesc(app.UIAxes2, app.Bmode_vol_LR(:, :, elevation_sl, changingValue));
            axis(app.UIAxes2,'equal','tight');
            colormap(app.UIAxes2,'gray');
            app.IQdataSliderLabel.Text = num2str(changingValue);
        end

        % Value changing function: ZoomSlider
        function ZoomSliderValueChanging(app, event)
            changingValue = event.Value;

            imshow(app.photo1,'Parent',app.UIAxes)
            axis(app.UIAxes,'fill');
            app.UIAxes.XLim = [size(app.photo1, 2) - changingValue * size(app.photo1, 2),...
                changingValue * size(app.photo1, 2)];
            app.UIAxes.YLim = [size(app.photo1, 1) - changingValue * size(app.photo1, 1),...
                changingValue * size(app.photo1, 1)];
            pan(app.UIAxes,'off');
            zoom(app.UIAxes,'off');
            app.ZoomSliderLabel.Text= num2str(changingValue);
        end

        % Value changing function: ZoomSlider_2
        function ZoomSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            imshow(app.photo2,'Parent',app.UIAxes_2)
            axis(app.UIAxes_2,'fill');
            app.UIAxes_2.XLim = [size(app.photo2, 2) - changingValue * size(app.photo2, 2),...
                changingValue * size(app.photo2, 2)];
            app.UIAxes_2.YLim = [size(app.photo2, 1) - changingValue * size(app.photo2, 1),...
                changingValue * size(app.photo2, 1)];
            pan(app.UIAxes_2,'off');
            zoom(app.UIAxes_2,'off');
            app.ZoomSlider_2Label.Text= num2str(changingValue);
        end

        % Drop down opening function: DisplaydecorrelationDropDown
        function DisplaydecorrelationDropDownOpening(app, event)
            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};
            app.DisplaydecorrelationDropDown.Items = imaging_parameters;
        end

        % Button pushed function: 
        % SavefigureincludingallimagingparametersButton
        function SavefigureincludingallimagingparametersButtonPushed(app, event)
            imaging_parameters = {'combined decorr', 'combined mot corr decorr',...
                'local decorr', 'local mot corr decorr',...
                'global decorr', 'global mot corr decorr',...
                'IBS'};
            num_crosssections = 3;
            
            figure;
            for i = 1:length(imaging_parameters)

                switch i
                    case 1
                        image_vol = app.registered_decorr_scaled;
                    case 2
                        image_vol = app.registered_motion_corr_decorr_scaled;
                    case 3
                        image_vol = app.registered_local_decorr_scaled;
                    case 4
                        image_vol = app.registered_local_motion_corr_decorr_scaled;
                    case 5
                        image_vol = app.registered_global_decorr_scaled;
                    case 6
                        image_vol = app.registered_global_motion_corr_decorr_scaled;
                    case 7
                        image_vol = app.registered_IBS;
                end
                alpha_value = app.alpha3value;

                % panel 1: Y vs. X
                subplot(i, 1, (i-1)*num_crosssections+1)
                post_abl_1day_image2D = uint8(squeeze(app.post_abl_1day_vol_image(:,:,round(app.slider8value),:)));
                image2D = squeeze(image_vol(:,:,round(app.slider8value)));
                mask2D = app.mask3D(:,:,round(app.slider8value));
                x = app.dicom_x;
                y = app.dicom_y;
                superimpose_image_on_MR(post_abl_1day_image2D, image2D, mask2D, x, y, alpha_value);

                % panel 2: Y vs. Z
                subplot(i, 1, (i-1)*num_crosssections+2)
                post_abl_1day_image2D = uint8(squeeze(app.post_abl_1day_vol_image(:,round(app.slider10value),:,:)));
                image2D = squeeze(image_vol(:,round(app.slider10value),:,:));
                mask2D = squeeze(app.mask3D(:,round(app.slider10value),:));
                x = app.dicom_z;
                y = app.dicom_y;
                superimpose_image_on_MR(post_abl_1day_image2D, image2D, mask2D, x, y, alpha_value);

                % panel 3: X vs. Y
                subplot(i, 1, (i-1)*num_crosssections+3)
                post_abl_1day_image2D = rot90(uint8(squeeze(app.post_abl_1day_vol_image(round(app.slider11value),:,:,:))));
                image2D = rot90(squeeze(image_vol(round(app.slider11value),:,:,:)));
                mask2D = rot90(squeeze(app.mask3D(round(app.slider11value),:,:)));
                x = app.dicom_x;
                y = app.dicom_z;
                superimpose_image_on_MR(post_abl_1day_image2D, image2D, mask2D, x, y, alpha_value);
            end
                directory = [app.decorr_IBS_figure_extension, ...
                    app.drop_down_val(1:end-3), app.decorr_IBS_figure_file_extension];
                saveas(gcf, directory)

            % 3D images
            figure;
            for i = 1:length(imaging_parameters)

                switch i
                    case 1
                        image_vol = app.registered_decorr_scaled;
                    case 2
                        image_vol = app.registered_motion_corr_decorr_scaled;
                    case 3
                        image_vol = app.registered_local_decorr_scaled;
                    case 4
                        image_vol = app.registered_local_motion_corr_decorr_scaled;
                    case 5
                        image_vol = app.registered_global_decorr_scaled;
                    case 6
                        image_vol = app.registered_global_motion_corr_decorr_scaled;
                    case 7
                        image_vol = app.registered_IBS;
                end

                subplot(1, length(imaging_parameters), i)
                p1 = slice(app.UIAxes_11, app.dicom_xmesh, app.dicom_ymesh, ...
                    app.dicom_zmesh, app.post_abl_1day_vol,...
                    round(app.slider10value)*app.dicom_dxdydz(1),0,...
                    round(app.slider8value)*app.dicom_dxdydz(3));
                set(p1, 'EdgeColor','none', 'FaceColor','interp');
                alpha(p1,.5);
                colormap gray;
                axis image;
                set(gca,'ZTick',[], 'YTick', [], 'XTick', []);
                set(gca,'YDir','reverse');
                hold on;

                image_vol_gauss = imgaussfilt3(image_vol,2);
                p2 = patch(app.UIAxes_11,isosurface(app.dicom_xmesh, ...
                    app.dicom_ymesh, app.dicom_zmesh, ...
                    imgaussfilt3(image_vol_gauss,.2)));%squeeze(hybrid(predictor,:,:,:,2)),threshold));
                p2.FaceAlpha = .8;
                p2.FaceColor = 'red';
                p2.EdgeColor = 'none';
                %             app.UIAxes_11.PlotBoxAspectRatio = [1 1 1];
                view(gca, 3);
                axis(gca,'tight');
                camlight(gca);
                lighting gouraud
                %                         app.UIAxes_11.XLim = [0 size(cum_decorr_gauss,1)];
                %                         app.UIAxes_11.YLim = [0 size(cum_decorr_gauss,2)];
                %                         app.UIAxes_11.ZLim = [0 size(cum_decorr_gauss,3)];
                xlabel('X')
                ylabel('Y')
                zlabel('Z')
            end
            directory = [app.decorr_IBS_figure_extension, ...
                app.drop_down_val(1:end-3), app.decorr_IBS_3Dfigure_file_extension];
            saveas(gcf, directory)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.UIAxes = uiaxes(app.RegistrationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.RegistrationTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RegistrationTab);
            title(app.UIAxes_3, 'High resolution US image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.RegistrationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes2 = uiaxes(app.RegistrationTab);
            title(app.UIAxes2, 'Low resolution US image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Image = uiimage(app.RegistrationTab);
            app.Image.ImageSource = 'axes_photo.jpeg';
            app.UITable = uitable(app.RegistrationTab);
            app.UITable.ColumnName = {'Parameter'; 'High res.'; 'Low res.'};
            app.UITable.RowName = {};
            app.SliceSliderLabel = uilabel(app.RegistrationTab);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.RegistrationTab);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.DateDropDownLabel = uilabel(app.RegistrationTab);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.RegistrationTab);
            app.DateDropDown.Items = {};
            app.DateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DateDropDownOpening, true);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.Button = uibutton(app.RegistrationTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'CT.png';
            app.Button.Text = '';
            app.RegistrationPanel = uipanel(app.RegistrationTab);
            app.RegistrationPanel.Title = 'Registration';
            app.UIAxes_13 = uiaxes(app.RegistrationPanel);
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.RegistrationPanel);
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.RegistrationPanel);
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.YawEditFieldLabel = uilabel(app.RegistrationPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.Button_2 = uibutton(app.RegistrationPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'apex.png';
            app.Button_2.Text = '';
            app.OrderofrotationDropDownLabel = uilabel(app.RegistrationPanel);
            app.OrderofrotationDropDownLabel.Text = 'Order of rotation';
            app.OrderofrotationDropDown = uidropdown(app.RegistrationPanel);
            app.OrderofrotationDropDown.Items = {};
            app.OrderofrotationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @OrderofrotationDropDownOpening, true);
            app.OrderofrotationDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderofrotationDropDownValueChanged, true);
            app.OrderofrotationDropDown.Value = {};
            app.PitchEditFieldLabel = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.PitchEditFieldLabel_2 = uilabel(app.RegistrationPanel);
            app.PitchEditFieldLabel_2.Text = 'Roll';
            app.RollEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.EchoapexonMRLabel = uilabel(app.RegistrationPanel);
            app.EchoapexonMRLabel.Text = 'Echo apex on MR';
            app.UpdateregistrationButton = uibutton(app.RegistrationPanel, 'push');
            app.UpdateregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateregistrationButtonPushed, true);
            app.UpdateregistrationButton.Text = 'Update registration';
            app.Lamp = uilamp(app.RegistrationPanel);
            app.EditField = uieditfield(app.RegistrationPanel, 'text');
            app.SliceSlider_7Label = uilabel(app.RegistrationPanel);
            app.SliceSlider_7Label.Text = 'Slice';
            app.SliceSlider_7 = uislider(app.RegistrationPanel);
            app.SliceSlider_7.MajorTicks = [];
            app.SliceSlider_7.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_7ValueChanging, true);
            app.SliceSlider_7.MinorTicks = [];
            app.OverlayultrasoundButton = uibutton(app.RegistrationPanel, 'push');
            app.OverlayultrasoundButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayultrasoundButtonPushed, true);
            app.OverlayultrasoundButton.Text = 'Overlay ultrasound';
            app.AlphaSliderLabel = uilabel(app.RegistrationPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.RegistrationPanel);
            app.AlphaSlider.MajorTicks = [];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.MinorTicks = [];
            app.ApextoneedleinZmmEditFieldLabel = uilabel(app.RegistrationPanel);
            app.ApextoneedleinZmmEditFieldLabel.Text = 'Apex to needle in Z (mm)';
            app.ApextoneedleinZmmEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.ApextoneedleinZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ApextoneedleinZmmEditFieldValueChanged, true);
            app.RotationanglesdegLabel = uilabel(app.RegistrationPanel);
            app.RotationanglesdegLabel.Text = 'Rotation angles (deg):';
            app.ApplytoLRUSButton = uibutton(app.RegistrationPanel, 'push');
            app.ApplytoLRUSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytoLRUSButtonPushed, true);
            app.ApplytoLRUSButton.Text = 'Apply to LR US';
            app.NeedletrackslicenumberEditFieldLabel = uilabel(app.RegistrationPanel);
            app.NeedletrackslicenumberEditFieldLabel.Text = 'Needle track slice number';
            app.NeedletrackslicenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.NeedletrackslicenumberEditField.ValueChangedFcn = createCallbackFcn(app, @NeedletrackslicenumberEditFieldValueChanged, true);
            app.DrawrectangularROIButton_2 = uibutton(app.RegistrationPanel, 'push');
            app.DrawrectangularROIButton_2.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangularROIButton_2Pushed, true);
            app.DrawrectangularROIButton_2.Text = 'Draw rectangular ROI';
            app.LowresolutionUSIQfilenumberLabel = uilabel(app.RegistrationPanel);
            app.LowresolutionUSIQfilenumberLabel.Text = 'Low resolution US IQ file number:';
            app.LowresolutionUSIQfilenumberEditField = uieditfield(app.RegistrationPanel, 'numeric');
            app.UITable2 = uitable(app.RegistrationPanel);
            app.UITable2.ColumnName = {'Parameter'; 'Value'};
            app.UITable2.RowName = {};
            app.Button_3 = uibutton(app.RegistrationTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'US.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.RegistrationTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'photo.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.RegistrationTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'mri.png';
            app.Button_5.Text = '';
            app.IQdataSliderLabel = uilabel(app.RegistrationTab);
            app.IQdataSliderLabel.Text = 'IQ data';
            app.IQdataSlider = uislider(app.RegistrationTab);
            app.IQdataSlider.Limits = [0 50];
            app.IQdataSlider.MajorTicks = [];
            app.IQdataSlider.MajorTickLabels = {''};
            app.IQdataSlider.ValueChangingFcn = createCallbackFcn(app, @IQdataSliderValueChanging, true);
            app.IQdataSlider.MinorTicks = [];
            app.ZoomSliderLabel = uilabel(app.RegistrationTab);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.RegistrationTab);
            app.ZoomSlider.Limits = [0.7 0.99];
            app.ZoomSlider.MajorTicks = [];
            app.ZoomSlider.ValueChangingFcn = createCallbackFcn(app, @ZoomSliderValueChanging, true);
            app.ZoomSlider.MinorTicks = [];
            app.ZoomSlider.Value = 0.7;
            app.ZoomSlider_2Label = uilabel(app.RegistrationTab);
            app.ZoomSlider_2Label.Text = 'Zoom';
            app.ZoomSlider_2 = uislider(app.RegistrationTab);
            app.ZoomSlider_2.Limits = [0.7 0.99];
            app.ZoomSlider_2.MajorTicks = [];
            app.ZoomSlider_2.ValueChangingFcn = createCallbackFcn(app, @ZoomSlider_2ValueChanging, true);
            app.ZoomSlider_2.MinorTicks = [];
            app.ZoomSlider_2.Value = 0.7;
            app.SegmentationandROCTab = uitab(app.TabGroup);
            app.SegmentationandROCTab.Title = 'Segmentation and ROC';
            app.UIAxes_9 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_9, 'Z (mm)')
            ylabel(app.UIAxes_9, 'Y (mm)')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_10, 'X (mm)')
            ylabel(app.UIAxes_10, 'Z (mm)')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.SegmentationandROCTab);
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.SegmentationandROCTab);
            xlabel(app.UIAxes_12, 'X (mm)')
            ylabel(app.UIAxes_12, 'Y (mm)')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.ApplyregistrationtoechodecorrelationandIBSButton = uibutton(app.SegmentationandROCTab, 'push');
            app.ApplyregistrationtoechodecorrelationandIBSButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyregistrationtoechodecorrelationandIBSButtonPushed, true);
            app.ApplyregistrationtoechodecorrelationandIBSButton.Text = 'Apply registration to echo decorrelation and IBS';
            app.SegmentationPanel = uipanel(app.SegmentationandROCTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.UIAxes_16 = uiaxes(app.SegmentationPanel);
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_18 = uiaxes(app.SegmentationPanel);
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.DisplayMRIButton = uibutton(app.SegmentationPanel, 'push');
            app.DisplayMRIButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMRIButtonPushed, true);
            app.DisplayMRIButton.Text = 'Display MRI';
            app.AblationfirstsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationfirstsliceEditFieldLabel.Text = 'Ablation first slice';
            app.AblationfirstsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationfirstsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationfirstsliceEditFieldValueChanged, true);
            app.AblationlastsliceEditFieldLabel = uilabel(app.SegmentationPanel);
            app.AblationlastsliceEditFieldLabel.Text = 'Ablation last slice';
            app.AblationlastsliceEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.AblationlastsliceEditField.ValueChangedFcn = createCallbackFcn(app, @AblationlastsliceEditFieldValueChanged, true);
            app.DreconstructlesionButton = uibutton(app.SegmentationPanel, 'push');
            app.DreconstructlesionButton.ButtonPushedFcn = createCallbackFcn(app, @DreconstructlesionButtonPushed, true);
            app.DreconstructlesionButton.Text = '3D reconstruct lesion';
            app.EditpolygonButton = uibutton(app.SegmentationPanel, 'push');
            app.EditpolygonButton.ButtonPushedFcn = createCallbackFcn(app, @EditpolygonButtonPushed, true);
            app.EditpolygonButton.Text = 'Edit polygon';
            app.FinishsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.FinishsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @FinishsegmentationButtonPushed, true);
            app.FinishsegmentationButton.Text = 'Finish segmentation';
            app.SliceSpinnerLabel = uilabel(app.SegmentationPanel);
            app.SliceSpinnerLabel.Text = 'Slice';
            app.SliceSpinner = uispinner(app.SegmentationPanel);
            app.SliceSpinner.ValueChangingFcn = createCallbackFcn(app, @SliceSpinnerValueChanging, true);
            app.DrawrectangularROIButton = uibutton(app.SegmentationPanel, 'push');
            app.DrawrectangularROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawrectangularROIButtonPushed, true);
            app.DrawrectangularROIButton.Text = 'Draw rectangular ROI';
            app.ROCcurveanalysisPanel = uipanel(app.SegmentationandROCTab);
            app.ROCcurveanalysisPanel.Title = 'ROC curve analysis';
            app.UIAxes_17 = uiaxes(app.ROCcurveanalysisPanel);
            xlabel(app.UIAxes_17, '1-Specificity')
            ylabel(app.UIAxes_17, 'Sensitivity')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.AUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.AUCEditFieldLabel.Text = 'AUC';
            app.AUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.AUCEditField.ValueChangedFcn = createCallbackFcn(app, @AUC2EditFieldValueChanged, true);
            app.MotioncorrectedAUCEditFieldLabel = uilabel(app.ROCcurveanalysisPanel);
            app.MotioncorrectedAUCEditFieldLabel.Text = 'Motion corrected AUC';
            app.MotioncorrectedAUCEditField = uieditfield(app.ROCcurveanalysisPanel, 'numeric');
            app.DisplayROCcurvesButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.DisplayROCcurvesButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROCcurvesButtonPushed, true);
            app.DisplayROCcurvesButton.Text = 'Display ROC curves';
            app.TrialsDropDownLabel = uilabel(app.ROCcurveanalysisPanel);
            app.TrialsDropDownLabel.Text = 'Trials';
            app.TrialsDropDown = uidropdown(app.ROCcurveanalysisPanel);
            app.TrialsDropDown.Items = {'Current trial', 'All trials'};
            app.TrialsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TrialsDropDownOpening, true);
            app.TrialsDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialsDropDownValueChanged, true);
            app.TrialsDropDown.Value = 'Current trial';
            app.SavefigureButton = uibutton(app.ROCcurveanalysisPanel, 'push');
            app.SavefigureButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureButtonPushed, true);
            app.SavefigureButton.Text = 'Save figure';
            app.SliceSlider_8Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_8Label.Text = 'Slice';
            app.SliceSlider_8 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_8.MajorTicks = [];
            app.SliceSlider_8.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_8ValueChanging, true);
            app.SliceSlider_8.MinorTicks = [];
            app.Lamp_2 = uilamp(app.SegmentationandROCTab);
            app.SliceSlider_10Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_10Label.Text = 'Slice';
            app.SliceSlider_10 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_10.MajorTicks = [];
            app.SliceSlider_10.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_10ValueChanging, true);
            app.SliceSlider_10.MinorTicks = [];
            app.SliceSlider_11Label = uilabel(app.SegmentationandROCTab);
            app.SliceSlider_11Label.Text = 'Slice';
            app.SliceSlider_11 = uislider(app.SegmentationandROCTab);
            app.SliceSlider_11.MajorTicks = [];
            app.SliceSlider_11.ValueChangingFcn = createCallbackFcn(app, @SliceSlider_11ValueChanging, true);
            app.SliceSlider_11.MinorTicks = [];
            app.DisplaydecorrelationDropDownLabel = uilabel(app.SegmentationandROCTab);
            app.DisplaydecorrelationDropDownLabel.Text = 'Display decorrelation';
            app.DisplaydecorrelationDropDown = uidropdown(app.SegmentationandROCTab);
            app.DisplaydecorrelationDropDown.Items = {};
            app.DisplaydecorrelationDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DisplaydecorrelationDropDownOpening, true);
            app.DisplaydecorrelationDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplaydecorrelationDropDownValueChanged, true);
            app.DisplaydecorrelationDropDown.Value = {};
            app.AlphaSlider_3Label = uilabel(app.SegmentationandROCTab);
            app.AlphaSlider_3Label.Text = 'Alpha';
            app.AlphaSlider_3 = uislider(app.SegmentationandROCTab);
            app.AlphaSlider_3.MajorTicks = [];
            app.AlphaSlider_3.ValueChangingFcn = createCallbackFcn(app, @AlphaSlider_3ValueChanging, true);
            app.AlphaSlider_3.MinorTicks = [];
            app.SavefigureincludingallimagingparametersButton = uibutton(app.SegmentationandROCTab, 'push');
            app.SavefigureincludingallimagingparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigureincludingallimagingparametersButtonPushed, true);
            app.SavefigureincludingallimagingparametersButton.Text = 'Save figure including all imaging parameters';
            app.EditField_2 = uieditfield(app.SegmentationandROCTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radiology_ablation_data_processing_v9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end